classdef ExportGui < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                matlab.ui.Figure
        TopGrid                 matlab.ui.container.GridLayout
        TopPanel                matlab.ui.container.Panel
        GridLayout5             matlab.ui.container.GridLayout
        GridLayout6             matlab.ui.container.GridLayout
        GridLayout7_2           matlab.ui.container.GridLayout
        expFolderPath           matlab.ui.control.EditField
        FolderPathLabel         matlab.ui.control.Label
        GridLayout7_3           matlab.ui.container.GridLayout
        FileNameLabel           matlab.ui.control.Label
        expFileName             matlab.ui.control.EditField
        GridLayout8             matlab.ui.container.GridLayout
        ExportDataButton        matlab.ui.control.Button
        SelectExportPathButton  matlab.ui.control.Button
        BottomGrid              matlab.ui.container.GridLayout
        GridLayout9             matlab.ui.container.GridLayout
        VolumeExportPanel       matlab.ui.container.Panel
        GridLayout10            matlab.ui.container.GridLayout
        ExpVolMat               matlab.ui.control.CheckBox
        ExpVolVtk               matlab.ui.control.CheckBox
        ExpVolWrk               matlab.ui.control.CheckBox
        MapExportPanel          matlab.ui.container.Panel
        GridLayout11            matlab.ui.container.GridLayout
        ExpImMat                matlab.ui.control.CheckBox
        ExpOverview             matlab.ui.control.CheckBox
        GridLayout41            matlab.ui.container.GridLayout
        ExpOverJpg              matlab.ui.control.CheckBox
        ExpOverPdf              matlab.ui.control.CheckBox
        ExpOverPng              matlab.ui.control.CheckBox
        ExpOverTiff             matlab.ui.control.CheckBox
        ExpNative               matlab.ui.control.CheckBox
        GridLayout42            matlab.ui.container.GridLayout
        ExpNativeJpg            matlab.ui.control.CheckBox
        ExpNativeTiff           matlab.ui.control.CheckBox
        ExpNativePng            matlab.ui.control.CheckBox
        ExpDepthMap             matlab.ui.control.CheckBox
        ExpWrkIm                matlab.ui.control.CheckBox
        ExportSettingsPanel     matlab.ui.container.Panel
        GridLayout12            matlab.ui.container.GridLayout
        ExpLogFile              matlab.ui.control.CheckBox
        ExpOverWrite            matlab.ui.control.CheckBox
    end

    
    properties (Access = public)
        PPA = PostProBackEnd().empty;
    end
    
    methods (Access = public)
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, PPA, filePath)
            % move gui window to top-left
            movegui(app.UIFigure,'north');
            if ~isempty(PPA.MasterGUI)
                masterGuiPos = PPA.MasterGUI.UIFigure.Position;
                rightBorder = masterGuiPos(1)+masterGuiPos(3);
                app.UIFigure.Position(1) = rightBorder;
            end
            % then move over to be next to master gui
            masterGuiPos = PPA.MasterGUI.UIFigure.Position;
            rightBorder = masterGuiPos(1)+masterGuiPos(3);
            app.UIFigure.Position(1) = rightBorder;
            
            % transfer PPA handle and app handle
            app.PPA = PPA;
            app.PPA.LoadGUI = app; % transfer handle to app
            
            if nargin == 3 && ~isempty(filePath) % filePath was provided, handle it
                app.PPA.filePath = filePath;
                app.expFileName.Value = app.PPA.fileName;
                app.expFolderPath.Value = app.PPA.folderPath;
            end
            
            if app.PPA.doBatchProcessing  % just load data, no preview
                app.PPA.Check_File_Content();
                app.PPA.Load_Raw_Data();
            elseif exist(app.PPA.filePath,'file') % we have a file in PPA, try and preview it
                app.expFileName.Value = app.PPA.fileName;
                app.expFolderPath.Value = app.PPA.folderPath;
                app.PPA.Preview_File_Content();
            else % no file provided, load default image with overlayed message
                defaultImage = imread('load_gui_default_im.jpg');
                % make it bigger, so it looks blurry but not pixelated if window size increases
                defaultImage = imresize(defaultImage,4).*0.4; % 0.4 makes image dark
                infoStr = sprintf('Please press\n[Select New File]');
                ctrPos = round(size(defaultImage)./2);
                ctrPos = ctrPos(1:2); % remove 3rd dim of rgb image
                defaultImage = insertText(defaultImage, ctrPos, infoStr, ...
                    'FontSize', 60, 'TextColor', 'black', 'BoxColor', 'white', ...
                    'BoxOpacity', 0.5, 'AnchorPoint','center');
                app.PrevImage.ImageSource = defaultImage;
            end
        end

        % Button pushed function: SelectExportPathButton
        function SelectExportPathButtonPushed(app, event)
            [fileName,folder] = uigetfile({'*.mat;', 'MATLAB Files (*.mat,)';...
                '*.tiff','Tiff (*.tiff, *.tif)'; ...
                '*.*',  'All Files (*.*)'},...
                'File Selector',app.PPA.folderPath);
            
            if fileName
                app.PPA.filePath = [folder fileName];
                app.expFileName.Value = app.PPA.fileName;
                app.expFolderPath.Value = app.PPA.folderPath;
            else
                uialert(app.UIFigure,'No file selected!','No file selected!');
                return;
            end
            
            if app.PPA.doBatchProcessing
                app.PPA.Check_File_Content();
                app.PPA.Load_Raw_Data();
            elseif exist(app.PPA.filePath,'file')
                app.PPA.Preview_File_Content();
            end
            
        end

        % Button pushed function: ExportDataButton
        function ExportDataButtonPushed(app, event)
            app.PPA.Load_Raw_Data();
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            % instead of closing app, we make it invisible
            app.UIFigure.Visible = 'off';
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'Load Raw Data';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.TopGrid = uigridlayout(app.UIFigure);
            app.TopPanel = uipanel(app.TopGrid);
            app.GridLayout5 = uigridlayout(app.TopPanel);
            app.GridLayout6 = uigridlayout(app.GridLayout5);
            app.GridLayout7_2 = uigridlayout(app.GridLayout6);
            app.expFolderPath = uieditfield(app.GridLayout7_2, 'text');
            app.FolderPathLabel = uilabel(app.GridLayout7_2);
            app.FolderPathLabel.Text = 'Folder Path';
            app.GridLayout7_3 = uigridlayout(app.GridLayout6);
            app.FileNameLabel = uilabel(app.GridLayout7_3);
            app.FileNameLabel.Text = 'File Name';
            app.expFileName = uieditfield(app.GridLayout7_3, 'text');
            app.GridLayout8 = uigridlayout(app.GridLayout5);
            app.ExportDataButton = uibutton(app.GridLayout8, 'push');
            app.ExportDataButton.ButtonPushedFcn = createCallbackFcn(app, @ExportDataButtonPushed, true);
            app.ExportDataButton.Text = 'Export Data';
            app.SelectExportPathButton = uibutton(app.GridLayout8, 'push');
            app.SelectExportPathButton.ButtonPushedFcn = createCallbackFcn(app, @SelectExportPathButtonPushed, true);
            app.SelectExportPathButton.Text = 'Select New Path';
            app.BottomGrid = uigridlayout(app.TopGrid);
            app.GridLayout9 = uigridlayout(app.BottomGrid);
            app.VolumeExportPanel = uipanel(app.GridLayout9);
            app.VolumeExportPanel.Title = 'Volume Export';
            app.GridLayout10 = uigridlayout(app.VolumeExportPanel);
            app.ExpVolMat = uicheckbox(app.GridLayout10);
            app.ExpVolMat.Enable = 'off';
            app.ExpVolMat.Text = 'Export .mat';
            app.ExpVolVtk = uicheckbox(app.GridLayout10);
            app.ExpVolVtk.Enable = 'off';
            app.ExpVolVtk.Text = 'Export .vtk';
            app.ExpVolWrk = uicheckbox(app.GridLayout10);
            app.ExpVolWrk.Enable = 'off';
            app.ExpVolWrk.Text = 'Export to workspace';
            app.MapExportPanel = uipanel(app.GridLayout9);
            app.MapExportPanel.Title = 'Map Export';
            app.GridLayout11 = uigridlayout(app.MapExportPanel);
            app.ExpImMat = uicheckbox(app.GridLayout11);
            app.ExpImMat.Enable = 'off';
            app.ExpImMat.Text = 'Export .mat';
            app.ExpOverview = uicheckbox(app.GridLayout11);
            app.ExpOverview.Enable = 'off';
            app.ExpOverview.Tooltip = {'Create side-by-side overviews of mip and depth map including colorbar. '};
            app.ExpOverview.Text = 'Overview';
            app.ExpOverview.Value = true;
            app.GridLayout41 = uigridlayout(app.GridLayout11);
            app.ExpOverJpg = uicheckbox(app.GridLayout41);
            app.ExpOverJpg.Enable = 'off';
            app.ExpOverJpg.Text = '.jpg';
            app.ExpOverJpg.Value = true;
            app.ExpOverPdf = uicheckbox(app.GridLayout41);
            app.ExpOverPdf.Enable = 'off';
            app.ExpOverPdf.Text = '.pdf';
            app.ExpOverPdf.Value = true;
            app.ExpOverPng = uicheckbox(app.GridLayout41);
            app.ExpOverPng.Enable = 'off';
            app.ExpOverPng.Text = '.png';
            app.ExpOverTiff = uicheckbox(app.GridLayout41);
            app.ExpOverTiff.Enable = 'off';
            app.ExpOverTiff.Text = '.tiff';
            app.ExpNative = uicheckbox(app.GridLayout11);
            app.ExpNative.Enable = 'off';
            app.ExpNative.Tooltip = {'Create native resolution RGB images (without color bar)?'};
            app.ExpNative.Text = 'Native';
            app.GridLayout42 = uigridlayout(app.GridLayout11);
            app.ExpNativeJpg = uicheckbox(app.GridLayout42);
            app.ExpNativeJpg.Enable = 'off';
            app.ExpNativeJpg.Text = '.jpg';
            app.ExpNativeTiff = uicheckbox(app.GridLayout42);
            app.ExpNativeTiff.Enable = 'off';
            app.ExpNativeTiff.Text = '.tiff';
            app.ExpNativePng = uicheckbox(app.GridLayout42);
            app.ExpNativePng.Enable = 'off';
            app.ExpNativePng.Text = '.png';
            app.ExpDepthMap = uicheckbox(app.GridLayout11);
            app.ExpDepthMap.Enable = 'off';
            app.ExpDepthMap.Tooltip = {'Create native resolution RGB depth map (without colorbar)?'; ''};
            app.ExpDepthMap.Text = 'Depth Map';
            app.ExpDepthMap.Value = true;
            app.ExpWrkIm = uicheckbox(app.GridLayout11);
            app.ExpWrkIm.Enable = 'off';
            app.ExpWrkIm.Text = 'Export to workspace';
            app.ExportSettingsPanel = uipanel(app.BottomGrid);
            app.ExportSettingsPanel.Title = 'Export Settings';
            app.GridLayout12 = uigridlayout(app.ExportSettingsPanel);
            app.ExpLogFile = uicheckbox(app.GridLayout12);
            app.ExpLogFile.Text = 'Export log file';
            app.ExpOverWrite = uicheckbox(app.GridLayout12);
            app.ExpOverWrite.Text = 'Overwrite?';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ExportGui(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end