classdef LoadGui < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure             matlab.ui.Figure
        GridLayout           matlab.ui.container.GridLayout
        Panel                matlab.ui.container.Panel
        GridLayout5          matlab.ui.container.GridLayout
        GridLayout6          matlab.ui.container.GridLayout
        GridLayout7_2        matlab.ui.container.GridLayout
        FolderPathEditField  matlab.ui.control.EditField
        FolderPathLabel      matlab.ui.control.Label
        GridLayout7_3        matlab.ui.container.GridLayout
        FileNameLabel        matlab.ui.control.Label
        FileNameEditField    matlab.ui.control.EditField
        GridLayout8          matlab.ui.container.GridLayout
        LoadDataButton       matlab.ui.control.Button
        SelectNewFileButton  matlab.ui.control.Button
        GridLayout2          matlab.ui.container.GridLayout
        FileContentPanel     matlab.ui.container.Panel
        GridLayout3          matlab.ui.container.GridLayout
        UITable              matlab.ui.control.Table
        GridLayout9          matlab.ui.container.GridLayout
        PrevImage            matlab.ui.control.Image
        Panel_2              matlab.ui.container.Panel
        FullInfoLampLabel    matlab.ui.control.Label
        FullInfoLamp         matlab.ui.control.Lamp
    end

    
    properties (Access = public)
        PPA = PostProBackEnd().empty;
    end
    
    methods (Access = public)
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, PPA, filePath)
            app.PPA = PPA;
            app.PPA.LoadGUI = app; % transfer handle to app
            if nargin == 3
                app.PPA.filePath = filePath;
                app.FileNameEditField.Value = app.PPA.fileName;
                app.FolderPathEditField.Value = app.PPA.folderPath;
            end
            if app.PPA.doBatchProcessing
                app.Load_Raw_Data();
            elseif exist(app.PPA.filePath,'file')
                app.FileNameEditField.Value = app.PPA.fileName;
                app.FolderPathEditField.Value = app.PPA.folderPath;
                app.PPA.Preview_File_Content();
            end          
        end

        % Button pushed function: SelectNewFileButton
        function SelectNewFileButtonPushed(app, event)
            [fileName,folder] = uigetfile({'*.mat;', 'MATLAB Files (*.mat,)';...
                '*.tiff','Tiff (*.tiff, *.tif)'; ...
                '*.*',  'All Files (*.*)'},...
                'File Selector',app.PPA.folderPath);
            
            if fileName
                app.PPA.filePath = [folder fileName];
                app.FileNameEditField.Value = app.PPA.fileName;
                app.FolderPathEditField.Value = app.PPA.folderPath;
            else
                uialert(app.UIFigure,'No file selected!','No file selected!');
                return;
            end
            if app.PPA.doBatchProcessing
                app.Load_Raw_Data();
            elseif exist(app.PPA.filePath,'file')
                app.PPA.Preview_File_Content();
            end
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.Panel = uipanel(app.GridLayout);
            app.GridLayout5 = uigridlayout(app.Panel);
            app.GridLayout6 = uigridlayout(app.GridLayout5);
            app.GridLayout7_2 = uigridlayout(app.GridLayout6);
            app.FolderPathEditField = uieditfield(app.GridLayout7_2, 'text');
            app.FolderPathLabel = uilabel(app.GridLayout7_2);
            app.FolderPathLabel.Text = {'Folder'; 'Path'};
            app.GridLayout7_3 = uigridlayout(app.GridLayout6);
            app.FileNameLabel = uilabel(app.GridLayout7_3);
            app.FileNameLabel.Text = {'File'; 'Name'};
            app.FileNameEditField = uieditfield(app.GridLayout7_3, 'text');
            app.GridLayout8 = uigridlayout(app.GridLayout5);
            app.LoadDataButton = uibutton(app.GridLayout8, 'push');
            app.LoadDataButton.Text = 'Load Data';
            app.SelectNewFileButton = uibutton(app.GridLayout8, 'push');
            app.SelectNewFileButton.ButtonPushedFcn = createCallbackFcn(app, @SelectNewFileButtonPushed, true);
            app.SelectNewFileButton.Text = 'Select New File';
            app.GridLayout2 = uigridlayout(app.GridLayout);
            app.FileContentPanel = uipanel(app.GridLayout2);
            app.FileContentPanel.Title = 'File Content';
            app.GridLayout3 = uigridlayout(app.FileContentPanel);
            app.UITable = uitable(app.GridLayout3);
            app.UITable.ColumnName = {'Name'; 'Size'; 'Bytes'};
            app.UITable.RowName = {};
            app.GridLayout9 = uigridlayout(app.GridLayout2);
            app.PrevImage = uiimage(app.GridLayout9);
            app.Panel_2 = uipanel(app.GridLayout9);
            app.Panel_2.Title = 'Panel';
            app.FullInfoLampLabel = uilabel(app.Panel_2);
            app.FullInfoLampLabel.Text = 'Full Info';
            app.FullInfoLamp = uilamp(app.Panel_2);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = LoadGui(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end