classdef MapGui < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        MainGrid                        matlab.ui.container.GridLayout
        GridLayout23                    matlab.ui.container.GridLayout
        imFiltDisp                      matlab.ui.control.UIAxes
        imDepthDisp                     matlab.ui.control.UIAxes
        GridLayout31                    matlab.ui.container.GridLayout
        ImageProcessinginorderPanel     matlab.ui.container.Panel
        GridLayout11                    matlab.ui.container.GridLayout
        ContrastCheck                   matlab.ui.control.CheckBox
        GridLayout22                    matlab.ui.container.GridLayout
        imSpotRem                       matlab.ui.control.NumericEditField
        SpotRemovalCheckBox             matlab.ui.control.CheckBox
        GridLayout22_2                  matlab.ui.container.GridLayout
        imInterpFct                     matlab.ui.control.NumericEditField
        InterpolateCheckBox             matlab.ui.control.CheckBox
        GridLayout22_3                  matlab.ui.container.GridLayout
        WienerSize                      matlab.ui.control.NumericEditField
        WienerCheckBox                  matlab.ui.control.CheckBox
        ImageGuidedCheckBox             matlab.ui.control.CheckBox
        AdjustContrastCheckBox          matlab.ui.control.CheckBox
        UseFrangiCheckBox               matlab.ui.control.CheckBox
        DepthMapSettingsPanel           matlab.ui.container.Panel
        GridLayout17                    matlab.ui.container.GridLayout
        GridLayout30                    matlab.ui.container.GridLayout
        ContrastSlider                  matlab.ui.control.Slider
        ContrastLabel                   matlab.ui.control.Label
        GridLayout30_2                  matlab.ui.container.GridLayout
        GridLayout30_5                  matlab.ui.container.GridLayout
        surfaceoffsetEditFieldLabel     matlab.ui.control.Label
        surfaceoffsetEditField          matlab.ui.control.NumericEditField
        GridLayout30_6                  matlab.ui.container.GridLayout
        depthoffsetEditFieldLabel       matlab.ui.control.Label
        depthoffsetEditField            matlab.ui.control.NumericEditField
        GridLayout30_3                  matlab.ui.container.GridLayout
        GridLayout30_7                  matlab.ui.container.GridLayout
        TranspEditFieldLabel            matlab.ui.control.Label
        TranspEditField                 matlab.ui.control.NumericEditField
        GridLayout30_8                  matlab.ui.container.GridLayout
        MinAmpEditFieldLabel            matlab.ui.control.Label
        MinAmpEditField                 matlab.ui.control.NumericEditField
        GridLayout30_4                  matlab.ui.container.GridLayout
        GridLayout30_9                  matlab.ui.container.GridLayout
        SmoothPxEditFieldLabel          matlab.ui.control.Label
        SmoothPxEditField               matlab.ui.control.NumericEditField
        GridLayout30_10                 matlab.ui.container.GridLayout
        depthColor                      matlab.ui.control.DropDown
        DepthMapLabel                   matlab.ui.control.Label
        histoAx                         matlab.ui.control.UIAxes
        CLAHESettingsPanel              matlab.ui.container.Panel
        GridLayout16                    matlab.ui.container.GridLayout
        GridLayout22_4                  matlab.ui.container.GridLayout
        TilesDropDownLabel              matlab.ui.control.Label
        ClaheTiles                      matlab.ui.control.DropDown
        GridLayout22_5                  matlab.ui.container.GridLayout
        BinsLabel                       matlab.ui.control.Label
        ClaheBins                       matlab.ui.control.DropDown
        DistributionLabel               matlab.ui.control.Label
        ClaheDistr                      matlab.ui.control.DropDown
        ClipLimitSliderLabel            matlab.ui.control.Label
        ClaheClipLim                    matlab.ui.control.Slider
        ImageGuidedFilterSettingsPanel  matlab.ui.container.Panel
        GridLayout16_2                  matlab.ui.container.GridLayout
        GridLayout22_6                  matlab.ui.container.GridLayout
        NbhSizeLabel                    matlab.ui.control.Label
        ImGuideSizeEditField            matlab.ui.control.NumericEditField
        GridLayout22_7                  matlab.ui.container.GridLayout
        SmoothSliderLabel               matlab.ui.control.Label
        ImGuideSmoothSlider             matlab.ui.control.Slider
        UseFrangiCheckBox_2             matlab.ui.control.CheckBox
        AdjustContrastSettingsPanel     matlab.ui.container.Panel
        GridLayout16_3                  matlab.ui.container.GridLayout
        GridLayout41                    matlab.ui.container.GridLayout
        LowerLimitLabel                 matlab.ui.control.Label
        ContrastLowLimEdit              matlab.ui.control.NumericEditField
        GridLayout42                    matlab.ui.container.GridLayout
        UpperLimitLabel                 matlab.ui.control.Label
        ContrastUpLimEdit               matlab.ui.control.NumericEditField
        GridLayout42_2                  matlab.ui.container.GridLayout
        GammaLabel                      matlab.ui.control.Label
        ContrastGammaEdit               matlab.ui.control.NumericEditField
        AutoContrCheckBox               matlab.ui.control.CheckBox
    end

    
    properties (Access = public)
        PPA = PostProBackEnd().empty;
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function Open_App(app, PPA)
            movegui(app.UIFigure,'northeast');
            
            if ~isempty(PPA.VolGUI)
                volGuiPos = PPA.VolGUI.UIFigure.Position;
                rightBorder = volGuiPos(1)+volGuiPos(3);
                app.UIFigure.Position(1) = rightBorder;
            elseif ~isempty(PPA.MasterGUI)
                masterGuiPos = PPA.MasterGUI.UIFigure.Position;
                rightBorder = masterGuiPos(1)+masterGuiPos(3);
                app.UIFigure.Position(1) = rightBorder;
            end
            app.PPA = PPA;
            app.PPA.MapGUI = app; % transfer handle to app
            
            % setup UI axis for images
            app.PPA.Setup_UIAxis(app.imFiltDisp,1);
            app.PPA.Setup_UIAxis(app.imDepthDisp,1);
        end

        % Close request function: UIFigure
        function CloseApp(app, event)
            app.UIFigure.Visible = 'off';
        end

        % Value changed function: AdjustContrastCheckBox, 
        % AutoContrCheckBox, ClaheBins, ClaheClipLim, ClaheDistr, 
        % ClaheTiles, ContrastCheck, ContrastGammaEdit, 
        % ContrastLowLimEdit, ContrastUpLimEdit, 
        % ImGuideSizeEditField, ImageGuidedCheckBox, 
        % InterpolateCheckBox, SpotRemovalCheckBox, WienerCheckBox, 
        % WienerSize, imInterpFct, imSpotRem
        function Apply_Image_Processing(app, event)
            app.PPA.Apply_Image_Processing();
        end

        % Value changed function: ContrastSlider, MinAmpEditField, 
        % SmoothPxEditField, TranspEditField, depthColor, 
        % depthoffsetEditField, surfaceoffsetEditField
        function Update_Depth_Map(app, event)
            app.PPA.Update_Depth_Map(app.imDepthDisp);            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'Map Processor';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @CloseApp, true);
            app.MainGrid = uigridlayout(app.UIFigure);
            app.GridLayout23 = uigridlayout(app.MainGrid);
            app.imFiltDisp = uiaxes(app.GridLayout23);
            title(app.imFiltDisp, 'Processed Map')
            xlabel(app.imFiltDisp, '')
            ylabel(app.imFiltDisp, '')
            app.imDepthDisp = uiaxes(app.GridLayout23);
            title(app.imDepthDisp, 'Depth Map')
            xlabel(app.imDepthDisp, '')
            ylabel(app.imDepthDisp, '')
            app.GridLayout31 = uigridlayout(app.MainGrid);
            app.ImageProcessinginorderPanel = uipanel(app.GridLayout31);
            app.ImageProcessinginorderPanel.Title = 'Image Processing (in order)';
            app.GridLayout11 = uigridlayout(app.ImageProcessinginorderPanel);
            app.ContrastCheck = uicheckbox(app.GridLayout11);
            app.ContrastCheck.ValueChangedFcn = createCallbackFcn(app, @Apply_Image_Processing, true);
            app.ContrastCheck.Text = 'CLAHE';
            app.ContrastCheck.Value = true;
            app.GridLayout22 = uigridlayout(app.GridLayout11);
            app.imSpotRem = uieditfield(app.GridLayout22, 'numeric');
            app.imSpotRem.Limits = [1 20];
            app.imSpotRem.RoundFractionalValues = 'on';
            app.imSpotRem.ValueChangedFcn = createCallbackFcn(app, @Apply_Image_Processing, true);
            app.imSpotRem.Value = 5;
            app.SpotRemovalCheckBox = uicheckbox(app.GridLayout22);
            app.SpotRemovalCheckBox.ValueChangedFcn = createCallbackFcn(app, @Apply_Image_Processing, true);
            app.SpotRemovalCheckBox.Text = {'Spot'; 'Removal'};
            app.GridLayout22_2 = uigridlayout(app.GridLayout11);
            app.imInterpFct = uieditfield(app.GridLayout22_2, 'numeric');
            app.imInterpFct.Limits = [0.1 10];
            app.imInterpFct.ValueChangedFcn = createCallbackFcn(app, @Apply_Image_Processing, true);
            app.imInterpFct.Value = 2;
            app.InterpolateCheckBox = uicheckbox(app.GridLayout22_2);
            app.InterpolateCheckBox.ValueChangedFcn = createCallbackFcn(app, @Apply_Image_Processing, true);
            app.InterpolateCheckBox.Text = 'Interpolate';
            app.GridLayout22_3 = uigridlayout(app.GridLayout11);
            app.WienerSize = uieditfield(app.GridLayout22_3, 'numeric');
            app.WienerSize.Limits = [3 50];
            app.WienerSize.RoundFractionalValues = 'on';
            app.WienerSize.ValueChangedFcn = createCallbackFcn(app, @Apply_Image_Processing, true);
            app.WienerSize.Value = 3;
            app.WienerCheckBox = uicheckbox(app.GridLayout22_3);
            app.WienerCheckBox.ValueChangedFcn = createCallbackFcn(app, @Apply_Image_Processing, true);
            app.WienerCheckBox.Text = 'Wiener';
            app.ImageGuidedCheckBox = uicheckbox(app.GridLayout11);
            app.ImageGuidedCheckBox.ValueChangedFcn = createCallbackFcn(app, @Apply_Image_Processing, true);
            app.ImageGuidedCheckBox.Text = 'Image Guided';
            app.AdjustContrastCheckBox = uicheckbox(app.GridLayout11);
            app.AdjustContrastCheckBox.ValueChangedFcn = createCallbackFcn(app, @Apply_Image_Processing, true);
            app.AdjustContrastCheckBox.Text = 'Adjust Contrast';
            app.AdjustContrastCheckBox.Value = true;
            app.UseFrangiCheckBox = uicheckbox(app.GridLayout11);
            app.UseFrangiCheckBox.Enable = 'off';
            app.UseFrangiCheckBox.Text = 'Use Frangi';
            app.DepthMapSettingsPanel = uipanel(app.GridLayout31);
            app.DepthMapSettingsPanel.Title = 'Depth Map Settings';
            app.GridLayout17 = uigridlayout(app.DepthMapSettingsPanel);
            app.GridLayout30 = uigridlayout(app.GridLayout17);
            app.ContrastSlider = uislider(app.GridLayout30);
            app.ContrastSlider.MajorTicks = [];
            app.ContrastSlider.MajorTickLabels = {''};
            app.ContrastSlider.ValueChangedFcn = createCallbackFcn(app, @Update_Depth_Map, true);
            app.ContrastSlider.MinorTicks = [1 25 50 75 100];
            app.ContrastLabel = uilabel(app.GridLayout30);
            app.ContrastLabel.Text = 'Contrast';
            app.GridLayout30_2 = uigridlayout(app.GridLayout17);
            app.GridLayout30_5 = uigridlayout(app.GridLayout30_2);
            app.surfaceoffsetEditFieldLabel = uilabel(app.GridLayout30_5);
            app.surfaceoffsetEditFieldLabel.Text = {'% surface'; 'offset'};
            app.surfaceoffsetEditField = uieditfield(app.GridLayout30_5, 'numeric');
            app.surfaceoffsetEditField.Limits = [0 100];
            app.surfaceoffsetEditField.RoundFractionalValues = 'on';
            app.surfaceoffsetEditField.ValueChangedFcn = createCallbackFcn(app, @Update_Depth_Map, true);
            app.GridLayout30_6 = uigridlayout(app.GridLayout30_2);
            app.depthoffsetEditFieldLabel = uilabel(app.GridLayout30_6);
            app.depthoffsetEditFieldLabel.Text = {'% depth'; 'offset'};
            app.depthoffsetEditField = uieditfield(app.GridLayout30_6, 'numeric');
            app.depthoffsetEditField.Limits = [0 100];
            app.depthoffsetEditField.RoundFractionalValues = 'on';
            app.depthoffsetEditField.ValueChangedFcn = createCallbackFcn(app, @Update_Depth_Map, true);
            app.GridLayout30_3 = uigridlayout(app.GridLayout17);
            app.GridLayout30_7 = uigridlayout(app.GridLayout30_3);
            app.TranspEditFieldLabel = uilabel(app.GridLayout30_7);
            app.TranspEditFieldLabel.Text = 'Transp. (%)';
            app.TranspEditField = uieditfield(app.GridLayout30_7, 'numeric');
            app.TranspEditField.Limits = [0 500];
            app.TranspEditField.RoundFractionalValues = 'on';
            app.TranspEditField.ValueChangedFcn = createCallbackFcn(app, @Update_Depth_Map, true);
            app.TranspEditField.Value = 110;
            app.GridLayout30_8 = uigridlayout(app.GridLayout30_3);
            app.MinAmpEditFieldLabel = uilabel(app.GridLayout30_8);
            app.MinAmpEditFieldLabel.Text = {'Min.'; 'Amp (%)'};
            app.MinAmpEditField = uieditfield(app.GridLayout30_8, 'numeric');
            app.MinAmpEditField.Limits = [0 100];
            app.MinAmpEditField.RoundFractionalValues = 'on';
            app.MinAmpEditField.ValueChangedFcn = createCallbackFcn(app, @Update_Depth_Map, true);
            app.MinAmpEditField.Value = 50;
            app.GridLayout30_4 = uigridlayout(app.GridLayout17);
            app.GridLayout30_9 = uigridlayout(app.GridLayout30_4);
            app.SmoothPxEditFieldLabel = uilabel(app.GridLayout30_9);
            app.SmoothPxEditFieldLabel.Text = {'Smooth'; '(Px)'};
            app.SmoothPxEditField = uieditfield(app.GridLayout30_9, 'numeric');
            app.SmoothPxEditField.Limits = [1 50];
            app.SmoothPxEditField.ValueChangedFcn = createCallbackFcn(app, @Update_Depth_Map, true);
            app.SmoothPxEditField.Value = 5;
            app.GridLayout30_10 = uigridlayout(app.GridLayout30_4);
            app.depthColor = uidropdown(app.GridLayout30_10);
            app.depthColor.Items = {'hot', 'jet', 'parula', 'BOG', 'BGO', 'GOB', 'GBO', 'OGB', 'OBG', 'GR', 'GO', 'ROG', ''};
            app.depthColor.ValueChangedFcn = createCallbackFcn(app, @Update_Depth_Map, true);
            app.depthColor.Tooltip = {'select colomap for image display'; ''};
            app.depthColor.Value = 'BGO';
            app.DepthMapLabel = uilabel(app.GridLayout30_10);
            app.DepthMapLabel.Text = 'Depth Map';
            app.histoAx = uiaxes(app.GridLayout17);
            title(app.histoAx, 'Depth Histogram ')
            xlabel(app.histoAx, '')
            ylabel(app.histoAx, '')
            app.histoAx.YTick = [];
            app.histoAx.YTickLabel = '';
            app.CLAHESettingsPanel = uipanel(app.GridLayout31);
            app.CLAHESettingsPanel.Title = 'CLAHE Settings';
            app.GridLayout16 = uigridlayout(app.CLAHESettingsPanel);
            app.GridLayout22_4 = uigridlayout(app.GridLayout16);
            app.TilesDropDownLabel = uilabel(app.GridLayout22_4);
            app.TilesDropDownLabel.Text = 'Tiles';
            app.ClaheTiles = uidropdown(app.GridLayout22_4);
            app.ClaheTiles.Items = {'8', '16', '32', '64', '128', '256', '512'};
            app.ClaheTiles.ValueChangedFcn = createCallbackFcn(app, @Apply_Image_Processing, true);
            app.ClaheTiles.Value = '32';
            app.GridLayout22_5 = uigridlayout(app.GridLayout16);
            app.BinsLabel = uilabel(app.GridLayout22_5);
            app.BinsLabel.Text = 'Bins';
            app.ClaheBins = uidropdown(app.GridLayout22_5);
            app.ClaheBins.Items = {'8', '16', '32', '64', '128', '256', '512', '1024', '2048'};
            app.ClaheBins.ValueChangedFcn = createCallbackFcn(app, @Apply_Image_Processing, true);
            app.ClaheBins.Value = '256';
            app.DistributionLabel = uilabel(app.GridLayout16);
            app.DistributionLabel.Text = 'Distribution';
            app.ClaheDistr = uidropdown(app.GridLayout16);
            app.ClaheDistr.Items = {'uniform', 'rayleigh', 'exponential'};
            app.ClaheDistr.ValueChangedFcn = createCallbackFcn(app, @Apply_Image_Processing, true);
            app.ClaheDistr.Value = 'uniform';
            app.ClipLimitSliderLabel = uilabel(app.GridLayout16);
            app.ClipLimitSliderLabel.Text = 'Clip Limit';
            app.ClaheClipLim = uislider(app.GridLayout16);
            app.ClaheClipLim.Limits = [0.0001 0.1];
            app.ClaheClipLim.MajorTicks = [];
            app.ClaheClipLim.MajorTickLabels = {};
            app.ClaheClipLim.ValueChangedFcn = createCallbackFcn(app, @Apply_Image_Processing, true);
            app.ClaheClipLim.MinorTicks = [];
            app.ClaheClipLim.Value = 0.02;
            app.ImageGuidedFilterSettingsPanel = uipanel(app.GridLayout31);
            app.ImageGuidedFilterSettingsPanel.Title = 'Image-Guided Filter Settings';
            app.GridLayout16_2 = uigridlayout(app.ImageGuidedFilterSettingsPanel);
            app.GridLayout22_6 = uigridlayout(app.GridLayout16_2);
            app.NbhSizeLabel = uilabel(app.GridLayout22_6);
            app.NbhSizeLabel.Text = {'Nbh'; 'Size'};
            app.ImGuideSizeEditField = uieditfield(app.GridLayout22_6, 'numeric');
            app.ImGuideSizeEditField.Limits = [1 100];
            app.ImGuideSizeEditField.RoundFractionalValues = 'on';
            app.ImGuideSizeEditField.ValueChangedFcn = createCallbackFcn(app, @Apply_Image_Processing, true);
            app.ImGuideSizeEditField.Enable = 'off';
            app.ImGuideSizeEditField.Value = 5;
            app.GridLayout22_7 = uigridlayout(app.GridLayout16_2);
            app.SmoothSliderLabel = uilabel(app.GridLayout22_7);
            app.SmoothSliderLabel.Text = 'Smooth';
            app.ImGuideSmoothSlider = uislider(app.GridLayout22_7);
            app.ImGuideSmoothSlider.Limits = [1e-05 0.5];
            app.ImGuideSmoothSlider.MajorTicks = [];
            app.ImGuideSmoothSlider.MajorTickLabels = {''};
            app.ImGuideSmoothSlider.MinorTicks = [];
            app.ImGuideSmoothSlider.Enable = 'off';
            app.ImGuideSmoothSlider.Value = 0.01;
            app.UseFrangiCheckBox_2 = uicheckbox(app.GridLayout16_2);
            app.UseFrangiCheckBox_2.Enable = 'off';
            app.UseFrangiCheckBox_2.Text = 'Use Frangi';
            app.AdjustContrastSettingsPanel = uipanel(app.GridLayout31);
            app.AdjustContrastSettingsPanel.Title = 'Adjust Contrast Settings';
            app.GridLayout16_3 = uigridlayout(app.AdjustContrastSettingsPanel);
            app.GridLayout41 = uigridlayout(app.GridLayout16_3);
            app.LowerLimitLabel = uilabel(app.GridLayout41);
            app.LowerLimitLabel.Text = {'Lower'; 'Limit'};
            app.ContrastLowLimEdit = uieditfield(app.GridLayout41, 'numeric');
            app.ContrastLowLimEdit.Limits = [0 1];
            app.ContrastLowLimEdit.ValueDisplayFormat = '%4.2f';
            app.ContrastLowLimEdit.ValueChangedFcn = createCallbackFcn(app, @Apply_Image_Processing, true);
            app.ContrastLowLimEdit.Enable = 'off';
            app.GridLayout42 = uigridlayout(app.GridLayout16_3);
            app.UpperLimitLabel = uilabel(app.GridLayout42);
            app.UpperLimitLabel.Text = {'Upper'; 'Limit'};
            app.ContrastUpLimEdit = uieditfield(app.GridLayout42, 'numeric');
            app.ContrastUpLimEdit.Limits = [0 1];
            app.ContrastUpLimEdit.ValueDisplayFormat = '%4.2f';
            app.ContrastUpLimEdit.ValueChangedFcn = createCallbackFcn(app, @Apply_Image_Processing, true);
            app.ContrastUpLimEdit.Enable = 'off';
            app.ContrastUpLimEdit.Value = 1;
            app.GridLayout42_2 = uigridlayout(app.GridLayout16_3);
            app.GammaLabel = uilabel(app.GridLayout42_2);
            app.GammaLabel.Text = 'Gamma';
            app.ContrastGammaEdit = uieditfield(app.GridLayout42_2, 'numeric');
            app.ContrastGammaEdit.Limits = [0 10];
            app.ContrastGammaEdit.ValueDisplayFormat = '%4.2f';
            app.ContrastGammaEdit.ValueChangedFcn = createCallbackFcn(app, @Apply_Image_Processing, true);
            app.ContrastGammaEdit.Enable = 'off';
            app.ContrastGammaEdit.Value = 1.25;
            app.AutoContrCheckBox = uicheckbox(app.GridLayout16_3);
            app.AutoContrCheckBox.ValueChangedFcn = createCallbackFcn(app, @Apply_Image_Processing, true);
            app.AutoContrCheckBox.Enable = 'off';
            app.AutoContrCheckBox.Text = 'Auto Calc Limits';
            app.AutoContrCheckBox.Value = true;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = MapGui(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)Open_App(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end