classdef MasterGui < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                matlab.ui.Figure
        MainGrid                matlab.ui.container.GridLayout
        PostProGuiLabel         matlab.ui.control.Label
        TextArea                matlab.ui.control.TextArea
        MainButtonGrid          matlab.ui.container.GridLayout
        LoadRawDataButton       matlab.ui.control.Button
        VolumeProcessingButton  matlab.ui.control.Button
        MapProcessingButton     matlab.ui.control.Button
        ExportMapsButton        matlab.ui.control.Button
        ExportVolumesButton     matlab.ui.control.Button
        BatchProcessingButton   matlab.ui.control.Button
        QuitButton              matlab.ui.control.Button
        GridLayout              matlab.ui.container.GridLayout
        VolumeInfoLabel         matlab.ui.control.Label
        GridLayout_2            matlab.ui.container.GridLayout
        MapInfoLabel            matlab.ui.control.Label
    end

    
    properties (Access = public)
        PPA = PostProBackEnd().empty;
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function StartGui(app)
            app.PPA = PostProBackEnd();
            app.PPA.MasterGUI = app;
            app.LoadRawDataButton.Enable = true;
        end

        % Close request function: UIFigure
        function CloseGui(app, event)
            if ishandle(app.PPA.LoadGUI)
                delete(app.PPA.LoadGUI);
            end
            if ishandle(app.PPA.VolGUI)
                delete(app.PPA.VolGUI);
            end
            delete(app);
        end

        % Button pushed function: LoadRawDataButton
        function LoadRawDataButtonPushed(app, event)
            if isa(app.PPA.LoadGUI,'LoadGui') && ishandle(app.PPA.LoadGUI.UIFigure)
                app.PPA.LoadGUI.UIFigure.Visible = 'on';
                figure(app.PPA.LoadGUI.UIFigure); % bring to front
            else
                LoadGui(app.PPA); % start Gui
            end
        end

        % Button pushed function: VolumeProcessingButton
        function VolumeProcessingButtonPushed(app, event)
            if isa(app.PPA.VolGUI,'VolGui') && ishandle(app.PPA.VolGUI.UIFigure)
                app.PPA.VolGUI.UIFigure.Visible = 'on';
                figure(app.PPA.VolGUI.UIFigure); % bring to front
            else
                VolGui(app.PPA); % start Gui
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = ' Post Pro Gui';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @CloseGui, true);
            app.MainGrid = uigridlayout(app.UIFigure);
            app.PostProGuiLabel = uilabel(app.MainGrid);
            app.PostProGuiLabel.Text = 'Post Pro Gui';
            app.TextArea = uitextarea(app.MainGrid);
            app.TextArea.Value = {'Status here? Or just the credits?'};
            app.MainButtonGrid = uigridlayout(app.MainGrid);
            app.LoadRawDataButton = uibutton(app.MainButtonGrid, 'push');
            app.LoadRawDataButton.ButtonPushedFcn = createCallbackFcn(app, @LoadRawDataButtonPushed, true);
            app.LoadRawDataButton.Enable = 'off';
            app.LoadRawDataButton.Text = {'Load'; 'Raw Data'};
            app.VolumeProcessingButton = uibutton(app.MainButtonGrid, 'push');
            app.VolumeProcessingButton.ButtonPushedFcn = createCallbackFcn(app, @VolumeProcessingButtonPushed, true);
            app.VolumeProcessingButton.Enable = 'off';
            app.VolumeProcessingButton.Text = {'Volume'; 'Processing'};
            app.MapProcessingButton = uibutton(app.MainButtonGrid, 'push');
            app.MapProcessingButton.Enable = 'off';
            app.MapProcessingButton.Text = {'Map'; 'Processing'};
            app.ExportMapsButton = uibutton(app.MainButtonGrid, 'push');
            app.ExportMapsButton.Enable = 'off';
            app.ExportMapsButton.Text = {'Export'; 'Maps'};
            app.ExportVolumesButton = uibutton(app.MainButtonGrid, 'push');
            app.ExportVolumesButton.Enable = 'off';
            app.ExportVolumesButton.Text = {'Export'; 'Volumes'};
            app.BatchProcessingButton = uibutton(app.MainButtonGrid, 'push');
            app.BatchProcessingButton.Enable = 'off';
            app.BatchProcessingButton.Text = {'Batch'; 'Processing'};
            app.QuitButton = uibutton(app.MainButtonGrid, 'push');
            app.QuitButton.Text = 'Quit';
            app.GridLayout = uigridlayout(app.MainGrid);
            app.VolumeInfoLabel = uilabel(app.GridLayout);
            app.VolumeInfoLabel.Text = 'Volume Info';
            app.GridLayout_2 = uigridlayout(app.MainGrid);
            app.MapInfoLabel = uilabel(app.GridLayout_2);
            app.MapInfoLabel.Text = 'Map Info';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = MasterGui

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @StartGui)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end