classdef PostProGUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        NotinuseyetMenu                 matlab.ui.container.Menu
        GridLayout                      matlab.ui.container.GridLayout
        TabGroup                        matlab.ui.container.TabGroup
        VolTab                          matlab.ui.container.Tab
        MainGrid                        matlab.ui.container.GridLayout
        SettingsGrid                    matlab.ui.container.GridLayout
        FrequencyFilteringSettingsPanel  matlab.ui.container.Panel
        GridLayout26                    matlab.ui.container.GridLayout
        FiltResponseAx                  matlab.ui.control.UIAxes
        GridLayout27                    matlab.ui.container.GridLayout
        GridLayout28                    matlab.ui.container.GridLayout
        MHzLabel                        matlab.ui.control.Label
        HighLabel                       matlab.ui.control.Label
        freqHigh                        matlab.ui.control.NumericEditField
        LowLabel                        matlab.ui.control.Label
        freqLow                         matlab.ui.control.NumericEditField
        OrderLabel                      matlab.ui.control.Label
        filtOrder                       matlab.ui.control.NumericEditField
        GridLayout29                    matlab.ui.container.GridLayout
        filtDesign                      matlab.ui.control.DropDown
        TypeDropDownLabel               matlab.ui.control.Label
        filtType                        matlab.ui.control.DropDown
        GridVolSettings                 matlab.ui.container.GridLayout
        GridLayout25                    matlab.ui.container.GridLayout
        DwnSplCheck                     matlab.ui.control.CheckBox
        GridLayout51                    matlab.ui.container.GridLayout
        xyLabel                         matlab.ui.control.Label
        DwnSplFactorEdit                matlab.ui.control.NumericEditField
        GridLayout51_2                  matlab.ui.container.GridLayout
        zLabel                          matlab.ui.control.Label
        DepthDwnSplFactorEdit           matlab.ui.control.NumericEditField
        GridLayout25_2                  matlab.ui.container.GridLayout
        CropCheck                       matlab.ui.control.CheckBox
        zCropHighEdit                   matlab.ui.control.NumericEditField
        zCropLowEdit                    matlab.ui.control.NumericEditField
        GridLayout25_3                  matlab.ui.container.GridLayout
        FreqFiltCheck                   matlab.ui.control.CheckBox
        GridLayout25_4                  matlab.ui.container.GridLayout
        MedFiltCheck                    matlab.ui.control.CheckBox
        MedFiltZ                        matlab.ui.control.NumericEditField
        MedFiltY                        matlab.ui.control.NumericEditField
        MedFiltX                        matlab.ui.control.NumericEditField
        GridLayout25_5                  matlab.ui.container.GridLayout
        PolarityCheck                   matlab.ui.control.CheckBox
        PolarityDropDown                matlab.ui.control.DropDown
        GridLayout25_6                  matlab.ui.container.GridLayout
        Load3DorMAPFileButton           matlab.ui.control.Button
        rawfileEditField                matlab.ui.control.EditField
        GridLayout35                    matlab.ui.container.GridLayout
        SliceWidthLabel                 matlab.ui.control.Label
        SliceWidthEditField             matlab.ui.control.NumericEditField
        GridLayout12                    matlab.ui.container.GridLayout
        FiltDisp                        matlab.ui.control.UIAxes
        yzSliceDisp                     matlab.ui.control.UIAxes
        xzSliceDisp                     matlab.ui.control.UIAxes
        GridLayout34                    matlab.ui.container.GridLayout
        xzProjDisp                      matlab.ui.control.UIAxes
        yzProjDisp                      matlab.ui.control.UIAxes
        ImageProcessingTab              matlab.ui.container.Tab
        mainGridImProc                  matlab.ui.container.GridLayout
        settingsGrid                    matlab.ui.container.GridLayout
        ImageProcessinginorderPanel     matlab.ui.container.Panel
        GridLayout11                    matlab.ui.container.GridLayout
        ContrastCheck                   matlab.ui.control.CheckBox
        UnsharpMaskingCheckBox          matlab.ui.control.CheckBox
        GridLayout22                    matlab.ui.container.GridLayout
        imSpotRem                       matlab.ui.control.NumericEditField
        SpotRemovalCheckBox             matlab.ui.control.CheckBox
        GridLayout22_4                  matlab.ui.container.GridLayout
        imInterpFct                     matlab.ui.control.NumericEditField
        InterpolateCheckBox             matlab.ui.control.CheckBox
        GridLayout22_7                  matlab.ui.container.GridLayout
        WienerSize                      matlab.ui.control.NumericEditField
        WienerCheckBox                  matlab.ui.control.CheckBox
        ImageGuidedCheckBox             matlab.ui.control.CheckBox
        AdjustContrastCheckBox          matlab.ui.control.CheckBox
        UseFrangiCheckBox               matlab.ui.control.CheckBox
        DepthMapSettingsPanel           matlab.ui.container.Panel
        GridLayout17                    matlab.ui.container.GridLayout
        GridLayout30                    matlab.ui.container.GridLayout
        ContrastSlider                  matlab.ui.control.Slider
        ContrastLabel                   matlab.ui.control.Label
        GridLayout30_2                  matlab.ui.container.GridLayout
        GridLayout30_5                  matlab.ui.container.GridLayout
        surfaceoffsetEditFieldLabel     matlab.ui.control.Label
        surfaceoffsetEditField          matlab.ui.control.NumericEditField
        GridLayout30_6                  matlab.ui.container.GridLayout
        depthoffsetEditFieldLabel       matlab.ui.control.Label
        depthoffsetEditField            matlab.ui.control.NumericEditField
        GridLayout30_3                  matlab.ui.container.GridLayout
        GridLayout30_10                 matlab.ui.container.GridLayout
        TranspLabel                     matlab.ui.control.Label
        TranspEditField                 matlab.ui.control.NumericEditField
        GridLayout30_9                  matlab.ui.container.GridLayout
        MinAmpEditFieldLabel            matlab.ui.control.Label
        MinAmpEditField                 matlab.ui.control.NumericEditField
        GridLayout30_4                  matlab.ui.container.GridLayout
        GridLayout30_11                 matlab.ui.container.GridLayout
        SmoothPxEditFieldLabel          matlab.ui.control.Label
        SmoothPxEditField               matlab.ui.control.NumericEditField
        GridLayout30_12                 matlab.ui.container.GridLayout
        depthColor                      matlab.ui.control.DropDown
        DepthMapLabel                   matlab.ui.control.Label
        histoAx                         matlab.ui.control.UIAxes
        CLAHESettingsPanel              matlab.ui.container.Panel
        GridLayout16                    matlab.ui.container.GridLayout
        GridLayout22_2                  matlab.ui.container.GridLayout
        TilesDropDownLabel              matlab.ui.control.Label
        ClaheTiles                      matlab.ui.control.DropDown
        GridLayout22_3                  matlab.ui.container.GridLayout
        BinsLabel                       matlab.ui.control.Label
        ClaheBins                       matlab.ui.control.DropDown
        DistributionLabel               matlab.ui.control.Label
        ClaheDistr                      matlab.ui.control.DropDown
        ClipLimitSliderLabel            matlab.ui.control.Label
        ClaheClipLim                    matlab.ui.control.Slider
        ImageGuidedFilterSettingsPanel  matlab.ui.container.Panel
        GridLayout16_2                  matlab.ui.container.GridLayout
        GridLayout22_10                 matlab.ui.container.GridLayout
        NbhSizeLabel                    matlab.ui.control.Label
        ImGuideSizeEditField            matlab.ui.control.NumericEditField
        GridLayout22_21                 matlab.ui.container.GridLayout
        SmoothSliderLabel               matlab.ui.control.Label
        ImGuideSmoothSlider             matlab.ui.control.Slider
        UnsharpMaskingSettingsPanel     matlab.ui.container.Panel
        GridLayout16_3                  matlab.ui.container.GridLayout
        GridLayout22_16                 matlab.ui.container.GridLayout
        RadiusEditFieldLabel            matlab.ui.control.Label
        UnsharpMaskRadiusEditField      matlab.ui.control.NumericEditField
        GridLayout22_17                 matlab.ui.container.GridLayout
        AmountSliderLabel               matlab.ui.control.Label
        UnsharpMaskAmountSlider         matlab.ui.control.Slider
        GridLayout22_20                 matlab.ui.container.GridLayout
        ThresholdLabel                  matlab.ui.control.Label
        UnsharpMaskThresholdEditField   matlab.ui.control.NumericEditField
        AdjustContrastSettingsPanel     matlab.ui.container.Panel
        GridLayout16_4                  matlab.ui.container.GridLayout
        GridLayout41                    matlab.ui.container.GridLayout
        LowerLimitLabel                 matlab.ui.control.Label
        ContrastLowLimEdit              matlab.ui.control.NumericEditField
        GridLayout42                    matlab.ui.container.GridLayout
        UpperLimitLabel                 matlab.ui.control.Label
        ContrastUpLimEdit               matlab.ui.control.NumericEditField
        GridLayout42_2                  matlab.ui.container.GridLayout
        GammaLabel                      matlab.ui.control.Label
        ContrastGammaEdit               matlab.ui.control.NumericEditField
        AutoContrCheckBox               matlab.ui.control.CheckBox
        GridLayout13                    matlab.ui.container.GridLayout
        imDepthDisp                     matlab.ui.control.UIAxes
        imFiltDisp                      matlab.ui.control.UIAxes
        VesselFilterTab                 matlab.ui.container.Tab
        GridLayout43                    matlab.ui.container.GridLayout
        GridLayout44                    matlab.ui.container.GridLayout
        imFrangiFilt                    matlab.ui.control.UIAxes
        imFrangiCombined                matlab.ui.control.UIAxes
        imFrangiFiltIn                  matlab.ui.control.UIAxes
        imFrangiScale                   matlab.ui.control.UIAxes
        GridLayout45                    matlab.ui.container.GridLayout
        FrangiSettingsPanel             matlab.ui.container.Panel
        GridLayout47                    matlab.ui.container.GridLayout
        GridLayout48                    matlab.ui.container.GridLayout
        GridLayout49_2                  matlab.ui.container.GridLayout
        StartScaleEditFieldLabel        matlab.ui.control.Label
        StartScaleEditField             matlab.ui.control.NumericEditField
        GridLayout49_3                  matlab.ui.container.GridLayout
        StopScaleEditFieldLabel         matlab.ui.control.Label
        StopScaleEditField              matlab.ui.control.NumericEditField
        ScaleRangeCheckBox              matlab.ui.control.CheckBox
        ManualScalesCheckBox            matlab.ui.control.CheckBox
        GridLayout49_4                  matlab.ui.container.GridLayout
        TotalScalesEditFieldLabel       matlab.ui.control.Label
        TotalScalesEditField            matlab.ui.control.NumericEditField
        ScalesTextField                 matlab.ui.control.EditField
        GridLayout49                    matlab.ui.container.GridLayout
        SensitivityEditFieldLabel       matlab.ui.control.Label
        SensitivityEditField            matlab.ui.control.NumericEditField
        InvertedCheckBox                matlab.ui.control.CheckBox
        GridLayout50                    matlab.ui.container.GridLayout
        ApplyButton                     matlab.ui.control.Button
        SelectFrangiScalesPanel         matlab.ui.container.Panel
        GridLayout46                    matlab.ui.container.GridLayout
        UITable                         matlab.ui.control.Table
        ExportBatchTab                  matlab.ui.container.Tab
        GridLayout14                    matlab.ui.container.GridLayout
        ExportPanel                     matlab.ui.container.Panel
        GridLayout38                    matlab.ui.container.GridLayout
        ExportMapsButton                matlab.ui.control.Button
        expFileName                     matlab.ui.control.EditField
        SelectExportPathButton          matlab.ui.control.Button
        expFolderPath                   matlab.ui.control.EditField
        ExpOverWrite                    matlab.ui.control.CheckBox
        ExpOverview                     matlab.ui.control.CheckBox
        GridLayout39                    matlab.ui.container.GridLayout
        ExpOverJpg                      matlab.ui.control.CheckBox
        ExpOverTiff                     matlab.ui.control.CheckBox
        GridLayout39_2                  matlab.ui.container.GridLayout
        ExpNativeTiff                   matlab.ui.control.CheckBox
        ExpNativeJpg                    matlab.ui.control.CheckBox
        GridLayout39_3                  matlab.ui.container.GridLayout
        ExpOverPdf                      matlab.ui.control.CheckBox
        ExpOverPng                      matlab.ui.control.CheckBox
        GridLayout39_4                  matlab.ui.container.GridLayout
        ExpNativePng                    matlab.ui.control.CheckBox
        GridLayout39_5                  matlab.ui.container.GridLayout
        ExpNative                       matlab.ui.control.CheckBox
        ExpDepthMap                     matlab.ui.control.CheckBox
        GridLayout40                    matlab.ui.container.GridLayout
        ExpMatFile                      matlab.ui.control.CheckBox
        ExpLogFile                      matlab.ui.control.CheckBox
        BatchProcessingPanel            matlab.ui.container.Panel
        GridLayout38_2                  matlab.ui.container.GridLayout
        BrowseFolderButton              matlab.ui.control.Button
        batchProcessFolder              matlab.ui.control.EditField
        GridLayout39_6                  matlab.ui.container.GridLayout
        ExpOverJpg_2                    matlab.ui.control.CheckBox
        ExpOverTiff_2                   matlab.ui.control.CheckBox
        GridLayout39_7                  matlab.ui.container.GridLayout
        ExpNativeTiff_2                 matlab.ui.control.CheckBox
        ExpNativeJpg_2                  matlab.ui.control.CheckBox
        GridLayout39_8                  matlab.ui.container.GridLayout
        ExpOverPdf_2                    matlab.ui.control.CheckBox
        ExpOverPng_2                    matlab.ui.control.CheckBox
        GridLayout39_9                  matlab.ui.container.GridLayout
        ExpNativePng_2                  matlab.ui.control.CheckBox
        GridLayout39_10                 matlab.ui.container.GridLayout
        ExpNative_2                     matlab.ui.control.CheckBox
        ExpDepthMap_2                   matlab.ui.control.CheckBox
        GridLayout40_2                  matlab.ui.container.GridLayout
        ExpMatFile_2                    matlab.ui.control.CheckBox
        ExpLogFile_2                    matlab.ui.control.CheckBox
        BatchProcessButton              matlab.ui.control.Button
        ProcessingLogTab                matlab.ui.container.Tab
        GridLayout15                    matlab.ui.container.GridLayout
        Label                           matlab.ui.control.Label
        DebugText                       matlab.ui.control.ListBox
        GridLayout4                     matlab.ui.container.GridLayout
        GridLayout52                    matlab.ui.container.GridLayout
        DebugMode                       matlab.ui.control.CheckBox
        GridLayout53                    matlab.ui.container.GridLayout
        cBars                           matlab.ui.control.DropDown
        ColormapLabel                   matlab.ui.control.Label
        StatusText                      matlab.ui.control.TextArea
        SizeInfo                        matlab.ui.container.GridLayout
        nX                              matlab.ui.control.NumericEditField
        nY                              matlab.ui.control.NumericEditField
        nZ                              matlab.ui.control.NumericEditField
        SizexyzLabel                    matlab.ui.control.Label
        dRLabel                         matlab.ui.control.Label
        dR                              matlab.ui.control.NumericEditField
    end

    
    properties (Access = public)
        PPA = PostProBackEnd().empty;
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, rawFilePath)
            app.PPA = PostProBackEnd();
            app.PPA.GUI = app; % transfer handle to app
            app.DebugText.Items{1} = 'FOAM Post Processor';
            if nargin==2
                [~,app.rawfileEditField.Value] = fileparts(rawFilePath);
                app.PPA.filePath = rawFilePath;
                app.PPA.Load_Raw_File();
            end
        end

        % Button pushed function: Load3DorMAPFileButton
        function Load3DorMAPFileButtonPushed(app, event)
            [folderPath] = fileparts(app.PPA.filePath);
            [fileName,folder] = uigetfile(folderPath);
            if fileName
                selectedFilePath = [folder fileName];
            else
                uialert(app.UIFigure,'No file selected!','No file selected!');
                return;
            end
            app.PPA.Load_Raw_File(selectedFilePath);
        end

        % Value changed function: DwnSplCheck
        function DwnSplCheckValueChanged(app, event)
            app.DwnSplFactorEdit.Enable = app.DwnSplCheck.Value;
            app.DepthDwnSplFactorEdit.Enable = app.DwnSplCheck.Value;
            app.PPA.Update_Status();
            app.PPA.Down_Sample_Volume();
        end

        % Value changed function: CropCheck
        function CropCheckValueChanged(app, event)
            app.zCropLowEdit.Enable = app.CropCheck.Value;
            app.zCropHighEdit.Enable = app.CropCheck.Value;
            app.PPA.Update_Status();
            app.PPA.Crop_Volume();
        end

        % Value changed function: DepthDwnSplFactorEdit, 
        % DwnSplFactorEdit
        function DwnSplFactorEditValueChanged(app, event)
            app.PPA.Down_Sample_Volume();
        end

        % Value changed function: zCropLowEdit
        function zCropLowEditValueChanged(app, event)
            app.zCropHighEdit.Limits(1) = app.zCropLowEdit.Value + 1;
            app.PPA.Update_Status();
            app.PPA.Crop_Volume();
        end

        % Value changed function: zCropHighEdit
        function zCropHighEditValueChanged(app, event)
            app.zCropLowEdit.Limits(2) = app.zCropHighEdit.Value - 1;
            app.PPA.Update_Status();
            app.PPA.Crop_Volume();
        end

        % Value changed function: FreqFiltCheck, filtDesign, 
        % filtOrder, filtType, freqHigh, freqLow
        function FreqFiltCheckValueChanged(app, event)
            app.filtDesign.Enable = app.FreqFiltCheck.Value;
            app.filtType.Enable = app.FreqFiltCheck.Value;
            app.MHzLabel.Enable = app.FreqFiltCheck.Value;
            app.freqHigh.Enable = app.FreqFiltCheck.Value;
            app.freqLow.Enable = app.FreqFiltCheck.Value;
            app.filtOrder.Enable = app.FreqFiltCheck.Value;
            app.freqLow.Limits(2) = app.freqHigh.Value - 1;
            app.freqHigh.Limits(1) = app.freqLow.Value + 1;
            app.PPA.Update_Status();
            app.PPA.Freq_Filt_Volume();
        end

        % Value changed function: MedFiltCheck
        function MedFiltCheckValueChanged(app, event)
            app.MedFiltX.Enable = app.MedFiltCheck.Value;
            app.MedFiltY.Enable = app.MedFiltCheck.Value;
            app.MedFiltZ.Enable = app.MedFiltCheck.Value;
            app.PPA.Update_Status();
            app.PPA.Med_Filt_Volume();
        end

        % Value changed function: PolarityCheck, PolarityDropDown
        function PolarityCheckValueChanged(app, event)
            app.PolarityDropDown.Enable = app.PolarityCheck.Value;
            app.PPA.Update_Status();
            app.PPA.Apply_Polarity_Volume();
        end

        % Value changed function: MedFiltX
        function MedFiltXValueChanged(app, event)
            app.MedFiltX.Value = app.PPA.Get_Allowed_Med_Filt_Coeff(app.MedFiltX.Value);
            app.MedFiltCheckValueChanged();
        end

        % Value changed function: MedFiltY
        function MedFiltYValueChanged(app, event)
            app.MedFiltY.Value = app.PPA.Get_Allowed_Med_Filt_Coeff(app.MedFiltY.Value);
            app.MedFiltCheckValueChanged();
        end

        % Value changed function: MedFiltZ
        function MedFiltZValueChanged(app, event)
            app.MedFiltZ.Value = app.PPA.Get_Allowed_Med_Filt_Coeff(app.MedFiltZ.Value);
            app.MedFiltCheckValueChanged();
        end

        % Callback function
        function SelectcoordsButtonPushed(app, event)
            app.PPA.Get_Signal_Location();
        end

        % Value changed function: cBars
        function cBarsValueChanged(app, event)
            colormap(app.FiltDisp,app.cBars.Value);
            colormap(app.imFiltDisp,app.cBars.Value);
        end

        % Value changed function: ContrastSlider, MinAmpEditField, 
        % SmoothPxEditField, TranspEditField, depthColor, 
        % depthoffsetEditField, surfaceoffsetEditField
        function ContrastSliderValueChanged(app, event)
            app.PPA.Update_Depth_Map(app.imDepthDisp);
        end

        % Button pushed function: ExportMapsButton
        function ExportMapsButtonPushed(app, event)
            app.PPA.Export();
        end

        % Button pushed function: SelectExportPathButton
        function SelectExportPathButtonPushed(app, event)
            [fileName,folder] = uigetfile('C:\Data');
            if fileName
                selectedFilePath = [folder fileName];
            else
                uialert(app.UIFigure,'No file selected!','No file selected!');
                return;
            end
            app.PPA.exportPath = selectedFilePath;
            app.expFolderPath.Value = selectedFilePath;
        end

        % Value changed function: SliceWidthEditField
        function SliceWidthEditFieldValueChanged(app, event)
            app.PPA.Update_Slice_Lines();
        end

        % Value changed function: ExpOverview
        function ExpOverviewValueChanged(app, event)
            app.ExpOverJpg.Enable = app.ExpOverview.Value;
            app.ExpOverTiff.Enable = app.ExpOverview.Value;
            app.ExpOverPdf.Enable = app.ExpOverview.Value;
            app.ExpOverPng.Enable = app.ExpOverview.Value;
        end

        % Value changed function: ExpNative
        function ExpNativeValueChanged(app, event)
            app.ExpNativePng.Enable = app.ExpNative.Value;
            app.ExpNativeTiff.Enable = app.ExpNative.Value;
            app.ExpNativeJpg.Enable = app.ExpNative.Value;
            app.ExpDepthMap.Enable = app.ExpNative.Value;
        end

        % Selection change function: TabGroup
        function TabGroupSelectionChanged(app, event)
            if app.TabGroup.SelectedTab == app.ImageProcessingTab
                app.PPA.Update_Depth_Map(app.imDepthDisp);
            end
            if app.TabGroup.SelectedTab == app.VesselFilterTab
                app.PPA.Update_Frangi_Scales();
            end
            
        end

        % Value changed function: AdjustContrastCheckBox, 
        % AutoContrCheckBox, ClaheBins, ClaheClipLim, ClaheDistr, 
        % ClaheTiles, ContrastCheck, ContrastGammaEdit, 
        % ContrastLowLimEdit, ContrastUpLimEdit, 
        % ImGuideSizeEditField, ImGuideSmoothSlider, 
        % ImageGuidedCheckBox, InterpolateCheckBox, 
        % SpotRemovalCheckBox, UnsharpMaskAmountSlider, 
        % UnsharpMaskRadiusEditField, 
        % UnsharpMaskThresholdEditField, UnsharpMaskingCheckBox, 
        % WienerCheckBox, WienerSize, imInterpFct, imSpotRem
        function Apply_Image_Processing(app, event)
            app.PPA.Apply_Image_Processing();
        end

        % Value changed function: ScalesTextField, 
        % StartScaleEditField, StopScaleEditField, 
        % TotalScalesEditField
        function UpdateScaleTable(app, event)
            app.PPA.Update_Frangi_Scales();
        end

        % Value changed function: ScaleRangeCheckBox
        function ScaleRangeCheckBoxValueChanged(app, event)
            app.ManualScalesCheckBox.Value = ~app.ScaleRangeCheckBox.Value;
            app.PPA.Update_Frangi_Scales();
        end

        % Value changed function: ManualScalesCheckBox
        function ManualScalesCheckBoxValueChanged(app, event)
            app.ScaleRangeCheckBox.Value = ~app.ManualScalesCheckBox.Value;
            app.PPA.Update_Frangi_Scales();
        end

        % Callback function: UITable, UITable
        function FrangiTableSelected(app, event)
            selectedRow = event.Indices(1);
            app.PPA.Plot_Frangi(true,selectedRow);
        end

        % Display data changed function: UITable
        function FrangiTableChanged(app, event)
            app.PPA.Update_Frangi_Combo();
            app.PPA.Plot_Frangi(false);
        end

        % Value changed function: UseFrangiCheckBox
        function UseFrangiCheckBoxValueChanged(app, event)
            app.PPA.Apply_Image_Processing();
        end

        % Button pushed function: ApplyButton
        function ApplyButtonPushed(app, event)
            app.PPA.Apply_Frangi();
        end

        % Button pushed function: BrowseFolderButton
        function BrowseFolderButtonPushed(app, event)
            folder = uigetdir('C:\data_temp\');
            if folder
                selectedFilePath = folder;
            else
                uialert(app.UIFigure,'No folder selected!','No file selected!');
                return;
            end
            app.PPA.batchPath = folder;
            app.batchProcessFolder.Value = selectedFilePath;
        end

        % Button pushed function: BatchProcessButton
        function BatchProcessButtonPushed(app, event)
            app.PPA.Batch_Process_Folder();
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'FOAM Post Pro App';
            app.NotinuseyetMenu = uimenu(app.UIFigure);
            app.NotinuseyetMenu.Enable = 'off';
            app.NotinuseyetMenu.Text = 'Not in use yet';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.TabGroup = uitabgroup(app.GridLayout);
            app.TabGroup.SelectionChangedFcn = createCallbackFcn(app, @TabGroupSelectionChanged, true);
            app.VolTab = uitab(app.TabGroup);
            app.VolTab.Title = 'Volume Processing';
            app.MainGrid = uigridlayout(app.VolTab);
            app.SettingsGrid = uigridlayout(app.MainGrid);
            app.FrequencyFilteringSettingsPanel = uipanel(app.SettingsGrid);
            app.FrequencyFilteringSettingsPanel.Title = 'Frequency Filtering Settings';
            app.GridLayout26 = uigridlayout(app.FrequencyFilteringSettingsPanel);
            app.FiltResponseAx = uiaxes(app.GridLayout26);
            title(app.FiltResponseAx, '')
            xlabel(app.FiltResponseAx, '')
            ylabel(app.FiltResponseAx, '')
            app.GridLayout27 = uigridlayout(app.GridLayout26);
            app.GridLayout28 = uigridlayout(app.GridLayout27);
            app.MHzLabel = uilabel(app.GridLayout28);
            app.MHzLabel.Text = 'MHz';
            app.HighLabel = uilabel(app.GridLayout28);
            app.HighLabel.Text = 'High';
            app.freqHigh = uieditfield(app.GridLayout28, 'numeric');
            app.freqHigh.Limits = [0.001 125];
            app.freqHigh.ValueChangedFcn = createCallbackFcn(app, @FreqFiltCheckValueChanged, true);
            app.freqHigh.Value = 50;
            app.LowLabel = uilabel(app.GridLayout28);
            app.LowLabel.Text = 'Low';
            app.freqLow = uieditfield(app.GridLayout28, 'numeric');
            app.freqLow.Limits = [0.001 125];
            app.freqLow.ValueChangedFcn = createCallbackFcn(app, @FreqFiltCheckValueChanged, true);
            app.freqLow.Value = 5;
            app.OrderLabel = uilabel(app.GridLayout28);
            app.OrderLabel.Text = 'Order';
            app.filtOrder = uieditfield(app.GridLayout28, 'numeric');
            app.filtOrder.Limits = [1 25];
            app.filtOrder.ValueChangedFcn = createCallbackFcn(app, @FreqFiltCheckValueChanged, true);
            app.filtOrder.Value = 3;
            app.GridLayout29 = uigridlayout(app.GridLayout27);
            app.filtDesign = uidropdown(app.GridLayout29);
            app.filtDesign.Items = {'butterworth', 'chebyshev'};
            app.filtDesign.ValueChangedFcn = createCallbackFcn(app, @FreqFiltCheckValueChanged, true);
            app.filtDesign.Value = 'butterworth';
            app.TypeDropDownLabel = uilabel(app.GridLayout29);
            app.TypeDropDownLabel.Text = 'Type';
            app.filtType = uidropdown(app.GridLayout29);
            app.filtType.Items = {'bandpass', 'highpass', ''};
            app.filtType.ValueChangedFcn = createCallbackFcn(app, @FreqFiltCheckValueChanged, true);
            app.filtType.Value = 'bandpass';
            app.GridVolSettings = uigridlayout(app.SettingsGrid);
            app.GridLayout25 = uigridlayout(app.GridVolSettings);
            app.DwnSplCheck = uicheckbox(app.GridLayout25);
            app.DwnSplCheck.ValueChangedFcn = createCallbackFcn(app, @DwnSplCheckValueChanged, true);
            app.DwnSplCheck.Tooltip = {'Apply xy downsampling (leave z-dimension untouched)?'; ''};
            app.DwnSplCheck.Text = 'Downsampling';
            app.GridLayout51 = uigridlayout(app.GridLayout25);
            app.xyLabel = uilabel(app.GridLayout51);
            app.xyLabel.Enable = 'off';
            app.xyLabel.Text = 'xy';
            app.DwnSplFactorEdit = uieditfield(app.GridLayout51, 'numeric');
            app.DwnSplFactorEdit.Limits = [1 Inf];
            app.DwnSplFactorEdit.RoundFractionalValues = 'on';
            app.DwnSplFactorEdit.ValueChangedFcn = createCallbackFcn(app, @DwnSplFactorEditValueChanged, true);
            app.DwnSplFactorEdit.Enable = 'off';
            app.DwnSplFactorEdit.Value = 1;
            app.GridLayout51_2 = uigridlayout(app.GridLayout25);
            app.zLabel = uilabel(app.GridLayout51_2);
            app.zLabel.Enable = 'off';
            app.zLabel.Text = 'z';
            app.DepthDwnSplFactorEdit = uieditfield(app.GridLayout51_2, 'numeric');
            app.DepthDwnSplFactorEdit.Limits = [1 Inf];
            app.DepthDwnSplFactorEdit.RoundFractionalValues = 'on';
            app.DepthDwnSplFactorEdit.ValueChangedFcn = createCallbackFcn(app, @DwnSplFactorEditValueChanged, true);
            app.DepthDwnSplFactorEdit.Enable = 'off';
            app.DepthDwnSplFactorEdit.Value = 2;
            app.GridLayout25_2 = uigridlayout(app.GridVolSettings);
            app.CropCheck = uicheckbox(app.GridLayout25_2);
            app.CropCheck.ValueChangedFcn = createCallbackFcn(app, @CropCheckValueChanged, true);
            app.CropCheck.Text = 'zCrop';
            app.zCropHighEdit = uieditfield(app.GridLayout25_2, 'numeric');
            app.zCropHighEdit.Limits = [1 Inf];
            app.zCropHighEdit.RoundFractionalValues = 'on';
            app.zCropHighEdit.ValueChangedFcn = createCallbackFcn(app, @zCropHighEditValueChanged, true);
            app.zCropHighEdit.Value = 1500;
            app.zCropLowEdit = uieditfield(app.GridLayout25_2, 'numeric');
            app.zCropLowEdit.Limits = [1 Inf];
            app.zCropLowEdit.RoundFractionalValues = 'on';
            app.zCropLowEdit.ValueChangedFcn = createCallbackFcn(app, @zCropLowEditValueChanged, true);
            app.zCropLowEdit.Value = 1;
            app.GridLayout25_3 = uigridlayout(app.GridVolSettings);
            app.FreqFiltCheck = uicheckbox(app.GridLayout25_3);
            app.FreqFiltCheck.ValueChangedFcn = createCallbackFcn(app, @FreqFiltCheckValueChanged, true);
            app.FreqFiltCheck.Text = 'Frequency Filter';
            app.FreqFiltCheck.Value = true;
            app.GridLayout25_4 = uigridlayout(app.GridVolSettings);
            app.MedFiltCheck = uicheckbox(app.GridLayout25_4);
            app.MedFiltCheck.ValueChangedFcn = createCallbackFcn(app, @MedFiltCheckValueChanged, true);
            app.MedFiltCheck.Text = 'Median Filter';
            app.MedFiltZ = uieditfield(app.GridLayout25_4, 'numeric');
            app.MedFiltZ.Limits = [1 Inf];
            app.MedFiltZ.RoundFractionalValues = 'on';
            app.MedFiltZ.ValueChangedFcn = createCallbackFcn(app, @MedFiltZValueChanged, true);
            app.MedFiltZ.Enable = 'off';
            app.MedFiltZ.Value = 3;
            app.MedFiltY = uieditfield(app.GridLayout25_4, 'numeric');
            app.MedFiltY.Limits = [1 Inf];
            app.MedFiltY.RoundFractionalValues = 'on';
            app.MedFiltY.ValueChangedFcn = createCallbackFcn(app, @MedFiltYValueChanged, true);
            app.MedFiltY.Enable = 'off';
            app.MedFiltY.Value = 1;
            app.MedFiltX = uieditfield(app.GridLayout25_4, 'numeric');
            app.MedFiltX.Limits = [1 Inf];
            app.MedFiltX.RoundFractionalValues = 'on';
            app.MedFiltX.ValueChangedFcn = createCallbackFcn(app, @MedFiltXValueChanged, true);
            app.MedFiltX.Enable = 'off';
            app.MedFiltX.Value = 1;
            app.GridLayout25_5 = uigridlayout(app.GridVolSettings);
            app.PolarityCheck = uicheckbox(app.GridLayout25_5);
            app.PolarityCheck.ValueChangedFcn = createCallbackFcn(app, @PolarityCheckValueChanged, true);
            app.PolarityCheck.Text = 'Polarity';
            app.PolarityDropDown = uidropdown(app.GridLayout25_5);
            app.PolarityDropDown.Items = {'Positive', 'Negative', 'Absolute', 'Envelope'};
            app.PolarityDropDown.ValueChangedFcn = createCallbackFcn(app, @PolarityCheckValueChanged, true);
            app.PolarityDropDown.Enable = 'off';
            app.PolarityDropDown.Value = 'Positive';
            app.GridLayout25_6 = uigridlayout(app.GridVolSettings);
            app.Load3DorMAPFileButton = uibutton(app.GridLayout25_6, 'push');
            app.Load3DorMAPFileButton.ButtonPushedFcn = createCallbackFcn(app, @Load3DorMAPFileButtonPushed, true);
            app.Load3DorMAPFileButton.Text = 'Load 3D or MAP File';
            app.rawfileEditField = uieditfield(app.GridLayout25_6, 'text');
            app.rawfileEditField.Editable = 'off';
            app.rawfileEditField.Value = 'press "Load File..."';
            app.GridLayout35 = uigridlayout(app.SettingsGrid);
            app.SliceWidthLabel = uilabel(app.GridLayout35);
            app.SliceWidthLabel.Text = 'Slice Width';
            app.SliceWidthEditField = uieditfield(app.GridLayout35, 'numeric');
            app.SliceWidthEditField.ValueChangedFcn = createCallbackFcn(app, @SliceWidthEditFieldValueChanged, true);
            app.SliceWidthEditField.Value = 0.5;
            app.GridLayout12 = uigridlayout(app.MainGrid);
            app.FiltDisp = uiaxes(app.GridLayout12);
            title(app.FiltDisp, '')
            app.yzSliceDisp = uiaxes(app.GridLayout12);
            title(app.yzSliceDisp, 'YZ Slice')
            app.xzSliceDisp = uiaxes(app.GridLayout12);
            title(app.xzSliceDisp, 'XZ Slice')
            app.GridLayout34 = uigridlayout(app.GridLayout12);
            app.xzProjDisp = uiaxes(app.GridLayout34);
            title(app.xzProjDisp, {'YZ Projection'; ''})
            app.yzProjDisp = uiaxes(app.GridLayout34);
            title(app.yzProjDisp, {'YZ Projection'; ''})
            app.ImageProcessingTab = uitab(app.TabGroup);
            app.ImageProcessingTab.Title = 'Image Processing';
            app.mainGridImProc = uigridlayout(app.ImageProcessingTab);
            app.settingsGrid = uigridlayout(app.mainGridImProc);
            app.ImageProcessinginorderPanel = uipanel(app.settingsGrid);
            app.ImageProcessinginorderPanel.Title = 'Image Processing (in order)';
            app.GridLayout11 = uigridlayout(app.ImageProcessinginorderPanel);
            app.ContrastCheck = uicheckbox(app.GridLayout11);
            app.ContrastCheck.ValueChangedFcn = createCallbackFcn(app, @Apply_Image_Processing, true);
            app.ContrastCheck.Text = 'CLAHE';
            app.ContrastCheck.Value = true;
            app.UnsharpMaskingCheckBox = uicheckbox(app.GridLayout11);
            app.UnsharpMaskingCheckBox.ValueChangedFcn = createCallbackFcn(app, @Apply_Image_Processing, true);
            app.UnsharpMaskingCheckBox.Text = 'Unsharp Masking';
            app.GridLayout22 = uigridlayout(app.GridLayout11);
            app.imSpotRem = uieditfield(app.GridLayout22, 'numeric');
            app.imSpotRem.Limits = [1 20];
            app.imSpotRem.RoundFractionalValues = 'on';
            app.imSpotRem.ValueChangedFcn = createCallbackFcn(app, @Apply_Image_Processing, true);
            app.imSpotRem.Value = 5;
            app.SpotRemovalCheckBox = uicheckbox(app.GridLayout22);
            app.SpotRemovalCheckBox.ValueChangedFcn = createCallbackFcn(app, @Apply_Image_Processing, true);
            app.SpotRemovalCheckBox.Text = {'Spot'; 'Removal'};
            app.GridLayout22_4 = uigridlayout(app.GridLayout11);
            app.imInterpFct = uieditfield(app.GridLayout22_4, 'numeric');
            app.imInterpFct.Limits = [0.1 10];
            app.imInterpFct.ValueChangedFcn = createCallbackFcn(app, @Apply_Image_Processing, true);
            app.imInterpFct.Value = 2;
            app.InterpolateCheckBox = uicheckbox(app.GridLayout22_4);
            app.InterpolateCheckBox.ValueChangedFcn = createCallbackFcn(app, @Apply_Image_Processing, true);
            app.InterpolateCheckBox.Text = 'Interpolate';
            app.GridLayout22_7 = uigridlayout(app.GridLayout11);
            app.WienerSize = uieditfield(app.GridLayout22_7, 'numeric');
            app.WienerSize.Limits = [3 50];
            app.WienerSize.RoundFractionalValues = 'on';
            app.WienerSize.ValueChangedFcn = createCallbackFcn(app, @Apply_Image_Processing, true);
            app.WienerSize.Value = 3;
            app.WienerCheckBox = uicheckbox(app.GridLayout22_7);
            app.WienerCheckBox.ValueChangedFcn = createCallbackFcn(app, @Apply_Image_Processing, true);
            app.WienerCheckBox.Text = 'Wiener';
            app.ImageGuidedCheckBox = uicheckbox(app.GridLayout11);
            app.ImageGuidedCheckBox.ValueChangedFcn = createCallbackFcn(app, @Apply_Image_Processing, true);
            app.ImageGuidedCheckBox.Text = 'Image Guided';
            app.AdjustContrastCheckBox = uicheckbox(app.GridLayout11);
            app.AdjustContrastCheckBox.ValueChangedFcn = createCallbackFcn(app, @Apply_Image_Processing, true);
            app.AdjustContrastCheckBox.Text = 'Adjust Contrast';
            app.AdjustContrastCheckBox.Value = true;
            app.UseFrangiCheckBox = uicheckbox(app.GridLayout11);
            app.UseFrangiCheckBox.ValueChangedFcn = createCallbackFcn(app, @UseFrangiCheckBoxValueChanged, true);
            app.UseFrangiCheckBox.Text = 'Use Frangi';
            app.DepthMapSettingsPanel = uipanel(app.settingsGrid);
            app.DepthMapSettingsPanel.Title = 'Depth Map Settings';
            app.GridLayout17 = uigridlayout(app.DepthMapSettingsPanel);
            app.GridLayout30 = uigridlayout(app.GridLayout17);
            app.ContrastSlider = uislider(app.GridLayout30);
            app.ContrastSlider.MajorTicks = [];
            app.ContrastSlider.MajorTickLabels = {''};
            app.ContrastSlider.ValueChangedFcn = createCallbackFcn(app, @ContrastSliderValueChanged, true);
            app.ContrastSlider.MinorTicks = [1 25 50 75 100];
            app.ContrastLabel = uilabel(app.GridLayout30);
            app.ContrastLabel.Text = 'Contrast';
            app.GridLayout30_2 = uigridlayout(app.GridLayout17);
            app.GridLayout30_5 = uigridlayout(app.GridLayout30_2);
            app.surfaceoffsetEditFieldLabel = uilabel(app.GridLayout30_5);
            app.surfaceoffsetEditFieldLabel.Text = {'% surface'; 'offset'};
            app.surfaceoffsetEditField = uieditfield(app.GridLayout30_5, 'numeric');
            app.surfaceoffsetEditField.Limits = [0 100];
            app.surfaceoffsetEditField.RoundFractionalValues = 'on';
            app.surfaceoffsetEditField.ValueChangedFcn = createCallbackFcn(app, @ContrastSliderValueChanged, true);
            app.GridLayout30_6 = uigridlayout(app.GridLayout30_2);
            app.depthoffsetEditFieldLabel = uilabel(app.GridLayout30_6);
            app.depthoffsetEditFieldLabel.Text = {'% depth'; 'offset'};
            app.depthoffsetEditField = uieditfield(app.GridLayout30_6, 'numeric');
            app.depthoffsetEditField.Limits = [0 100];
            app.depthoffsetEditField.RoundFractionalValues = 'on';
            app.depthoffsetEditField.ValueChangedFcn = createCallbackFcn(app, @ContrastSliderValueChanged, true);
            app.GridLayout30_3 = uigridlayout(app.GridLayout17);
            app.GridLayout30_10 = uigridlayout(app.GridLayout30_3);
            app.TranspLabel = uilabel(app.GridLayout30_10);
            app.TranspLabel.Text = 'Transp. (%)';
            app.TranspEditField = uieditfield(app.GridLayout30_10, 'numeric');
            app.TranspEditField.Limits = [0 500];
            app.TranspEditField.RoundFractionalValues = 'on';
            app.TranspEditField.ValueChangedFcn = createCallbackFcn(app, @ContrastSliderValueChanged, true);
            app.TranspEditField.Value = 110;
            app.GridLayout30_9 = uigridlayout(app.GridLayout30_3);
            app.MinAmpEditFieldLabel = uilabel(app.GridLayout30_9);
            app.MinAmpEditFieldLabel.Text = {'Min.'; 'Amp (%)'};
            app.MinAmpEditField = uieditfield(app.GridLayout30_9, 'numeric');
            app.MinAmpEditField.Limits = [0 100];
            app.MinAmpEditField.RoundFractionalValues = 'on';
            app.MinAmpEditField.ValueChangedFcn = createCallbackFcn(app, @ContrastSliderValueChanged, true);
            app.MinAmpEditField.Value = 50;
            app.GridLayout30_4 = uigridlayout(app.GridLayout17);
            app.GridLayout30_11 = uigridlayout(app.GridLayout30_4);
            app.SmoothPxEditFieldLabel = uilabel(app.GridLayout30_11);
            app.SmoothPxEditFieldLabel.Text = {'Smooth'; '(Px)'};
            app.SmoothPxEditField = uieditfield(app.GridLayout30_11, 'numeric');
            app.SmoothPxEditField.Limits = [1 50];
            app.SmoothPxEditField.ValueChangedFcn = createCallbackFcn(app, @ContrastSliderValueChanged, true);
            app.SmoothPxEditField.Value = 5;
            app.GridLayout30_12 = uigridlayout(app.GridLayout30_4);
            app.depthColor = uidropdown(app.GridLayout30_12);
            app.depthColor.Items = {'hot', 'jet', 'parula', 'BOG', 'BGO', 'GOB', 'GBO', 'OGB', 'OBG', 'GR', 'GO', 'ROG', ''};
            app.depthColor.ValueChangedFcn = createCallbackFcn(app, @ContrastSliderValueChanged, true);
            app.depthColor.Tooltip = {'select colomap for image display'; ''};
            app.depthColor.Value = 'BGO';
            app.DepthMapLabel = uilabel(app.GridLayout30_12);
            app.DepthMapLabel.Text = 'Depth Map';
            app.histoAx = uiaxes(app.GridLayout17);
            title(app.histoAx, 'Depth Histogram ')
            xlabel(app.histoAx, '')
            ylabel(app.histoAx, '')
            app.histoAx.YTick = [];
            app.histoAx.YTickLabel = {};
            app.CLAHESettingsPanel = uipanel(app.settingsGrid);
            app.CLAHESettingsPanel.Title = 'CLAHE Settings';
            app.GridLayout16 = uigridlayout(app.CLAHESettingsPanel);
            app.GridLayout22_2 = uigridlayout(app.GridLayout16);
            app.TilesDropDownLabel = uilabel(app.GridLayout22_2);
            app.TilesDropDownLabel.Text = 'Tiles';
            app.ClaheTiles = uidropdown(app.GridLayout22_2);
            app.ClaheTiles.Items = {'8', '16', '32', '64', '128', '256', '512'};
            app.ClaheTiles.ValueChangedFcn = createCallbackFcn(app, @Apply_Image_Processing, true);
            app.ClaheTiles.Value = '32';
            app.GridLayout22_3 = uigridlayout(app.GridLayout16);
            app.BinsLabel = uilabel(app.GridLayout22_3);
            app.BinsLabel.Text = 'Bins';
            app.ClaheBins = uidropdown(app.GridLayout22_3);
            app.ClaheBins.Items = {'8', '16', '32', '64', '128', '256', '512', '1024', '2048'};
            app.ClaheBins.ValueChangedFcn = createCallbackFcn(app, @Apply_Image_Processing, true);
            app.ClaheBins.Value = '256';
            app.DistributionLabel = uilabel(app.GridLayout16);
            app.DistributionLabel.Text = 'Distribution';
            app.ClaheDistr = uidropdown(app.GridLayout16);
            app.ClaheDistr.Items = {'uniform', 'rayleigh', 'exponential'};
            app.ClaheDistr.ValueChangedFcn = createCallbackFcn(app, @Apply_Image_Processing, true);
            app.ClaheDistr.Value = 'uniform';
            app.ClipLimitSliderLabel = uilabel(app.GridLayout16);
            app.ClipLimitSliderLabel.Text = 'Clip Limit';
            app.ClaheClipLim = uislider(app.GridLayout16);
            app.ClaheClipLim.Limits = [0.0001 0.1];
            app.ClaheClipLim.MajorTicks = [];
            app.ClaheClipLim.MajorTickLabels = {};
            app.ClaheClipLim.ValueChangedFcn = createCallbackFcn(app, @Apply_Image_Processing, true);
            app.ClaheClipLim.MinorTicks = [];
            app.ClaheClipLim.Value = 0.02;
            app.ImageGuidedFilterSettingsPanel = uipanel(app.settingsGrid);
            app.ImageGuidedFilterSettingsPanel.Title = 'Image-Guided Filter Settings';
            app.GridLayout16_2 = uigridlayout(app.ImageGuidedFilterSettingsPanel);
            app.GridLayout22_10 = uigridlayout(app.GridLayout16_2);
            app.NbhSizeLabel = uilabel(app.GridLayout22_10);
            app.NbhSizeLabel.Text = {'Nbh'; 'Size'};
            app.ImGuideSizeEditField = uieditfield(app.GridLayout22_10, 'numeric');
            app.ImGuideSizeEditField.Limits = [1 100];
            app.ImGuideSizeEditField.RoundFractionalValues = 'on';
            app.ImGuideSizeEditField.ValueChangedFcn = createCallbackFcn(app, @Apply_Image_Processing, true);
            app.ImGuideSizeEditField.Enable = 'off';
            app.ImGuideSizeEditField.Value = 5;
            app.GridLayout22_21 = uigridlayout(app.GridLayout16_2);
            app.SmoothSliderLabel = uilabel(app.GridLayout22_21);
            app.SmoothSliderLabel.Text = 'Smooth';
            app.ImGuideSmoothSlider = uislider(app.GridLayout22_21);
            app.ImGuideSmoothSlider.Limits = [1e-05 0.5];
            app.ImGuideSmoothSlider.MajorTicks = [];
            app.ImGuideSmoothSlider.MajorTickLabels = {''};
            app.ImGuideSmoothSlider.ValueChangedFcn = createCallbackFcn(app, @Apply_Image_Processing, true);
            app.ImGuideSmoothSlider.MinorTicks = [];
            app.ImGuideSmoothSlider.Enable = 'off';
            app.ImGuideSmoothSlider.Value = 0.01;
            app.UnsharpMaskingSettingsPanel = uipanel(app.settingsGrid);
            app.UnsharpMaskingSettingsPanel.Title = '  Unsharp Masking Settings';
            app.GridLayout16_3 = uigridlayout(app.UnsharpMaskingSettingsPanel);
            app.GridLayout22_16 = uigridlayout(app.GridLayout16_3);
            app.RadiusEditFieldLabel = uilabel(app.GridLayout22_16);
            app.RadiusEditFieldLabel.Text = 'Radius';
            app.UnsharpMaskRadiusEditField = uieditfield(app.GridLayout22_16, 'numeric');
            app.UnsharpMaskRadiusEditField.Limits = [0.01 20];
            app.UnsharpMaskRadiusEditField.ValueDisplayFormat = '%11.2f';
            app.UnsharpMaskRadiusEditField.ValueChangedFcn = createCallbackFcn(app, @Apply_Image_Processing, true);
            app.UnsharpMaskRadiusEditField.Enable = 'off';
            app.UnsharpMaskRadiusEditField.Tooltip = {'Standard deviation of the Gaussian lowpass filter, specified as a positive number. This value controls the size of the region around the edge pixels that is affected by sharpening. A large value sharpens wider regions around the edges, whereas a small value sharpens narrower regions around edges.'};
            app.UnsharpMaskRadiusEditField.Value = 1;
            app.GridLayout22_17 = uigridlayout(app.GridLayout16_3);
            app.AmountSliderLabel = uilabel(app.GridLayout22_17);
            app.AmountSliderLabel.Text = 'Amount';
            app.UnsharpMaskAmountSlider = uislider(app.GridLayout22_17);
            app.UnsharpMaskAmountSlider.Limits = [0 3];
            app.UnsharpMaskAmountSlider.MajorTicks = [];
            app.UnsharpMaskAmountSlider.MajorTickLabels = {''};
            app.UnsharpMaskAmountSlider.ValueChangedFcn = createCallbackFcn(app, @Apply_Image_Processing, true);
            app.UnsharpMaskAmountSlider.MinorTicks = [];
            app.UnsharpMaskAmountSlider.Enable = 'off';
            app.UnsharpMaskAmountSlider.Tooltip = {'Strength of the sharpening effect, specified as a numeric scalar. A higher value leads to larger increase in the contrast of the sharpened pixels. Typical values for this parameter are within the range [0 2], although values greater than 2 are allowed. Very large values for this parameter may create undesirable effects in the output image.'};
            app.UnsharpMaskAmountSlider.Value = 0.8;
            app.GridLayout22_20 = uigridlayout(app.GridLayout16_3);
            app.ThresholdLabel = uilabel(app.GridLayout22_20);
            app.ThresholdLabel.Text = 'Threshold';
            app.UnsharpMaskThresholdEditField = uieditfield(app.GridLayout22_20, 'numeric');
            app.UnsharpMaskThresholdEditField.Limits = [0 1];
            app.UnsharpMaskThresholdEditField.ValueDisplayFormat = '%11.2f';
            app.UnsharpMaskThresholdEditField.ValueChangedFcn = createCallbackFcn(app, @Apply_Image_Processing, true);
            app.UnsharpMaskThresholdEditField.Enable = 'off';
            app.UnsharpMaskThresholdEditField.Tooltip = {'Minimum contrast required for a pixel to be considered an edge pixel, specified as a scalar in the range [0 1]. Higher values (closer to 1) allow sharpening only in high-contrast regions, such as strong edges, while leaving low-contrast regions unaffected. Lower values (closer to 0) additionally allow sharpening in relatively smoother regions of the image. This parameter is useful in avoiding sharpening noise in the output image.'};
            app.UnsharpMaskThresholdEditField.Value = 0.1;
            app.AdjustContrastSettingsPanel = uipanel(app.settingsGrid);
            app.AdjustContrastSettingsPanel.Title = 'Adjust Contrast Settings';
            app.GridLayout16_4 = uigridlayout(app.AdjustContrastSettingsPanel);
            app.GridLayout41 = uigridlayout(app.GridLayout16_4);
            app.LowerLimitLabel = uilabel(app.GridLayout41);
            app.LowerLimitLabel.Text = {'Lower'; 'Limit'};
            app.ContrastLowLimEdit = uieditfield(app.GridLayout41, 'numeric');
            app.ContrastLowLimEdit.Limits = [0 1];
            app.ContrastLowLimEdit.ValueDisplayFormat = '%4.2f';
            app.ContrastLowLimEdit.ValueChangedFcn = createCallbackFcn(app, @Apply_Image_Processing, true);
            app.ContrastLowLimEdit.Enable = 'off';
            app.GridLayout42 = uigridlayout(app.GridLayout16_4);
            app.UpperLimitLabel = uilabel(app.GridLayout42);
            app.UpperLimitLabel.Text = {'Upper'; 'Limit'};
            app.ContrastUpLimEdit = uieditfield(app.GridLayout42, 'numeric');
            app.ContrastUpLimEdit.Limits = [0 1];
            app.ContrastUpLimEdit.ValueDisplayFormat = '%4.2f';
            app.ContrastUpLimEdit.ValueChangedFcn = createCallbackFcn(app, @Apply_Image_Processing, true);
            app.ContrastUpLimEdit.Enable = 'off';
            app.ContrastUpLimEdit.Value = 1;
            app.GridLayout42_2 = uigridlayout(app.GridLayout16_4);
            app.GammaLabel = uilabel(app.GridLayout42_2);
            app.GammaLabel.Text = 'Gamma';
            app.ContrastGammaEdit = uieditfield(app.GridLayout42_2, 'numeric');
            app.ContrastGammaEdit.Limits = [0 10];
            app.ContrastGammaEdit.ValueDisplayFormat = '%4.2f';
            app.ContrastGammaEdit.ValueChangedFcn = createCallbackFcn(app, @Apply_Image_Processing, true);
            app.ContrastGammaEdit.Enable = 'off';
            app.ContrastGammaEdit.Value = 1.25;
            app.AutoContrCheckBox = uicheckbox(app.GridLayout16_4);
            app.AutoContrCheckBox.ValueChangedFcn = createCallbackFcn(app, @Apply_Image_Processing, true);
            app.AutoContrCheckBox.Enable = 'off';
            app.AutoContrCheckBox.Text = 'Auto Calc Limits';
            app.AutoContrCheckBox.Value = true;
            app.GridLayout13 = uigridlayout(app.mainGridImProc);
            app.imDepthDisp = uiaxes(app.GridLayout13);
            title(app.imDepthDisp, '')
            xlabel(app.imDepthDisp, '')
            ylabel(app.imDepthDisp, '')
            app.imFiltDisp = uiaxes(app.GridLayout13);
            title(app.imFiltDisp, '')
            app.VesselFilterTab = uitab(app.TabGroup);
            app.VesselFilterTab.Title = 'Vessel Filter';
            app.GridLayout43 = uigridlayout(app.VesselFilterTab);
            app.GridLayout44 = uigridlayout(app.GridLayout43);
            app.imFrangiFilt = uiaxes(app.GridLayout44);
            title(app.imFrangiFilt, 'Frangi Filtered')
            app.imFrangiFilt.XTick = [];
            app.imFrangiFilt.YTick = [];
            app.imFrangiCombined = uiaxes(app.GridLayout44);
            title(app.imFrangiCombined, 'Combined')
            app.imFrangiCombined.XTick = [];
            app.imFrangiCombined.YTick = [];
            app.imFrangiFiltIn = uiaxes(app.GridLayout44);
            title(app.imFrangiFiltIn, {'Filter Input'; ''})
            app.imFrangiFiltIn.XTick = [];
            app.imFrangiFiltIn.YTick = [];
            app.imFrangiScale = uiaxes(app.GridLayout44);
            title(app.imFrangiScale, {'Scale'; ''})
            app.imFrangiScale.XTick = [];
            app.imFrangiScale.YTick = [];
            app.GridLayout45 = uigridlayout(app.GridLayout43);
            app.FrangiSettingsPanel = uipanel(app.GridLayout45);
            app.FrangiSettingsPanel.Title = 'Frangi Settings';
            app.GridLayout47 = uigridlayout(app.FrangiSettingsPanel);
            app.GridLayout48 = uigridlayout(app.GridLayout47);
            app.GridLayout49_2 = uigridlayout(app.GridLayout48);
            app.StartScaleEditFieldLabel = uilabel(app.GridLayout49_2);
            app.StartScaleEditFieldLabel.Text = 'Start Scale';
            app.StartScaleEditField = uieditfield(app.GridLayout49_2, 'numeric');
            app.StartScaleEditField.Limits = [0.01 200];
            app.StartScaleEditField.ValueDisplayFormat = '%11.1f';
            app.StartScaleEditField.ValueChangedFcn = createCallbackFcn(app, @UpdateScaleTable, true);
            app.StartScaleEditField.Value = 1;
            app.GridLayout49_3 = uigridlayout(app.GridLayout48);
            app.StopScaleEditFieldLabel = uilabel(app.GridLayout49_3);
            app.StopScaleEditFieldLabel.Text = 'Stop Scale';
            app.StopScaleEditField = uieditfield(app.GridLayout49_3, 'numeric');
            app.StopScaleEditField.Limits = [1 500];
            app.StopScaleEditField.RoundFractionalValues = 'on';
            app.StopScaleEditField.ValueDisplayFormat = '%11.1f';
            app.StopScaleEditField.ValueChangedFcn = createCallbackFcn(app, @UpdateScaleTable, true);
            app.StopScaleEditField.Value = 20;
            app.ScaleRangeCheckBox = uicheckbox(app.GridLayout48);
            app.ScaleRangeCheckBox.ValueChangedFcn = createCallbackFcn(app, @ScaleRangeCheckBoxValueChanged, true);
            app.ScaleRangeCheckBox.Text = 'Scale Range';
            app.ScaleRangeCheckBox.Value = true;
            app.ManualScalesCheckBox = uicheckbox(app.GridLayout48);
            app.ManualScalesCheckBox.ValueChangedFcn = createCallbackFcn(app, @ManualScalesCheckBoxValueChanged, true);
            app.ManualScalesCheckBox.Text = 'Manual Scales';
            app.GridLayout49_4 = uigridlayout(app.GridLayout48);
            app.TotalScalesEditFieldLabel = uilabel(app.GridLayout49_4);
            app.TotalScalesEditFieldLabel.Text = 'Total Scales';
            app.TotalScalesEditField = uieditfield(app.GridLayout49_4, 'numeric');
            app.TotalScalesEditField.Limits = [1 1000];
            app.TotalScalesEditField.RoundFractionalValues = 'on';
            app.TotalScalesEditField.ValueChangedFcn = createCallbackFcn(app, @UpdateScaleTable, true);
            app.TotalScalesEditField.Value = 5;
            app.ScalesTextField = uieditfield(app.GridLayout48, 'text');
            app.ScalesTextField.ValueChangedFcn = createCallbackFcn(app, @UpdateScaleTable, true);
            app.ScalesTextField.Value = '1 5 10';
            app.GridLayout49 = uigridlayout(app.GridLayout48);
            app.SensitivityEditFieldLabel = uilabel(app.GridLayout49);
            app.SensitivityEditFieldLabel.Text = 'Sensitivity';
            app.SensitivityEditField = uieditfield(app.GridLayout49, 'numeric');
            app.SensitivityEditField.Limits = [0.001 100];
            app.SensitivityEditField.ValueDisplayFormat = '%4.2f';
            app.SensitivityEditField.Value = 0.05;
            app.InvertedCheckBox = uicheckbox(app.GridLayout48);
            app.InvertedCheckBox.Text = 'Inverted?';
            app.GridLayout50 = uigridlayout(app.GridLayout47);
            app.ApplyButton = uibutton(app.GridLayout50, 'push');
            app.ApplyButton.ButtonPushedFcn = createCallbackFcn(app, @ApplyButtonPushed, true);
            app.ApplyButton.Text = 'Apply';
            app.SelectFrangiScalesPanel = uipanel(app.GridLayout45);
            app.SelectFrangiScalesPanel.Title = 'Select Frangi Scales';
            app.GridLayout46 = uigridlayout(app.SelectFrangiScalesPanel);
            app.UITable = uitable(app.GridLayout46);
            app.UITable.ColumnName = {'scale'; 'selected'};
            app.UITable.RowName = {''};
            app.UITable.ColumnEditable = [true true];
            app.UITable.CellEditCallback = createCallbackFcn(app, @FrangiTableSelected, true);
            app.UITable.CellSelectionCallback = createCallbackFcn(app, @FrangiTableSelected, true);
            app.UITable.DisplayDataChangedFcn = createCallbackFcn(app, @FrangiTableChanged, true);
            app.ExportBatchTab = uitab(app.TabGroup);
            app.ExportBatchTab.Title = 'Export & Batch';
            app.GridLayout14 = uigridlayout(app.ExportBatchTab);
            app.ExportPanel = uipanel(app.GridLayout14);
            app.ExportPanel.Title = 'Export';
            app.GridLayout38 = uigridlayout(app.ExportPanel);
            app.ExportMapsButton = uibutton(app.GridLayout38, 'push');
            app.ExportMapsButton.ButtonPushedFcn = createCallbackFcn(app, @ExportMapsButtonPushed, true);
            app.ExportMapsButton.Text = 'Export Maps';
            app.expFileName = uieditfield(app.GridLayout38, 'text');
            app.expFileName.Value = 'exportName';
            app.SelectExportPathButton = uibutton(app.GridLayout38, 'push');
            app.SelectExportPathButton.ButtonPushedFcn = createCallbackFcn(app, @SelectExportPathButtonPushed, true);
            app.SelectExportPathButton.Text = 'Select Export Path';
            app.expFolderPath = uieditfield(app.GridLayout38, 'text');
            app.expFolderPath.Editable = 'off';
            app.expFolderPath.Value = 'exportPath';
            app.ExpOverWrite = uicheckbox(app.GridLayout38);
            app.ExpOverWrite.Text = 'Overwrite?';
            app.ExpOverview = uicheckbox(app.GridLayout38);
            app.ExpOverview.ValueChangedFcn = createCallbackFcn(app, @ExpOverviewValueChanged, true);
            app.ExpOverview.Tooltip = {'Create side-by-side overviews of mip and depth map including colorbar. '};
            app.ExpOverview.Text = 'Overview';
            app.ExpOverview.Value = true;
            app.GridLayout39 = uigridlayout(app.GridLayout38);
            app.ExpOverJpg = uicheckbox(app.GridLayout39);
            app.ExpOverJpg.Text = '.jpg';
            app.ExpOverJpg.Value = true;
            app.ExpOverTiff = uicheckbox(app.GridLayout39);
            app.ExpOverTiff.Text = '.tiff';
            app.GridLayout39_2 = uigridlayout(app.GridLayout38);
            app.ExpNativeTiff = uicheckbox(app.GridLayout39_2);
            app.ExpNativeTiff.Enable = 'off';
            app.ExpNativeTiff.Text = '.tiff';
            app.ExpNativeJpg = uicheckbox(app.GridLayout39_2);
            app.ExpNativeJpg.Enable = 'off';
            app.ExpNativeJpg.Text = '.jpg';
            app.GridLayout39_3 = uigridlayout(app.GridLayout38);
            app.ExpOverPdf = uicheckbox(app.GridLayout39_3);
            app.ExpOverPdf.Text = '.pdf';
            app.ExpOverPdf.Value = true;
            app.ExpOverPng = uicheckbox(app.GridLayout39_3);
            app.ExpOverPng.Text = '.png';
            app.GridLayout39_4 = uigridlayout(app.GridLayout38);
            app.ExpNativePng = uicheckbox(app.GridLayout39_4);
            app.ExpNativePng.Enable = 'off';
            app.ExpNativePng.Text = '.png';
            app.GridLayout39_5 = uigridlayout(app.GridLayout38);
            app.ExpNative = uicheckbox(app.GridLayout39_5);
            app.ExpNative.ValueChangedFcn = createCallbackFcn(app, @ExpNativeValueChanged, true);
            app.ExpNative.Tooltip = {'Create native resolution RGB images (without color bar)?'};
            app.ExpNative.Text = 'Native';
            app.ExpDepthMap = uicheckbox(app.GridLayout39_5);
            app.ExpDepthMap.Enable = 'off';
            app.ExpDepthMap.Tooltip = {'Create native resolution RGB depth map (without colorbar)?'; ''};
            app.ExpDepthMap.Text = 'Depth Map';
            app.ExpDepthMap.Value = true;
            app.GridLayout40 = uigridlayout(app.GridLayout38);
            app.ExpMatFile = uicheckbox(app.GridLayout40);
            app.ExpMatFile.Text = 'Export .mat';
            app.ExpLogFile = uicheckbox(app.GridLayout40);
            app.ExpLogFile.Text = 'Export log file';
            app.ExpLogFile.Value = true;
            app.BatchProcessingPanel = uipanel(app.GridLayout14);
            app.BatchProcessingPanel.Title = 'Batch Processing';
            app.GridLayout38_2 = uigridlayout(app.BatchProcessingPanel);
            app.BrowseFolderButton = uibutton(app.GridLayout38_2, 'push');
            app.BrowseFolderButton.ButtonPushedFcn = createCallbackFcn(app, @BrowseFolderButtonPushed, true);
            app.BrowseFolderButton.Text = 'Browse Folder';
            app.batchProcessFolder = uieditfield(app.GridLayout38_2, 'text');
            app.batchProcessFolder.Value = 'exportName';
            app.GridLayout39_6 = uigridlayout(app.GridLayout38_2);
            app.ExpOverJpg_2 = uicheckbox(app.GridLayout39_6);
            app.ExpOverJpg_2.Text = '.jpg';
            app.ExpOverJpg_2.Value = true;
            app.ExpOverTiff_2 = uicheckbox(app.GridLayout39_6);
            app.ExpOverTiff_2.Text = '.tiff';
            app.GridLayout39_7 = uigridlayout(app.GridLayout38_2);
            app.ExpNativeTiff_2 = uicheckbox(app.GridLayout39_7);
            app.ExpNativeTiff_2.Enable = 'off';
            app.ExpNativeTiff_2.Text = '.tiff';
            app.ExpNativeJpg_2 = uicheckbox(app.GridLayout39_7);
            app.ExpNativeJpg_2.Enable = 'off';
            app.ExpNativeJpg_2.Text = '.jpg';
            app.GridLayout39_8 = uigridlayout(app.GridLayout38_2);
            app.ExpOverPdf_2 = uicheckbox(app.GridLayout39_8);
            app.ExpOverPdf_2.Text = '.pdf';
            app.ExpOverPdf_2.Value = true;
            app.ExpOverPng_2 = uicheckbox(app.GridLayout39_8);
            app.ExpOverPng_2.Text = '.png';
            app.GridLayout39_9 = uigridlayout(app.GridLayout38_2);
            app.ExpNativePng_2 = uicheckbox(app.GridLayout39_9);
            app.ExpNativePng_2.Enable = 'off';
            app.ExpNativePng_2.Text = '.png';
            app.GridLayout39_10 = uigridlayout(app.GridLayout38_2);
            app.ExpNative_2 = uicheckbox(app.GridLayout39_10);
            app.ExpNative_2.Tooltip = {'Create native resolution RGB images (without color bar)?'};
            app.ExpNative_2.Text = 'Native';
            app.ExpDepthMap_2 = uicheckbox(app.GridLayout39_10);
            app.ExpDepthMap_2.Enable = 'off';
            app.ExpDepthMap_2.Tooltip = {'Create native resolution RGB depth map (without colorbar)?'; ''};
            app.ExpDepthMap_2.Text = 'Depth Map';
            app.ExpDepthMap_2.Value = true;
            app.GridLayout40_2 = uigridlayout(app.GridLayout38_2);
            app.ExpMatFile_2 = uicheckbox(app.GridLayout40_2);
            app.ExpMatFile_2.Text = 'Export .mat';
            app.ExpLogFile_2 = uicheckbox(app.GridLayout40_2);
            app.ExpLogFile_2.Text = 'Export log file';
            app.ExpLogFile_2.Value = true;
            app.BatchProcessButton = uibutton(app.GridLayout38_2, 'push');
            app.BatchProcessButton.ButtonPushedFcn = createCallbackFcn(app, @BatchProcessButtonPushed, true);
            app.BatchProcessButton.Text = 'Batch Process!';
            app.ProcessingLogTab = uitab(app.TabGroup);
            app.ProcessingLogTab.Title = 'Processing Log';
            app.GridLayout15 = uigridlayout(app.ProcessingLogTab);
            app.Label = uilabel(app.GridLayout15);
            app.Label.Text = '';
            app.DebugText = uilistbox(app.GridLayout15);
            app.DebugText.Items = {'SystemLog'};
            app.DebugText.Value = {};
            app.GridLayout4 = uigridlayout(app.GridLayout);
            app.GridLayout52 = uigridlayout(app.GridLayout4);
            app.DebugMode = uicheckbox(app.GridLayout52);
            app.DebugMode.Text = 'Debug Mode';
            app.GridLayout53 = uigridlayout(app.GridLayout52);
            app.cBars = uidropdown(app.GridLayout53);
            app.cBars.Items = {'gray', 'hot', 'jet', 'parula'};
            app.cBars.ValueChangedFcn = createCallbackFcn(app, @cBarsValueChanged, true);
            app.cBars.Tooltip = {'select colomap for image display'; ''};
            app.cBars.Value = 'gray';
            app.ColormapLabel = uilabel(app.GridLayout53);
            app.ColormapLabel.Text = 'Colormap';
            app.StatusText = uitextarea(app.GridLayout4);
            app.StatusText.Editable = 'off';
            app.StatusText.Value = {'status updates coming here soon!'};
            app.SizeInfo = uigridlayout(app.GridLayout4);
            app.nX = uieditfield(app.SizeInfo, 'numeric');
            app.nX.RoundFractionalValues = 'on';
            app.nX.Editable = 'off';
            app.nY = uieditfield(app.SizeInfo, 'numeric');
            app.nY.RoundFractionalValues = 'on';
            app.nY.Editable = 'off';
            app.nZ = uieditfield(app.SizeInfo, 'numeric');
            app.nZ.RoundFractionalValues = 'on';
            app.nZ.Editable = 'off';
            app.SizexyzLabel = uilabel(app.SizeInfo);
            app.SizexyzLabel.Text = {'Size'; 'xyz'};
            app.dRLabel = uilabel(app.SizeInfo);
            app.dRLabel.Text = 'dR';
            app.dR = uieditfield(app.SizeInfo, 'numeric');
            app.dR.ValueDisplayFormat = '%11.2f';
            app.dR.Editable = 'off';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = PostProGUI(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end