classdef FrangiGui < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        GridLayout43               matlab.ui.container.GridLayout
        GridLayout44               matlab.ui.container.GridLayout
        imFrangiFiltIn             matlab.ui.control.Image
        imFrangiScale              matlab.ui.control.Image
        imFrangiFilt               matlab.ui.control.Image
        imFrangiCombined           matlab.ui.control.Image
        FrangiInputLabel           matlab.ui.control.Label
        SelectedScaleLabel         matlab.ui.control.Label
        FrangiFilteredLabel        matlab.ui.control.Label
        FrangiCombinedLabel        matlab.ui.control.Label
        GridLayout51               matlab.ui.container.GridLayout
        FrangiSettingsPanel        matlab.ui.container.Panel
        GridLayout47               matlab.ui.container.GridLayout
        GridLayout48               matlab.ui.container.GridLayout
        GridLayout49               matlab.ui.container.GridLayout
        StartScaleEditFieldLabel   matlab.ui.control.Label
        StartScaleEditField        matlab.ui.control.NumericEditField
        GridLayout49_2             matlab.ui.container.GridLayout
        StopScaleEditFieldLabel    matlab.ui.control.Label
        StopScaleEditField         matlab.ui.control.NumericEditField
        ScaleRangeCheckBox         matlab.ui.control.CheckBox
        ManualScalesCheckBox       matlab.ui.control.CheckBox
        GridLayout49_3             matlab.ui.container.GridLayout
        TotalScalesEditFieldLabel  matlab.ui.control.Label
        TotalScalesEditField       matlab.ui.control.NumericEditField
        ScalesTextField            matlab.ui.control.EditField
        GridLayout49_4             matlab.ui.container.GridLayout
        SensitivityEditFieldLabel  matlab.ui.control.Label
        SensitivityEditField       matlab.ui.control.NumericEditField
        InvertedCheckBox           matlab.ui.control.CheckBox
        GridLayout50               matlab.ui.container.GridLayout
        ApplyButton                matlab.ui.control.Button
        SelectFrangiScalesPanel    matlab.ui.container.Panel
        GridLayout46               matlab.ui.container.GridLayout
        UITable                    matlab.ui.control.Table
    end

    
    properties (Access = public)
        PPA = PostProBackEnd().empty;
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function Open_App(app, PPA)
            movegui(app.UIFigure,'northwest');
            if ~isempty(PPA.MasterGUI)
                masterGuiPos = PPA.MasterGUI.UIFigure.Position;
                rightBorder = masterGuiPos(1)+masterGuiPos(3);
                app.UIFigure.Position(1) = rightBorder;
                % move down a bit so windows don't fully overlapp
                app.UIFigure.Position(2) = app.UIFigure.Position(2) - 50;
            end
            app.PPA = PPA;
            app.PPA.MapGUI = app; % transfer handle to app
            
            % setup UI axis for images
            app.PPA.Setup_UIAxis(app.imFiltDisp,1);
            app.PPA.Setup_UIAxis(app.imDepthDisp,1);
        end

        % Close request function: UIFigure
        function CloseApp(app, event)
            app.UIFigure.Visible = 'off';
        end

        % Callback function
        function Apply_Image_Processing(app, event)
            app.PPA.Apply_Image_Processing();
        end

        % Callback function
        function Update_Depth_Map(app, event)
            app.PPA.Update_Depth_Map(app.imDepthDisp);
        end

        % Button pushed function: ApplyButton
        function ApplyButtonPushed(app, event)
            app.PPA.Apply_Frangi();
            
        end

        % Value changed function: ManualScalesCheckBox
        function ManualScalesCheckBoxValueChanged(app, event)
            app.ScaleRangeCheckBox.Value = ~app.ManualScalesCheckBox.Value;
            app.PPA.Update_Frangi_Scales();
        end

        % Value changed function: ScalesTextField, 
        % StartScaleEditField, StopScaleEditField, 
        % TotalScalesEditField
        function UpdateScaleTable(app, event)
            app.PPA.Update_Frangi_Scales();
        end

        % Value changed function: ScaleRangeCheckBox
        function ScaleRangeCheckBoxValueChanged(app, event)
            app.ManualScalesCheckBox.Value = ~app.ScaleRangeCheckBox.Value;
            app.PPA.Update_Frangi_Scales();
        end

        % Callback function: UITable, UITable
        function FrangiTableSelected(app, event)
                        selectedRow = event.Indices(1);
            app.PPA.Plot_Frangi(true,selectedRow);
        end

        % Display data changed function: UITable
        function FrangiTableChanged(app, event)
            app.PPA.Update_Frangi_Combo();
            app.PPA.Plot_Frangi(false);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'Map';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @CloseApp, true);
            app.GridLayout43 = uigridlayout(app.UIFigure);
            app.GridLayout44 = uigridlayout(app.GridLayout43);
            app.imFrangiFiltIn = uiimage(app.GridLayout44);
            app.imFrangiScale = uiimage(app.GridLayout44);
            app.imFrangiFilt = uiimage(app.GridLayout44);
            app.imFrangiCombined = uiimage(app.GridLayout44);
            app.FrangiInputLabel = uilabel(app.GridLayout44);
            app.FrangiInputLabel.Text = 'Frangi Input';
            app.SelectedScaleLabel = uilabel(app.GridLayout44);
            app.SelectedScaleLabel.Text = 'Selected Scale';
            app.FrangiFilteredLabel = uilabel(app.GridLayout44);
            app.FrangiFilteredLabel.Text = 'Frangi Filtered';
            app.FrangiCombinedLabel = uilabel(app.GridLayout44);
            app.FrangiCombinedLabel.Text = 'Frangi Combined';
            app.GridLayout51 = uigridlayout(app.GridLayout43);
            app.FrangiSettingsPanel = uipanel(app.GridLayout51);
            app.FrangiSettingsPanel.Title = 'Frangi Settings';
            app.GridLayout47 = uigridlayout(app.FrangiSettingsPanel);
            app.GridLayout48 = uigridlayout(app.GridLayout47);
            app.GridLayout49 = uigridlayout(app.GridLayout48);
            app.StartScaleEditFieldLabel = uilabel(app.GridLayout49);
            app.StartScaleEditFieldLabel.Text = 'Start Scale';
            app.StartScaleEditField = uieditfield(app.GridLayout49, 'numeric');
            app.StartScaleEditField.Limits = [0.01 200];
            app.StartScaleEditField.ValueDisplayFormat = '%11.1f';
            app.StartScaleEditField.ValueChangedFcn = createCallbackFcn(app, @UpdateScaleTable, true);
            app.StartScaleEditField.Value = 1;
            app.GridLayout49_2 = uigridlayout(app.GridLayout48);
            app.StopScaleEditFieldLabel = uilabel(app.GridLayout49_2);
            app.StopScaleEditFieldLabel.Text = 'Stop Scale';
            app.StopScaleEditField = uieditfield(app.GridLayout49_2, 'numeric');
            app.StopScaleEditField.Limits = [1 500];
            app.StopScaleEditField.RoundFractionalValues = 'on';
            app.StopScaleEditField.ValueDisplayFormat = '%11.1f';
            app.StopScaleEditField.ValueChangedFcn = createCallbackFcn(app, @UpdateScaleTable, true);
            app.StopScaleEditField.Value = 20;
            app.ScaleRangeCheckBox = uicheckbox(app.GridLayout48);
            app.ScaleRangeCheckBox.ValueChangedFcn = createCallbackFcn(app, @ScaleRangeCheckBoxValueChanged, true);
            app.ScaleRangeCheckBox.Text = 'Scale Range';
            app.ScaleRangeCheckBox.Value = true;
            app.ManualScalesCheckBox = uicheckbox(app.GridLayout48);
            app.ManualScalesCheckBox.ValueChangedFcn = createCallbackFcn(app, @ManualScalesCheckBoxValueChanged, true);
            app.ManualScalesCheckBox.Text = 'Manual Scales';
            app.GridLayout49_3 = uigridlayout(app.GridLayout48);
            app.TotalScalesEditFieldLabel = uilabel(app.GridLayout49_3);
            app.TotalScalesEditFieldLabel.Text = 'Total Scales';
            app.TotalScalesEditField = uieditfield(app.GridLayout49_3, 'numeric');
            app.TotalScalesEditField.Limits = [1 1000];
            app.TotalScalesEditField.RoundFractionalValues = 'on';
            app.TotalScalesEditField.ValueChangedFcn = createCallbackFcn(app, @UpdateScaleTable, true);
            app.TotalScalesEditField.Value = 5;
            app.ScalesTextField = uieditfield(app.GridLayout48, 'text');
            app.ScalesTextField.ValueChangedFcn = createCallbackFcn(app, @UpdateScaleTable, true);
            app.ScalesTextField.Value = '1 5 10';
            app.GridLayout49_4 = uigridlayout(app.GridLayout48);
            app.SensitivityEditFieldLabel = uilabel(app.GridLayout49_4);
            app.SensitivityEditFieldLabel.Text = 'Sensitivity';
            app.SensitivityEditField = uieditfield(app.GridLayout49_4, 'numeric');
            app.SensitivityEditField.Limits = [0.001 100];
            app.SensitivityEditField.ValueDisplayFormat = '%4.2f';
            app.SensitivityEditField.Value = 0.05;
            app.InvertedCheckBox = uicheckbox(app.GridLayout48);
            app.InvertedCheckBox.Text = 'Inverted?';
            app.GridLayout50 = uigridlayout(app.GridLayout47);
            app.ApplyButton = uibutton(app.GridLayout50, 'push');
            app.ApplyButton.ButtonPushedFcn = createCallbackFcn(app, @ApplyButtonPushed, true);
            app.ApplyButton.Text = 'Apply';
            app.SelectFrangiScalesPanel = uipanel(app.GridLayout51);
            app.SelectFrangiScalesPanel.Title = 'Select Frangi Scales';
            app.GridLayout46 = uigridlayout(app.SelectFrangiScalesPanel);
            app.UITable = uitable(app.GridLayout46);
            app.UITable.ColumnName = {'scale'; 'selected'};
            app.UITable.RowName = {''};
            app.UITable.ColumnEditable = [true true];
            app.UITable.CellEditCallback = createCallbackFcn(app, @FrangiTableSelected, true);
            app.UITable.CellSelectionCallback = createCallbackFcn(app, @FrangiTableSelected, true);
            app.UITable.DisplayDataChangedFcn = createCallbackFcn(app, @FrangiTableChanged, true);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = FrangiGui(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)Open_App(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end