classdef VesselGui < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                 matlab.ui.Figure
        MainGrid                 matlab.ui.container.GridLayout
        GridLayout31             matlab.ui.container.GridLayout
        BinarizeImagePanel       matlab.ui.container.Panel
        GridLayout16             matlab.ui.container.GridLayout
        BinMethodDropDownLabel   matlab.ui.control.Label
        BinMethodDropDown        matlab.ui.control.DropDown
        GridLayout32             matlab.ui.container.GridLayout
        nLevelsLabel             matlab.ui.control.Label
        BinMultiLevels           matlab.ui.control.NumericEditField
        GridLayout32_7           matlab.ui.container.GridLayout
        sensitivityLabel         matlab.ui.control.Label
        BinSensEdit              matlab.ui.control.NumericEditField
        ShowResultsPanel         matlab.ui.container.Panel
        VesselAnalysisLabel      matlab.ui.control.Label
        CleanSegmentedPanel      matlab.ui.container.Panel
        GridLayout16_3           matlab.ui.container.GridLayout
        GridLayout32_8           matlab.ui.container.GridLayout
        fillholesizeLabel        matlab.ui.control.Label
        MinHoleSizeEdit          matlab.ui.control.NumericEditField
        GridLayout32_9           matlab.ui.container.GridLayout
        minobjsizeLabel          matlab.ui.control.Label
        MinObjSizeEdit           matlab.ui.control.NumericEditField
        GridLayout32_10          matlab.ui.container.GridLayout
        minspurlengthLabel       matlab.ui.control.Label
        MinSpurLength            matlab.ui.control.NumericEditField
        ClearNearBranchCheckBox  matlab.ui.control.CheckBox
        GridLayout32_14          matlab.ui.container.GridLayout
        smoothparallelLabel      matlab.ui.control.Label
        SmoothPar                matlab.ui.control.NumericEditField
        GridLayout32_15          matlab.ui.container.GridLayout
        minsplinelengthLabel     matlab.ui.control.Label
        MinSplineLength          matlab.ui.control.NumericEditField
        RemoveExtreme            matlab.ui.control.CheckBox
        GridLayout32_16          matlab.ui.container.GridLayout
        splinesmoothnessLabel    matlab.ui.control.Label
        SplineSmooth             matlab.ui.control.NumericEditField
        ForceConnect             matlab.ui.control.CheckBox
        GridLayout32_18          matlab.ui.container.GridLayout
        smoothperpendLabel       matlab.ui.control.Label
        SmoothPer                matlab.ui.control.NumericEditField
        GridLayout32_11          matlab.ui.container.GridLayout
        AnalyseImagePanel        matlab.ui.container.Panel
        GridLayout16_2           matlab.ui.container.GridLayout
        GridLayout32_6           matlab.ui.container.GridLayout
        GridLayout32_12          matlab.ui.container.GridLayout
        GridLayout32_17          matlab.ui.container.GridLayout
        FindVesselsButton        matlab.ui.control.Button
        GridLayout33             matlab.ui.container.GridLayout
        AutoUpdateCheckBox       matlab.ui.control.CheckBox
    end

    
    properties (Access = public)
        PPA = PostProBackEnd().empty;
    end
    
    methods (Access = public)
        function Close_Request(app)
            app.UIFigure.Visible = 'off';
            delete(app.PPA.VesselFigs.MainFig);
            app.PPA.VesselFigs = []; % delete old handles
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function Open_App(app, PPA)
            movegui(app.UIFigure,'northwest');
            if ~isempty(PPA.MasterGUI)
                masterGuiPos = PPA.MasterGUI.UIFigure.Position;
                rightBorder = masterGuiPos(1)+masterGuiPos(3);
                app.UIFigure.Position(1) = rightBorder;
                % move down a bit so windows don't fully overlapp
                app.UIFigure.Position(2) = app.UIFigure.Position(2) - 50;
            end
            app.PPA = PPA;
            app.PPA.VesselGUI = app; % transfer handle to app
            
            app.PPA.Setup_Vessel_Figures();
            app.PPA.Apply_Vessel_Processing();
        end

        % Close request function: UIFigure
        function CloseApp(app, event)
            app.Close_Request();
        end

        % Value changed function: BinMethodDropDown, 
        % BinMultiLevels, BinSensEdit, ClearNearBranchCheckBox, 
        % ForceConnect, MinHoleSizeEdit, MinObjSizeEdit, 
        % MinSplineLength, MinSpurLength, RemoveExtreme, SmoothPar, 
        % SmoothPer, SplineSmooth
        function Apply_Vessel_Processing(app, event)
            switch app.BinMethodDropDown.Value
                case 'Otsu'
                    app.BinSensEdit.Enable = false;
                    app.BinMultiLevels.Enable = false;
                    
                case 'Multi'
                    app.BinSensEdit.Enable = false;
                    app.BinMultiLevels.Enable = true;
                    
                case 'Adaptive'
                    app.BinSensEdit.Enable = true;
                    app.BinMultiLevels.Enable = false;
                case 'Manual'
                    app.BinSensEdit.Enable = true;
                    app.BinMultiLevels.Enable = false;
            end
            if app.AutoUpdateCheckBox.Value
                app.PPA.Apply_Vessel_Processing();
            end
        end

        % Button pushed function: FindVesselsButton
        function FindVesselsButtonPushed(app, event)
            app.PPA.Apply_Vessel_Processing();
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'Vessel';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @CloseApp, true);
            app.MainGrid = uigridlayout(app.UIFigure);
            app.GridLayout31 = uigridlayout(app.MainGrid);
            app.BinarizeImagePanel = uipanel(app.GridLayout31);
            app.BinarizeImagePanel.Title = 'Binarize Image';
            app.GridLayout16 = uigridlayout(app.BinarizeImagePanel);
            app.BinMethodDropDownLabel = uilabel(app.GridLayout16);
            app.BinMethodDropDownLabel.Text = 'Bin Method';
            app.BinMethodDropDown = uidropdown(app.GridLayout16);
            app.BinMethodDropDown.Items = {'Otsu', 'Manual', 'Multi', 'Adaptive'};
            app.BinMethodDropDown.ValueChangedFcn = createCallbackFcn(app, @Apply_Vessel_Processing, true);
            app.BinMethodDropDown.Value = 'Adaptive';
            app.GridLayout32 = uigridlayout(app.GridLayout16);
            app.nLevelsLabel = uilabel(app.GridLayout32);
            app.nLevelsLabel.Enable = 'off';
            app.nLevelsLabel.Text = 'n Levels';
            app.BinMultiLevels = uieditfield(app.GridLayout32, 'numeric');
            app.BinMultiLevels.Limits = [1 Inf];
            app.BinMultiLevels.RoundFractionalValues = 'on';
            app.BinMultiLevels.ValueDisplayFormat = '%i';
            app.BinMultiLevels.ValueChangedFcn = createCallbackFcn(app, @Apply_Vessel_Processing, true);
            app.BinMultiLevels.Enable = 'off';
            app.BinMultiLevels.Value = 1;
            app.GridLayout32_7 = uigridlayout(app.GridLayout16);
            app.sensitivityLabel = uilabel(app.GridLayout32_7);
            app.sensitivityLabel.Text = 'sensitivity';
            app.BinSensEdit = uieditfield(app.GridLayout32_7, 'numeric');
            app.BinSensEdit.Limits = [0 1];
            app.BinSensEdit.ValueDisplayFormat = '%3.2f';
            app.BinSensEdit.ValueChangedFcn = createCallbackFcn(app, @Apply_Vessel_Processing, true);
            app.BinSensEdit.Value = 0.25;
            app.ShowResultsPanel = uipanel(app.GridLayout31);
            app.ShowResultsPanel.Title = 'Show Results';
            app.VesselAnalysisLabel = uilabel(app.GridLayout31);
            app.VesselAnalysisLabel.Text = 'Vessel Analysis';
            app.CleanSegmentedPanel = uipanel(app.GridLayout31);
            app.CleanSegmentedPanel.Title = 'Clean Segmented';
            app.GridLayout16_3 = uigridlayout(app.CleanSegmentedPanel);
            app.GridLayout32_8 = uigridlayout(app.GridLayout16_3);
            app.fillholesizeLabel = uilabel(app.GridLayout32_8);
            app.fillholesizeLabel.Text = 'fill hole size';
            app.MinHoleSizeEdit = uieditfield(app.GridLayout32_8, 'numeric');
            app.MinHoleSizeEdit.Limits = [0 Inf];
            app.MinHoleSizeEdit.RoundFractionalValues = 'on';
            app.MinHoleSizeEdit.ValueDisplayFormat = '%i';
            app.MinHoleSizeEdit.ValueChangedFcn = createCallbackFcn(app, @Apply_Vessel_Processing, true);
            app.MinHoleSizeEdit.Tooltip = {''};
            app.MinHoleSizeEdit.Value = 5;
            app.GridLayout32_9 = uigridlayout(app.GridLayout16_3);
            app.minobjsizeLabel = uilabel(app.GridLayout32_9);
            app.minobjsizeLabel.Text = 'min obj size';
            app.MinObjSizeEdit = uieditfield(app.GridLayout32_9, 'numeric');
            app.MinObjSizeEdit.Limits = [0 Inf];
            app.MinObjSizeEdit.RoundFractionalValues = 'on';
            app.MinObjSizeEdit.ValueDisplayFormat = '%i';
            app.MinObjSizeEdit.ValueChangedFcn = createCallbackFcn(app, @Apply_Vessel_Processing, true);
            app.MinObjSizeEdit.Tooltip = {''};
            app.MinObjSizeEdit.Value = 5;
            app.GridLayout32_10 = uigridlayout(app.GridLayout16_3);
            app.minspurlengthLabel = uilabel(app.GridLayout32_10);
            app.minspurlengthLabel.Text = {'min spur'; 'length'};
            app.MinSpurLength = uieditfield(app.GridLayout32_10, 'numeric');
            app.MinSpurLength.Limits = [0 Inf];
            app.MinSpurLength.RoundFractionalValues = 'on';
            app.MinSpurLength.ValueDisplayFormat = '%i';
            app.MinSpurLength.ValueChangedFcn = createCallbackFcn(app, @Apply_Vessel_Processing, true);
            app.MinSpurLength.Tooltip = {''};
            app.MinSpurLength.Value = 5;
            app.ClearNearBranchCheckBox = uicheckbox(app.GridLayout16_3);
            app.ClearNearBranchCheckBox.ValueChangedFcn = createCallbackFcn(app, @Apply_Vessel_Processing, true);
            app.ClearNearBranchCheckBox.Tooltip = {''};
            app.ClearNearBranchCheckBox.Text = 'clear near branch';
            app.GridLayout32_14 = uigridlayout(app.GridLayout16_3);
            app.smoothparallelLabel = uilabel(app.GridLayout32_14);
            app.smoothparallelLabel.Text = {'smooth'; 'parallel'};
            app.SmoothPar = uieditfield(app.GridLayout32_14, 'numeric');
            app.SmoothPar.Limits = [0 Inf];
            app.SmoothPar.ValueDisplayFormat = '%2.2f';
            app.SmoothPar.ValueChangedFcn = createCallbackFcn(app, @Apply_Vessel_Processing, true);
            app.SmoothPar.Tooltip = {''};
            app.SmoothPar.Value = 1;
            app.GridLayout32_15 = uigridlayout(app.GridLayout16_3);
            app.minsplinelengthLabel = uilabel(app.GridLayout32_15);
            app.minsplinelengthLabel.Text = {'min spline'; 'length'};
            app.MinSplineLength = uieditfield(app.GridLayout32_15, 'numeric');
            app.MinSplineLength.Limits = [3 Inf];
            app.MinSplineLength.RoundFractionalValues = 'on';
            app.MinSplineLength.ValueDisplayFormat = '%i';
            app.MinSplineLength.ValueChangedFcn = createCallbackFcn(app, @Apply_Vessel_Processing, true);
            app.MinSplineLength.Tooltip = {''};
            app.MinSplineLength.Value = 5;
            app.RemoveExtreme = uicheckbox(app.GridLayout16_3);
            app.RemoveExtreme.ValueChangedFcn = createCallbackFcn(app, @Apply_Vessel_Processing, true);
            app.RemoveExtreme.Tooltip = {''; ''};
            app.RemoveExtreme.Text = 'remove fat vessels';
            app.RemoveExtreme.Value = true;
            app.GridLayout32_16 = uigridlayout(app.GridLayout16_3);
            app.splinesmoothnessLabel = uilabel(app.GridLayout32_16);
            app.splinesmoothnessLabel.Text = {'spline'; 'smoothness'};
            app.SplineSmooth = uieditfield(app.GridLayout32_16, 'numeric');
            app.SplineSmooth.Limits = [3 Inf];
            app.SplineSmooth.RoundFractionalValues = 'on';
            app.SplineSmooth.ValueDisplayFormat = '%i';
            app.SplineSmooth.ValueChangedFcn = createCallbackFcn(app, @Apply_Vessel_Processing, true);
            app.SplineSmooth.Tooltip = {''};
            app.SplineSmooth.Value = 10;
            app.ForceConnect = uicheckbox(app.GridLayout16_3);
            app.ForceConnect.ValueChangedFcn = createCallbackFcn(app, @Apply_Vessel_Processing, true);
            app.ForceConnect.Tooltip = {''; ''};
            app.ForceConnect.Text = 'force connectivity';
            app.ForceConnect.Value = true;
            app.GridLayout32_18 = uigridlayout(app.GridLayout16_3);
            app.smoothperpendLabel = uilabel(app.GridLayout32_18);
            app.smoothperpendLabel.Text = {'smooth'; 'perpend.'};
            app.SmoothPer = uieditfield(app.GridLayout32_18, 'numeric');
            app.SmoothPer.Limits = [0 Inf];
            app.SmoothPer.ValueDisplayFormat = '%2.2f';
            app.SmoothPer.ValueChangedFcn = createCallbackFcn(app, @Apply_Vessel_Processing, true);
            app.SmoothPer.Tooltip = {''};
            app.SmoothPer.Value = 0.1;
            app.GridLayout32_11 = uigridlayout(app.GridLayout31);
            app.AnalyseImagePanel = uipanel(app.GridLayout31);
            app.AnalyseImagePanel.Title = 'Analyse Image';
            app.GridLayout16_2 = uigridlayout(app.AnalyseImagePanel);
            app.GridLayout32_6 = uigridlayout(app.GridLayout16_2);
            app.GridLayout32_12 = uigridlayout(app.GridLayout32_6);
            app.GridLayout32_17 = uigridlayout(app.GridLayout32_6);
            app.FindVesselsButton = uibutton(app.GridLayout16_2, 'push');
            app.FindVesselsButton.ButtonPushedFcn = createCallbackFcn(app, @FindVesselsButtonPushed, true);
            app.FindVesselsButton.Text = 'Find Vessels';
            app.GridLayout33 = uigridlayout(app.GridLayout31);
            app.AutoUpdateCheckBox = uicheckbox(app.GridLayout33);
            app.AutoUpdateCheckBox.Text = 'Auto Update?';
            app.AutoUpdateCheckBox.Value = true;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = VesselGui(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)Open_App(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end