classdef VolGui < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        MainGrid                       matlab.ui.container.GridLayout
        SettingsGrid                   matlab.ui.container.GridLayout
        FrequencyFilteringSettingsPanel  matlab.ui.container.Panel
        GridLayout26                   matlab.ui.container.GridLayout
        FiltResponseAx                 matlab.ui.control.UIAxes
        GridLayout27                   matlab.ui.container.GridLayout
        GridLayout28                   matlab.ui.container.GridLayout
        MHzLabel                       matlab.ui.control.Label
        HighLabel                      matlab.ui.control.Label
        freqHigh                       matlab.ui.control.NumericEditField
        LowLabel                       matlab.ui.control.Label
        freqLow                        matlab.ui.control.NumericEditField
        OrderLabel                     matlab.ui.control.Label
        filtOrder                      matlab.ui.control.NumericEditField
        GridLayout29                   matlab.ui.container.GridLayout
        filtDesign                     matlab.ui.control.DropDown
        TypeDropDownLabel              matlab.ui.control.Label
        filtType                       matlab.ui.control.DropDown
        SliceWidthGrid                 matlab.ui.container.GridLayout
        SliceWidthmmEditFieldLabel     matlab.ui.control.Label
        SliceWidthEditField            matlab.ui.control.NumericEditField
        VolumeProcessingSettingsPanel  matlab.ui.container.Panel
        GridVolSettings                matlab.ui.container.GridLayout
        GridLayout25_2                 matlab.ui.container.GridLayout
        CropCheck                      matlab.ui.control.CheckBox
        zCropHighEdit                  matlab.ui.control.NumericEditField
        zCropLowEdit                   matlab.ui.control.NumericEditField
        GridLayout25_3                 matlab.ui.container.GridLayout
        FreqFiltCheck                  matlab.ui.control.CheckBox
        GridLayout25_4                 matlab.ui.container.GridLayout
        MedFiltCheck                   matlab.ui.control.CheckBox
        MedFiltZ                       matlab.ui.control.NumericEditField
        MedFiltY                       matlab.ui.control.NumericEditField
        MedFiltX                       matlab.ui.control.NumericEditField
        GridLayout25_5                 matlab.ui.container.GridLayout
        PolarityCheck                  matlab.ui.control.CheckBox
        PolarityDropDown               matlab.ui.control.DropDown
        GridLayout25                   matlab.ui.container.GridLayout
        DwnSplCheck                    matlab.ui.control.CheckBox
        GridLayout51_2                 matlab.ui.container.GridLayout
        xyLabel                        matlab.ui.control.Label
        DwnSplFactorEdit               matlab.ui.control.NumericEditField
        GridLayout51_4                 matlab.ui.container.GridLayout
        zLabel                         matlab.ui.control.Label
        DepthDwnSplFactorEdit          matlab.ui.control.NumericEditField
        GridLayout30                   matlab.ui.container.GridLayout
        VolClaheCheckBox               matlab.ui.control.CheckBox
        ClipLimitEditFieldLabel        matlab.ui.control.Label
        ClipLimitEditField             matlab.ui.control.NumericEditField
        MainImageGrid                  matlab.ui.container.GridLayout
        FiltDisp                       matlab.ui.control.UIAxes
        yzSliceDisp                    matlab.ui.control.UIAxes
        xzSliceDisp                    matlab.ui.control.UIAxes
        SideProjGrid                   matlab.ui.container.GridLayout
        xzProjDisp                     matlab.ui.control.UIAxes
        yzProjDisp                     matlab.ui.control.UIAxes
    end

    
    properties (Access = public)
        PPA = PostProBackEnd().empty;
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function Open_App(app, PPA)
            movegui(app.UIFigure,'northeast');
            
            if ~isempty(PPA.LoadGUI)
                loadGuiPos = PPA.LoadGUI.UIFigure.Position;
                rightBorder = loadGuiPos(1)+loadGuiPos(3);
                app.UIFigure.Position(1) = rightBorder;
            elseif ~isempty(PPA.MasterGUI)
                masterGuiPos = PPA.MasterGUI.UIFigure.Position;
                rightBorder = masterGuiPos(1)+masterGuiPos(3);
                app.UIFigure.Position(1) = rightBorder;
            end
            app.PPA = PPA;
            app.PPA.VolGUI = app; % transfer handle to app
            
            % setup UI axis for images
            app.PPA.Setup_UIAxis(app.FiltDisp);
            app.PPA.Setup_UIAxis(app.yzSliceDisp);
            app.PPA.Setup_UIAxis(app.xzSliceDisp);
            app.PPA.Setup_UIAxis(app.xzProjDisp);
            app.PPA.Setup_UIAxis(app.yzProjDisp);
            
            % add callback so that when we click on the FiltDisp image
            % we update the projections
            set_image_click_callback(app.FiltDisp,PPA);
        end

        % Close request function: UIFigure
        function CloseApp(app, event)
            app.UIFigure.Visible = 'off';
        end

        % Value changed function: DepthDwnSplFactorEdit, 
        % DwnSplCheck, DwnSplFactorEdit
        function DwnSplCheckValueChanged(app, event)
            app.DwnSplFactorEdit.Enable = app.DwnSplCheck.Value;
            app.DepthDwnSplFactorEdit.Enable = app.DwnSplCheck.Value;
            app.PPA.Down_Sample_Volume();
        end

        % Value changed function: CropCheck
        function CropCheckValueChanged(app, event)
            app.zCropLowEdit.Enable = app.CropCheck.Value;
            app.zCropHighEdit.Enable = app.CropCheck.Value;
            app.PPA.Crop_Volume();
        end

        % Value changed function: zCropLowEdit
        function zCropLowEditValueChanged(app, event)
            app.zCropHighEdit.Limits(1) = app.zCropLowEdit.Value + 1;
            app.PPA.Crop_Volume();
        end

        % Value changed function: zCropHighEdit
        function zCropHighEditValueChanged(app, event)
            app.zCropLowEdit.Limits(2) = app.zCropHighEdit.Value - 1;
            app.PPA.Update_Status();
            app.PPA.Crop_Volume();
        end

        % Value changed function: FreqFiltCheck
        function FreqFiltCheckValueChanged(app, event)
            isEnabled = app.FreqFiltCheck.Value;
            app.filtDesign.Enable = isEnabled;
            app.filtType.Enable = isEnabled;
            app.MHzLabel.Enable = isEnabled;
            app.freqHigh.Enable = isEnabled;
            app.freqLow.Enable = isEnabled;
            app.filtOrder.Enable = isEnabled;
            app.freqLow.Limits(2) = app.freqHigh.Value - 0.1;
            app.freqHigh.Limits(1) = app.freqLow.Value + 0.1;
            app.PPA.Freq_Filt_Volume();
        end

        % Value changed function: filtDesign, filtOrder, filtType, 
        % freqHigh, freqLow
        function ApplyFreqFilt(app, event)
            app.freqLow.Limits(2) = app.freqHigh.Value - 0.1;
            app.freqHigh.Limits(1) = app.freqLow.Value + 0.1;
            app.PPA.Freq_Filt_Volume();
        end

        % Value changed function: SliceWidthEditField
        function SliceWidthEditFieldValueChanged(app, event)
            app.PPA.Update_Slice_Lines();
        end

        % Value changed function: MedFiltCheck
        function MedFiltCheckValueChanged(app, event)
            app.MedFiltX.Enable = app.MedFiltCheck.Value;
            app.MedFiltY.Enable = app.MedFiltCheck.Value;
            app.MedFiltZ.Enable = app.MedFiltCheck.Value;
            app.PPA.Med_Filt_Volume();
        end

        % Value changed function: MedFiltX, MedFiltY, MedFiltZ
        function Apply_Med_Filt(app, event)
            app.MedFiltX.Value = app.PPA.Get_Allowed_Med_Filt_Coeff(app.MedFiltX.Value);
            app.MedFiltY.Value = app.PPA.Get_Allowed_Med_Filt_Coeff(app.MedFiltY.Value);
            app.MedFiltZ.Value = app.PPA.Get_Allowed_Med_Filt_Coeff(app.MedFiltZ.Value);
            app.PPA.Med_Filt_Volume();
        end

        % Value changed function: PolarityCheck, PolarityDropDown
        function PolarityCheckValueChanged(app, event)
            app.PolarityDropDown.Enable = app.PolarityCheck.Value;
            app.PPA.Apply_Polarity_Volume();
        end

        % Value changed function: ClipLimitEditField, 
        % VolClaheCheckBox
        function VolClaheCheckBoxValueChanged(app, event)
            app.ClipLimitEditField.Enable = app.VolClaheCheckBox.Value;
            app.PPA.Update_Vol_Projections(); %            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'Volume Processor';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @CloseApp, true);
            app.MainGrid = uigridlayout(app.UIFigure);
            app.SettingsGrid = uigridlayout(app.MainGrid);
            app.FrequencyFilteringSettingsPanel = uipanel(app.SettingsGrid);
            app.FrequencyFilteringSettingsPanel.Title = 'Frequency Filtering Settings';
            app.GridLayout26 = uigridlayout(app.FrequencyFilteringSettingsPanel);
            app.FiltResponseAx = uiaxes(app.GridLayout26);
            title(app.FiltResponseAx, '')
            xlabel(app.FiltResponseAx, '')
            ylabel(app.FiltResponseAx, '')
            app.GridLayout27 = uigridlayout(app.GridLayout26);
            app.GridLayout28 = uigridlayout(app.GridLayout27);
            app.MHzLabel = uilabel(app.GridLayout28);
            app.MHzLabel.Text = 'MHz';
            app.HighLabel = uilabel(app.GridLayout28);
            app.HighLabel.Text = 'High';
            app.freqHigh = uieditfield(app.GridLayout28, 'numeric');
            app.freqHigh.Limits = [0.001 125];
            app.freqHigh.ValueChangedFcn = createCallbackFcn(app, @ApplyFreqFilt, true);
            app.freqHigh.Value = 50;
            app.LowLabel = uilabel(app.GridLayout28);
            app.LowLabel.Text = 'Low';
            app.freqLow = uieditfield(app.GridLayout28, 'numeric');
            app.freqLow.Limits = [0.001 125];
            app.freqLow.ValueChangedFcn = createCallbackFcn(app, @ApplyFreqFilt, true);
            app.freqLow.Value = 5;
            app.OrderLabel = uilabel(app.GridLayout28);
            app.OrderLabel.Text = 'Order';
            app.filtOrder = uieditfield(app.GridLayout28, 'numeric');
            app.filtOrder.Limits = [1 25];
            app.filtOrder.ValueChangedFcn = createCallbackFcn(app, @ApplyFreqFilt, true);
            app.filtOrder.Value = 3;
            app.GridLayout29 = uigridlayout(app.GridLayout27);
            app.filtDesign = uidropdown(app.GridLayout29);
            app.filtDesign.Items = {'butterworth', 'chebyshev'};
            app.filtDesign.ValueChangedFcn = createCallbackFcn(app, @ApplyFreqFilt, true);
            app.filtDesign.Value = 'butterworth';
            app.TypeDropDownLabel = uilabel(app.GridLayout29);
            app.TypeDropDownLabel.Text = 'Type';
            app.filtType = uidropdown(app.GridLayout29);
            app.filtType.Items = {'bandpass', 'highpass', ''};
            app.filtType.ValueChangedFcn = createCallbackFcn(app, @ApplyFreqFilt, true);
            app.filtType.Value = 'bandpass';
            app.SliceWidthGrid = uigridlayout(app.SettingsGrid);
            app.SliceWidthmmEditFieldLabel = uilabel(app.SliceWidthGrid);
            app.SliceWidthmmEditFieldLabel.Text = 'Slice Width (mm)';
            app.SliceWidthEditField = uieditfield(app.SliceWidthGrid, 'numeric');
            app.SliceWidthEditField.ValueChangedFcn = createCallbackFcn(app, @SliceWidthEditFieldValueChanged, true);
            app.SliceWidthEditField.Value = 0.5;
            app.VolumeProcessingSettingsPanel = uipanel(app.SettingsGrid);
            app.VolumeProcessingSettingsPanel.Title = 'Volume Processing Settings';
            app.GridVolSettings = uigridlayout(app.VolumeProcessingSettingsPanel);
            app.GridLayout25_2 = uigridlayout(app.GridVolSettings);
            app.CropCheck = uicheckbox(app.GridLayout25_2);
            app.CropCheck.ValueChangedFcn = createCallbackFcn(app, @CropCheckValueChanged, true);
            app.CropCheck.Text = 'zCrop';
            app.zCropHighEdit = uieditfield(app.GridLayout25_2, 'numeric');
            app.zCropHighEdit.Limits = [1 Inf];
            app.zCropHighEdit.RoundFractionalValues = 'on';
            app.zCropHighEdit.ValueChangedFcn = createCallbackFcn(app, @zCropHighEditValueChanged, true);
            app.zCropHighEdit.Enable = 'off';
            app.zCropHighEdit.Value = 1500;
            app.zCropLowEdit = uieditfield(app.GridLayout25_2, 'numeric');
            app.zCropLowEdit.Limits = [1 Inf];
            app.zCropLowEdit.RoundFractionalValues = 'on';
            app.zCropLowEdit.ValueChangedFcn = createCallbackFcn(app, @zCropLowEditValueChanged, true);
            app.zCropLowEdit.Enable = 'off';
            app.zCropLowEdit.Value = 1;
            app.GridLayout25_3 = uigridlayout(app.GridVolSettings);
            app.FreqFiltCheck = uicheckbox(app.GridLayout25_3);
            app.FreqFiltCheck.ValueChangedFcn = createCallbackFcn(app, @FreqFiltCheckValueChanged, true);
            app.FreqFiltCheck.Text = 'Frequency Filter';
            app.FreqFiltCheck.Value = true;
            app.GridLayout25_4 = uigridlayout(app.GridVolSettings);
            app.MedFiltCheck = uicheckbox(app.GridLayout25_4);
            app.MedFiltCheck.ValueChangedFcn = createCallbackFcn(app, @MedFiltCheckValueChanged, true);
            app.MedFiltCheck.Text = 'Median Filter';
            app.MedFiltZ = uieditfield(app.GridLayout25_4, 'numeric');
            app.MedFiltZ.Limits = [1 Inf];
            app.MedFiltZ.RoundFractionalValues = 'on';
            app.MedFiltZ.ValueChangedFcn = createCallbackFcn(app, @Apply_Med_Filt, true);
            app.MedFiltZ.Enable = 'off';
            app.MedFiltZ.Value = 3;
            app.MedFiltY = uieditfield(app.GridLayout25_4, 'numeric');
            app.MedFiltY.Limits = [1 Inf];
            app.MedFiltY.RoundFractionalValues = 'on';
            app.MedFiltY.ValueChangedFcn = createCallbackFcn(app, @Apply_Med_Filt, true);
            app.MedFiltY.Enable = 'off';
            app.MedFiltY.Value = 1;
            app.MedFiltX = uieditfield(app.GridLayout25_4, 'numeric');
            app.MedFiltX.Limits = [1 Inf];
            app.MedFiltX.RoundFractionalValues = 'on';
            app.MedFiltX.ValueChangedFcn = createCallbackFcn(app, @Apply_Med_Filt, true);
            app.MedFiltX.Enable = 'off';
            app.MedFiltX.Value = 1;
            app.GridLayout25_5 = uigridlayout(app.GridVolSettings);
            app.PolarityCheck = uicheckbox(app.GridLayout25_5);
            app.PolarityCheck.ValueChangedFcn = createCallbackFcn(app, @PolarityCheckValueChanged, true);
            app.PolarityCheck.Text = 'Polarity';
            app.PolarityDropDown = uidropdown(app.GridLayout25_5);
            app.PolarityDropDown.Items = {'Positive', 'Negative', 'Absolute', 'Envelope'};
            app.PolarityDropDown.ValueChangedFcn = createCallbackFcn(app, @PolarityCheckValueChanged, true);
            app.PolarityDropDown.Enable = 'off';
            app.PolarityDropDown.Value = 'Positive';
            app.GridLayout25 = uigridlayout(app.GridVolSettings);
            app.DwnSplCheck = uicheckbox(app.GridLayout25);
            app.DwnSplCheck.ValueChangedFcn = createCallbackFcn(app, @DwnSplCheckValueChanged, true);
            app.DwnSplCheck.Tooltip = {'Apply xy downsampling (leave z-dimension untouched)?'; ''};
            app.DwnSplCheck.Text = 'Downsampling';
            app.GridLayout51_2 = uigridlayout(app.GridLayout25);
            app.xyLabel = uilabel(app.GridLayout51_2);
            app.xyLabel.Enable = 'off';
            app.xyLabel.Text = 'xy';
            app.DwnSplFactorEdit = uieditfield(app.GridLayout51_2, 'numeric');
            app.DwnSplFactorEdit.Limits = [1 Inf];
            app.DwnSplFactorEdit.RoundFractionalValues = 'on';
            app.DwnSplFactorEdit.ValueChangedFcn = createCallbackFcn(app, @DwnSplCheckValueChanged, true);
            app.DwnSplFactorEdit.Enable = 'off';
            app.DwnSplFactorEdit.Value = 1;
            app.GridLayout51_4 = uigridlayout(app.GridLayout25);
            app.zLabel = uilabel(app.GridLayout51_4);
            app.zLabel.Enable = 'off';
            app.zLabel.Text = 'z';
            app.DepthDwnSplFactorEdit = uieditfield(app.GridLayout51_4, 'numeric');
            app.DepthDwnSplFactorEdit.Limits = [1 Inf];
            app.DepthDwnSplFactorEdit.RoundFractionalValues = 'on';
            app.DepthDwnSplFactorEdit.ValueChangedFcn = createCallbackFcn(app, @DwnSplCheckValueChanged, true);
            app.DepthDwnSplFactorEdit.Enable = 'off';
            app.DepthDwnSplFactorEdit.Value = 2;
            app.GridLayout30 = uigridlayout(app.SettingsGrid);
            app.VolClaheCheckBox = uicheckbox(app.GridLayout30);
            app.VolClaheCheckBox.ValueChangedFcn = createCallbackFcn(app, @VolClaheCheckBoxValueChanged, true);
            app.VolClaheCheckBox.Text = 'Clahe';
            app.VolClaheCheckBox.Value = true;
            app.ClipLimitEditFieldLabel = uilabel(app.GridLayout30);
            app.ClipLimitEditFieldLabel.Text = 'Clip Limit';
            app.ClipLimitEditField = uieditfield(app.GridLayout30, 'numeric');
            app.ClipLimitEditField.Limits = [0.001 1];
            app.ClipLimitEditField.ValueChangedFcn = createCallbackFcn(app, @VolClaheCheckBoxValueChanged, true);
            app.ClipLimitEditField.Value = 0.02;
            app.MainImageGrid = uigridlayout(app.MainGrid);
            app.FiltDisp = uiaxes(app.MainImageGrid);
            title(app.FiltDisp, {'XY Projection'; ''})
            app.yzSliceDisp = uiaxes(app.MainImageGrid);
            title(app.yzSliceDisp, 'YZ Slice')
            app.xzSliceDisp = uiaxes(app.MainImageGrid);
            title(app.xzSliceDisp, 'XZ Slice')
            app.SideProjGrid = uigridlayout(app.MainImageGrid);
            app.xzProjDisp = uiaxes(app.SideProjGrid);
            title(app.xzProjDisp, {'XZ Projection'; ''})
            app.yzProjDisp = uiaxes(app.SideProjGrid);
            title(app.yzProjDisp, {'YZ Projection'; ''})
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = VolGui(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)Open_App(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end