classdef ExportGui < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        TopGrid                     matlab.ui.container.GridLayout
        TopPanel                    matlab.ui.container.Panel
        GridLayout5                 matlab.ui.container.GridLayout
        GridLayout6                 matlab.ui.container.GridLayout
        GridLayout7_2               matlab.ui.container.GridLayout
        expFolderPath               matlab.ui.control.EditField
        FolderPathLabel             matlab.ui.control.Label
        GridLayout7_3               matlab.ui.container.GridLayout
        FileNameLabel               matlab.ui.control.Label
        expFileName                 matlab.ui.control.EditField
        PreFixLabel                 matlab.ui.control.Label
        ExpPreFix                   matlab.ui.control.EditField
        GridLayout44                matlab.ui.container.GridLayout
        SelectExportPathButton      matlab.ui.control.Button
        BottomGrid                  matlab.ui.container.GridLayout
        GridLayout9                 matlab.ui.container.GridLayout
        VolumeExportPanel           matlab.ui.container.Panel
        GridLayout10                matlab.ui.container.GridLayout
        ExpVolMat                   matlab.ui.control.CheckBox
        ExpVolVtk                   matlab.ui.control.CheckBox
        ExpVolWrk                   matlab.ui.control.CheckBox
        ExpVolMatDoCmpr             matlab.ui.control.CheckBox
        MapExportPanel              matlab.ui.container.Panel
        GridLayout11                matlab.ui.container.GridLayout
        ExpImMat                    matlab.ui.control.CheckBox
        ExpOverview                 matlab.ui.control.CheckBox
        GridLayout41                matlab.ui.container.GridLayout
        ExpOverJpg                  matlab.ui.control.CheckBox
        ExpOverPng                  matlab.ui.control.CheckBox
        ExpOverTiff                 matlab.ui.control.CheckBox
        ExpOverPdf                  matlab.ui.control.CheckBox
        ExpNative                   matlab.ui.control.CheckBox
        GridLayout42                matlab.ui.container.GridLayout
        ExpNativeTiff               matlab.ui.control.CheckBox
        ExpNativeJpg                matlab.ui.control.CheckBox
        ExpNativePng                matlab.ui.control.CheckBox
        ExpDepthMap                 matlab.ui.control.CheckBox
        ExpWrkIm                    matlab.ui.control.CheckBox
        VesselInMap                 matlab.ui.control.CheckBox
        GeneralExportSettingsPanel  matlab.ui.container.Panel
        GridLayout43                matlab.ui.container.GridLayout
        GridLayout46                matlab.ui.container.GridLayout
        ExpOverWrite                matlab.ui.control.CheckBox
        UseFileName                 matlab.ui.control.CheckBox
        ExpLogFile                  matlab.ui.control.CheckBox
        GridLayout46_2              matlab.ui.container.GridLayout
        ExpProcessingSettings       matlab.ui.control.CheckBox
        OpenFolder                  matlab.ui.control.CheckBox
        VesselExportPanel           matlab.ui.container.Panel
        GridLayout11_2              matlab.ui.container.GridLayout
        ExpVesselMat                matlab.ui.control.CheckBox
        ExpVesselOverview           matlab.ui.control.CheckBox
        GridLayout42_2              matlab.ui.container.GridLayout
        ExpVesselJpg                matlab.ui.control.CheckBox
        ExpVesselFig                matlab.ui.control.CheckBox
        GridLayout45                matlab.ui.container.GridLayout
        ExportDataButton            matlab.ui.control.Button
        StartBatchProcessButton     matlab.ui.control.Button
        TextArea                    matlab.ui.control.TextArea
        TextArea_2                  matlab.ui.control.TextArea
    end

    
    properties (Access = public)
        PPA = PostProBackEnd().empty;
    end
    
    methods (Access = public)
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, PPA, filePath)
            movegui(app.UIFigure,'northwest');
            if ~isempty(PPA.MasterGUI)
                masterGuiPos = PPA.MasterGUI.UIFigure.Position;
                rightBorder = masterGuiPos(1)+masterGuiPos(3);
                app.UIFigure.Position(1) = rightBorder;
                % move down a bit so windows don't fully overlapp
                app.UIFigure.Position(2) = app.UIFigure.Position(2) - 75;
            end
            
            % transfer PPA handle and app handle
            app.PPA = PPA;
            app.PPA.ExportGUI = app; % transfer handle to app
            
            app.expFileName.Editable = true;
            app.expFileName.Enable = true;
            app.expFileName.Value = app.PPA.fileName;
            % set file path as default export
            app.PPA.exportPath = fullfile(app.PPA.folderPath,'gui_export');
            app.expFolderPath.Value = app.PPA.exportPath;
            
            app.PPA.Handle_Export_Controls();
        end

        % Button pushed function: SelectExportPathButton
        function SelectExportPathButtonPushed(app, event)
            folder = uigetdir(app.PPA.folderPath);
            if folder
                app.PPA.exportPath = folder;
                app.expFolderPath.Value = folder;
            else
                uialert(app.UIFigure,'No folder selected!','No folder selected!');
                return;
            end
        end

        % Button pushed function: ExportDataButton
        function ExportDataButtonPushed(app, event)
            app.PPA.Export();
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            % instead of closing app, we make it invisible
            app.UIFigure.Visible = 'off';
        end

        % Value changed function: ExpOverview
        function ExpOverviewValueChanged(app, event)
            app.ExpOverJpg.Enable = app.ExpOverview.Value;
            app.ExpOverPdf.Enable = app.ExpOverview.Value;
            app.ExpOverPng.Enable = app.ExpOverview.Value;
            app.ExpOverTiff.Enable = app.ExpOverview.Value;
        end

        % Value changed function: ExpVolMat
        function ExpVolMatValueChanged(app, event)
            app.ExpVolMatDoCmpr.Enable = app.ExpVolMat.Value;
        end

        % Value changed function: ExpNative
        function ExpNativeValueChanged(app, event)
            app.ExpNativeJpg.Enable = app.ExpNative.Value;
            app.ExpNativeTiff.Enable = app.ExpNative.Value;
            app.ExpNativePng.Enable = app.ExpNative.Value;
            
        end

        % Value changed function: ExpVolWrk
        function ExpVolWrkValueChanged(app, event)
            app.ExpWrkIm.Value = ~app.ExpVolWrk.Value;
        end

        % Value changed function: ExpWrkIm
        function ExpWrkImValueChanged(app, event)
            app.ExpVolWrk.Value = ~app.ExpWrkIm.Value;
        end

        % Button pushed function: StartBatchProcessButton
        function StartBatchProcessButtonPushed(app, event)
            app.PPA.Batch_Process_Folder();
        end

        % Value changed function: ExpVesselOverview
        function ExpVesselOverviewValueChanged(app, event)
           app.ExpVesselJpg.Enable = app.ExpVesselOverview.Value;
           app.ExpVesselFig.Enable = app.ExpVesselOverview.Value;
        end

        % Value changed function: UseFileName
        function UseFileNameValueChanged(app, event)
            if app.UseFileName.Value
                app.expFileName.Value = app.PPA.fileName;
            end
            app.expFileName.Editable = ~app.UseFileName.Value;
            app.expFileName.Enable = true;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'Export';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.TopGrid = uigridlayout(app.UIFigure);
            app.TopPanel = uipanel(app.TopGrid);
            app.GridLayout5 = uigridlayout(app.TopPanel);
            app.GridLayout6 = uigridlayout(app.GridLayout5);
            app.GridLayout7_2 = uigridlayout(app.GridLayout6);
            app.expFolderPath = uieditfield(app.GridLayout7_2, 'text');
            app.FolderPathLabel = uilabel(app.GridLayout7_2);
            app.FolderPathLabel.Text = 'Folder Path';
            app.GridLayout7_3 = uigridlayout(app.GridLayout6);
            app.FileNameLabel = uilabel(app.GridLayout7_3);
            app.FileNameLabel.Text = 'File Name';
            app.expFileName = uieditfield(app.GridLayout7_3, 'text');
            app.PreFixLabel = uilabel(app.GridLayout7_3);
            app.PreFixLabel.Text = 'PreFix';
            app.ExpPreFix = uieditfield(app.GridLayout7_3, 'text');
            app.GridLayout44 = uigridlayout(app.GridLayout5);
            app.SelectExportPathButton = uibutton(app.GridLayout44, 'push');
            app.SelectExportPathButton.ButtonPushedFcn = createCallbackFcn(app, @SelectExportPathButtonPushed, true);
            app.SelectExportPathButton.Enable = 'off';
            app.SelectExportPathButton.Text = {'Select New '; 'Export Folder'};
            app.BottomGrid = uigridlayout(app.TopGrid);
            app.GridLayout9 = uigridlayout(app.BottomGrid);
            app.VolumeExportPanel = uipanel(app.GridLayout9);
            app.VolumeExportPanel.Title = 'Volume Export';
            app.GridLayout10 = uigridlayout(app.VolumeExportPanel);
            app.ExpVolMat = uicheckbox(app.GridLayout10);
            app.ExpVolMat.ValueChangedFcn = createCallbackFcn(app, @ExpVolMatValueChanged, true);
            app.ExpVolMat.Enable = 'off';
            app.ExpVolMat.Text = 'Export .mat';
            app.ExpVolVtk = uicheckbox(app.GridLayout10);
            app.ExpVolVtk.Enable = 'off';
            app.ExpVolVtk.Text = 'Export .vtk';
            app.ExpVolWrk = uicheckbox(app.GridLayout10);
            app.ExpVolWrk.ValueChangedFcn = createCallbackFcn(app, @ExpVolWrkValueChanged, true);
            app.ExpVolWrk.Enable = 'off';
            app.ExpVolWrk.Text = 'Export to workspace';
            app.ExpVolMatDoCmpr = uicheckbox(app.GridLayout10);
            app.ExpVolMatDoCmpr.Enable = 'off';
            app.ExpVolMatDoCmpr.Text = 'Compress .mat';
            app.MapExportPanel = uipanel(app.GridLayout9);
            app.MapExportPanel.Title = 'Map Export';
            app.GridLayout11 = uigridlayout(app.MapExportPanel);
            app.ExpImMat = uicheckbox(app.GridLayout11);
            app.ExpImMat.Enable = 'off';
            app.ExpImMat.Text = 'Export .mat';
            app.ExpOverview = uicheckbox(app.GridLayout11);
            app.ExpOverview.ValueChangedFcn = createCallbackFcn(app, @ExpOverviewValueChanged, true);
            app.ExpOverview.Enable = 'off';
            app.ExpOverview.Tooltip = {'Create side-by-side overviews of mip and depth map including colorbar. '};
            app.ExpOverview.Text = 'Overview';
            app.ExpOverview.Value = true;
            app.GridLayout41 = uigridlayout(app.GridLayout11);
            app.ExpOverJpg = uicheckbox(app.GridLayout41);
            app.ExpOverJpg.Enable = 'off';
            app.ExpOverJpg.Text = '.jpg';
            app.ExpOverJpg.Value = true;
            app.ExpOverPng = uicheckbox(app.GridLayout41);
            app.ExpOverPng.Enable = 'off';
            app.ExpOverPng.Text = '.png';
            app.ExpOverTiff = uicheckbox(app.GridLayout41);
            app.ExpOverTiff.Enable = 'off';
            app.ExpOverTiff.Text = '.tiff';
            app.ExpOverPdf = uicheckbox(app.GridLayout41);
            app.ExpOverPdf.Enable = 'off';
            app.ExpOverPdf.Text = '.pdf';
            app.ExpOverPdf.Value = true;
            app.ExpNative = uicheckbox(app.GridLayout11);
            app.ExpNative.ValueChangedFcn = createCallbackFcn(app, @ExpNativeValueChanged, true);
            app.ExpNative.Enable = 'off';
            app.ExpNative.Tooltip = {'Create native resolution RGB images (without color bar)?'};
            app.ExpNative.Text = 'Native';
            app.ExpNative.Value = true;
            app.GridLayout42 = uigridlayout(app.GridLayout11);
            app.ExpNativeTiff = uicheckbox(app.GridLayout42);
            app.ExpNativeTiff.Enable = 'off';
            app.ExpNativeTiff.Text = '.tiff';
            app.ExpNativeTiff.Value = true;
            app.ExpNativeJpg = uicheckbox(app.GridLayout42);
            app.ExpNativeJpg.Enable = 'off';
            app.ExpNativeJpg.Text = '.jpg';
            app.ExpNativeJpg.Value = true;
            app.ExpNativePng = uicheckbox(app.GridLayout42);
            app.ExpNativePng.Enable = 'off';
            app.ExpNativePng.Text = '.png';
            app.ExpDepthMap = uicheckbox(app.GridLayout11);
            app.ExpDepthMap.Enable = 'off';
            app.ExpDepthMap.Tooltip = {'Create native resolution RGB depth map (without colorbar)?'; ''};
            app.ExpDepthMap.Text = 'Depth Map';
            app.ExpDepthMap.Value = true;
            app.ExpWrkIm = uicheckbox(app.GridLayout11);
            app.ExpWrkIm.ValueChangedFcn = createCallbackFcn(app, @ExpWrkImValueChanged, true);
            app.ExpWrkIm.Enable = 'off';
            app.ExpWrkIm.Text = 'Export to workspace';
            app.VesselInMap = uicheckbox(app.GridLayout11);
            app.VesselInMap.Enable = 'off';
            app.VesselInMap.Tooltip = {'Create native resolution RGB depth map (without colorbar)?'; ''};
            app.VesselInMap.Text = 'Processing Settings';
            app.GeneralExportSettingsPanel = uipanel(app.GridLayout9);
            app.GeneralExportSettingsPanel.Title = 'General Export Settings';
            app.GridLayout43 = uigridlayout(app.GeneralExportSettingsPanel);
            app.GridLayout46 = uigridlayout(app.GridLayout43);
            app.ExpOverWrite = uicheckbox(app.GridLayout46);
            app.ExpOverWrite.Text = 'Overwrite?';
            app.ExpOverWrite.Value = true;
            app.UseFileName = uicheckbox(app.GridLayout46);
            app.UseFileName.ValueChangedFcn = createCallbackFcn(app, @UseFileNameValueChanged, true);
            app.UseFileName.Tooltip = {'If enabled, uses load file name as default file name for export when loading new data.'};
            app.UseFileName.Text = 'Use file name';
            app.ExpLogFile = uicheckbox(app.GridLayout46);
            app.ExpLogFile.Text = 'Export log';
            app.GridLayout46_2 = uigridlayout(app.GridLayout43);
            app.ExpProcessingSettings = uicheckbox(app.GridLayout46_2);
            app.ExpProcessingSettings.Text = 'Export Settings';
            app.ExpProcessingSettings.Value = true;
            app.OpenFolder = uicheckbox(app.GridLayout46_2);
            app.OpenFolder.Text = {'Open Export Folder?'; ''};
            app.OpenFolder.Value = true;
            app.VesselExportPanel = uipanel(app.GridLayout9);
            app.VesselExportPanel.Title = 'Vessel Export';
            app.GridLayout11_2 = uigridlayout(app.VesselExportPanel);
            app.ExpVesselMat = uicheckbox(app.GridLayout11_2);
            app.ExpVesselMat.Enable = 'off';
            app.ExpVesselMat.Text = 'Export .mat';
            app.ExpVesselMat.Value = true;
            app.ExpVesselOverview = uicheckbox(app.GridLayout11_2);
            app.ExpVesselOverview.ValueChangedFcn = createCallbackFcn(app, @ExpVesselOverviewValueChanged, true);
            app.ExpVesselOverview.Enable = 'off';
            app.ExpVesselOverview.Tooltip = {'Create side-by-side overviews of mip and depth map including colorbar. '};
            app.ExpVesselOverview.Text = 'Overview';
            app.GridLayout42_2 = uigridlayout(app.GridLayout11_2);
            app.ExpVesselJpg = uicheckbox(app.GridLayout42_2);
            app.ExpVesselJpg.Enable = 'off';
            app.ExpVesselJpg.Text = '.jpg';
            app.ExpVesselJpg.Value = true;
            app.ExpVesselFig = uicheckbox(app.GridLayout42_2);
            app.ExpVesselFig.Enable = 'off';
            app.ExpVesselFig.Text = '.fig';
            app.GridLayout45 = uigridlayout(app.BottomGrid);
            app.ExportDataButton = uibutton(app.GridLayout45, 'push');
            app.ExportDataButton.ButtonPushedFcn = createCallbackFcn(app, @ExportDataButtonPushed, true);
            app.ExportDataButton.Enable = 'off';
            app.ExportDataButton.Text = 'Export Data';
            app.StartBatchProcessButton = uibutton(app.GridLayout45, 'push');
            app.StartBatchProcessButton.ButtonPushedFcn = createCallbackFcn(app, @StartBatchProcessButtonPushed, true);
            app.StartBatchProcessButton.Enable = 'off';
            app.StartBatchProcessButton.Text = 'Start Batch Process';
            app.TextArea = uitextarea(app.GridLayout45);
            app.TextArea.Value = {'Select the files you want to export on the left, then press [Export Data] to export the current dataset.'; ''; 'You can change the default export folder by pressing [Select New Path] above. '};
            app.TextArea_2 = uitextarea(app.GridLayout45);
            app.TextArea_2.Value = {'Press [Start Batch Process] below to select a folder containing mutilple datasets and to process them in the exact same way as you processed the last dataset. '};
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ExportGui(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end