classdef LoadGui < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        TopGrid                    matlab.ui.container.GridLayout
        Panel                      matlab.ui.container.Panel
        GridLayout5                matlab.ui.container.GridLayout
        GridLayout6                matlab.ui.container.GridLayout
        GridLayout7_2              matlab.ui.container.GridLayout
        FolderPathEditField        matlab.ui.control.EditField
        FolderPathLabel            matlab.ui.control.Label
        GridLayout7_3              matlab.ui.container.GridLayout
        FileNameLabel              matlab.ui.control.Label
        FileNameEditField          matlab.ui.control.EditField
        GridLayout8                matlab.ui.container.GridLayout
        LoadDataButton             matlab.ui.control.Button
        SelectNewFileButton        matlab.ui.control.Button
        BottomGrid                 matlab.ui.container.GridLayout
        FileContentPanel           matlab.ui.container.Panel
        GridLayout3                matlab.ui.container.GridLayout
        UITable                    matlab.ui.control.Table
        GridLayout9                matlab.ui.container.GridLayout
        GridLayout10               matlab.ui.container.GridLayout
        GridLayout10_3             matlab.ui.container.GridLayout
        FullInfoLampLabel          matlab.ui.control.Label
        FullInfoLamp               matlab.ui.control.Lamp
        GridLayout10_2             matlab.ui.container.GridLayout
        GoodFormatLampLabel        matlab.ui.control.Label
        GoodFormatLamp             matlab.ui.control.Lamp
        PrevImage                  matlab.ui.control.Image
        GridLayout11               matlab.ui.container.GridLayout
        OptionsLabel               matlab.ui.control.Label
        LoadFromWorkspaceCheckBox  matlab.ui.control.CheckBox
        LoadVariableCheckBox       matlab.ui.control.CheckBox
    end

    
    properties (Access = public)
       PPA = PostProBackEnd().empty;
       lastSelectedRow; 
    end
    
    properties (Access = private)
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, PPA, filePath)
            % move gui window to top-left
            movegui(app.UIFigure,'north');
            if ~isempty(PPA.MasterGUI)
                masterGuiPos = PPA.MasterGUI.UIFigure.Position;
                rightBorder = masterGuiPos(1)+masterGuiPos(3);
                app.UIFigure.Position(1) = rightBorder;
            end
            % then move over to be next to master gui
            masterGuiPos = PPA.MasterGUI.UIFigure.Position;
            rightBorder = masterGuiPos(1)+masterGuiPos(3);
            app.UIFigure.Position(1) = rightBorder;
            
            % transfer PPA handle and app handle
            app.PPA = PPA;
            app.PPA.LoadGUI = app; % transfer handle to app
            
            if nargin == 3 && ~isempty(filePath) % filePath was provided, handle it
                app.PPA.filePath = filePath;
                app.FileNameEditField.Value = app.PPA.fileName;
                app.FolderPathEditField.Value = app.PPA.folderPath;
            end
            
            if app.PPA.doBatchProcessing  % just load data, no preview
                app.PPA.Check_File_Content();
                app.PPA.Load_Raw_Data();
            elseif exist(app.PPA.filePath,'file') % we have a file in PPA, try and preview it
                app.FileNameEditField.Value = app.PPA.fileName;
                app.FolderPathEditField.Value = app.PPA.folderPath;
                app.PPA.Preview_File_Content();
            else % no file provided, load default image with overlayed message
                defaultImage = imread('load_gui_default_im.jpg');
                % make it bigger, so it looks blurry but not pixelated if window size increases
                defaultImage = imresize(defaultImage,4).*0.4; % 0.4 makes image dark
                infoStr = sprintf('Please press\n[Select New File]');
                ctrPos = round(size(defaultImage)./2);
                ctrPos = ctrPos(1:2); % remove 3rd dim of rgb image
                defaultImage = insertText(defaultImage, ctrPos, infoStr, ...
                    'FontSize', 60, 'TextColor', 'black', 'BoxColor', 'white', ...
                    'BoxOpacity', 0.5, 'AnchorPoint','center');
                app.PrevImage.ImageSource = defaultImage;
            end
        end

        % Callback function: PrevImage, SelectNewFileButton
        function SelectNewFileButtonPushed(app, event)
            app.PPA.Handle_Master_Gui_State('load_start');

            [fileName,folder] = uigetfile({'*.*',  'All Files (*.*)';...
                '*.tiff;.tif','Tiff (*.tiff, *.tif)'; ...
                '*.mat', 'MATLAB Files (*.mat,)'},...
                'File Selector',app.PPA.folderPath);
            
            if fileName
                app.PPA.filePath = [folder fileName];
                app.FileNameEditField.Value = app.PPA.fileName;
                app.FolderPathEditField.Value = app.PPA.folderPath;
            else
                uialert(app.UIFigure,'No file selected!','No file selected!');
                return;
            end
            
            if app.PPA.doBatchProcessing
                app.PPA.Check_File_Content();
                app.PPA.Load_Raw_Data();
            elseif exist(app.PPA.filePath,'file')
                app.PPA.Preview_File_Content();
            end
            
        end

        % Button pushed function: LoadDataButton
        function LoadDataButtonPushed(app, event)
            app.PPA.Load_Raw_Data();
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            % instead of closing app, we make it invisible
            app.UIFigure.Visible = 'off';
        end

        % Value changed function: LoadFromWorkspaceCheckBox
        function LoadFromWorkspaceCheckBoxValueChanged(app, event)
            app.SelectNewFileButton.Enable = ~app.LoadFromWorkspaceCheckBox.Value;
            app.FileNameEditField.Enable = ~app.LoadFromWorkspaceCheckBox.Value;
            app.FolderPathEditField.Enable = ~app.LoadFromWorkspaceCheckBox.Value;
            app.LoadVariableCheckBox.Value = false;
            
            if app.LoadFromWorkspaceCheckBox.Value
                hasValidVars = app.PPA.Check_Workspace_Content();
            elseif exist(app.PPA.filePath,'file')
                hasValidVars = app.PPA.Preview_File_Content();
            else
                hasValidVars = false;
            end
            
            app.LoadDataButton.Enable = hasValidVars;
            app.LoadFromWorkspaceCheckBox.Value = hasValidVars;
            app.SelectNewFileButton.Enable = ~hasValidVars;
            app.FileNameEditField.Enable = ~hasValidVars;
            app.FolderPathEditField.Enable = ~hasValidVars;
            
        end

        % Value changed function: LoadVariableCheckBox
        function LoadVariableCheckBoxValueChanged(app, event)
            app.SelectNewFileButton.Enable = ~app.LoadVariableCheckBox.Value;
            app.FileNameEditField.Enable = ~app.LoadVariableCheckBox.Value;
            app.FolderPathEditField.Enable = ~app.LoadVariableCheckBox.Value;
            if app.LoadVariableCheckBox.Value
                app.FileContentPanel.Title = 'Workspace Variables';
                app.LoadFromWorkspaceCheckBox.Value = false;
                varInfos = evalin('base','whos');
                app.UITable.Data = [];
                names = cell(numel(varInfos), 1);
                sizes = cell(numel(varInfos), 1);
                bytes = cell(numel(varInfos), 1);
                
                for iData = 1:numel(varInfos)
                    names{iData} = varInfos(iData).name;
                    sizes{iData} = sprintf('%i ', varInfos(iData).size);
                    bytes{iData} = [num2sip(varInfos(iData).bytes, 3, false, true) 'B'];
                end
                app.UITable.Data = table(names, sizes, bytes);
                uiconfirm(app.UIFigure,...
                    'Select workspace variable on the left and press load.',...
                    'Load from workspace',...
                    'Options',{'Alright!'}, 'Icon','info');
            end
        end

        % Cell selection callback: UITable
        function UITableCellSelection(app, event)
            app.lastSelectedRow = event.Indices(1);
            if app.LoadVariableCheckBox.Value
                app.LoadDataButton.Enable = true;
                app.PPA.fileType = 5;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'Load';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.TopGrid = uigridlayout(app.UIFigure);
            app.Panel = uipanel(app.TopGrid);
            app.GridLayout5 = uigridlayout(app.Panel);
            app.GridLayout6 = uigridlayout(app.GridLayout5);
            app.GridLayout7_2 = uigridlayout(app.GridLayout6);
            app.FolderPathEditField = uieditfield(app.GridLayout7_2, 'text');
            app.FolderPathLabel = uilabel(app.GridLayout7_2);
            app.FolderPathLabel.Text = 'Folder Path';
            app.GridLayout7_3 = uigridlayout(app.GridLayout6);
            app.FileNameLabel = uilabel(app.GridLayout7_3);
            app.FileNameLabel.Text = 'File Name';
            app.FileNameEditField = uieditfield(app.GridLayout7_3, 'text');
            app.GridLayout8 = uigridlayout(app.GridLayout5);
            app.LoadDataButton = uibutton(app.GridLayout8, 'push');
            app.LoadDataButton.ButtonPushedFcn = createCallbackFcn(app, @LoadDataButtonPushed, true);
            app.LoadDataButton.Enable = 'off';
            app.LoadDataButton.Text = 'Load Data';
            app.SelectNewFileButton = uibutton(app.GridLayout8, 'push');
            app.SelectNewFileButton.ButtonPushedFcn = createCallbackFcn(app, @SelectNewFileButtonPushed, true);
            app.SelectNewFileButton.Text = 'Select New File';
            app.BottomGrid = uigridlayout(app.TopGrid);
            app.FileContentPanel = uipanel(app.BottomGrid);
            app.FileContentPanel.Title = 'File Content';
            app.GridLayout3 = uigridlayout(app.FileContentPanel);
            app.UITable = uitable(app.GridLayout3);
            app.UITable.ColumnName = {'Name'; 'Size'; 'Bytes'};
            app.UITable.RowName = {};
            app.UITable.CellSelectionCallback = createCallbackFcn(app, @UITableCellSelection, true);
            app.GridLayout9 = uigridlayout(app.BottomGrid);
            app.GridLayout10 = uigridlayout(app.GridLayout9);
            app.GridLayout10_3 = uigridlayout(app.GridLayout10);
            app.FullInfoLampLabel = uilabel(app.GridLayout10_3);
            app.FullInfoLampLabel.Text = 'Full Info';
            app.FullInfoLamp = uilamp(app.GridLayout10_3);
            app.FullInfoLamp.Tooltip = {'Mat file contains all required info to be loaded "automatically". No manual entry of data required.'; ''};
            app.GridLayout10_2 = uigridlayout(app.GridLayout10);
            app.GoodFormatLampLabel = uilabel(app.GridLayout10_2);
            app.GoodFormatLampLabel.Text = 'Good Format';
            app.GoodFormatLamp = uilamp(app.GridLayout10_2);
            app.GoodFormatLamp.Tooltip = {'Mat file contains all required info to be loaded "automatically". No manual entry of data required.'; ''};
            app.PrevImage = uiimage(app.GridLayout9);
            app.PrevImage.ImageClickedFcn = createCallbackFcn(app, @SelectNewFileButtonPushed, true);
            app.GridLayout11 = uigridlayout(app.GridLayout9);
            app.OptionsLabel = uilabel(app.GridLayout11);
            app.OptionsLabel.Text = 'Options';
            app.LoadFromWorkspaceCheckBox = uicheckbox(app.GridLayout11);
            app.LoadFromWorkspaceCheckBox.ValueChangedFcn = createCallbackFcn(app, @LoadFromWorkspaceCheckBoxValueChanged, true);
            app.LoadFromWorkspaceCheckBox.Tooltip = {'Requires map, x and y'};
            app.LoadFromWorkspaceCheckBox.Text = 'Load From Workspace';
            app.LoadVariableCheckBox = uicheckbox(app.GridLayout11);
            app.LoadVariableCheckBox.ValueChangedFcn = createCallbackFcn(app, @LoadVariableCheckBoxValueChanged, true);
            app.LoadVariableCheckBox.Tooltip = {'Select variable from workspace'};
            app.LoadVariableCheckBox.Text = 'Load Variable';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = LoadGui(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end