classdef MapGui < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        MainGrid                    matlab.ui.container.GridLayout
        GridLayout31                matlab.ui.container.GridLayout
        GridLayout33                matlab.ui.container.GridLayout
        UpdateMapsButton            matlab.ui.control.Button
        AutoUpdateCheckBox          matlab.ui.control.CheckBox
        ImageProcessingLabel        matlab.ui.control.Label
        SelectProcessingStepsPanel  matlab.ui.container.Panel
        GridLayout11                matlab.ui.container.GridLayout
        ContrastCheck               matlab.ui.control.CheckBox
        GridLayout22                matlab.ui.container.GridLayout
        imSpotRem                   matlab.ui.control.NumericEditField
        SpotRemovalCheckBox         matlab.ui.control.CheckBox
        GridLayout22_2              matlab.ui.container.GridLayout
        imInterpFactor              matlab.ui.control.NumericEditField
        InterpolateCheckBox         matlab.ui.control.CheckBox
        GridLayout22_3              matlab.ui.container.GridLayout
        WienerCheckBox              matlab.ui.control.CheckBox
        WienerSize                  matlab.ui.control.NumericEditField
        ImageGuidedCheckBox         matlab.ui.control.CheckBox
        AdjustContrastCheckBox      matlab.ui.control.CheckBox
        UseFrangiCheckBox           matlab.ui.control.CheckBox
        FrangiSettingsButton        matlab.ui.control.Button
        SpecialFilterCheckBox       matlab.ui.control.CheckBox
        SpecialFilterPanel          matlab.ui.container.Panel
        GridLayout22_13             matlab.ui.container.GridLayout
        GridLayout22_15             matlab.ui.container.GridLayout
        StrenghEditFieldLabel       matlab.ui.control.Label
        FiltStrength                matlab.ui.control.NumericEditField
        FilterDropDown              matlab.ui.control.DropDown
        GridLayout22_17             matlab.ui.container.GridLayout
        SizeEditFieldLabel          matlab.ui.control.Label
        FiltSize                    matlab.ui.control.NumericEditField
        CLAHEPanel                  matlab.ui.container.Panel
        GridLayout16                matlab.ui.container.GridLayout
        GridLayout22_4              matlab.ui.container.GridLayout
        GridLayout22_8              matlab.ui.container.GridLayout
        BinsLabel                   matlab.ui.control.Label
        ClaheBins                   matlab.ui.control.DropDown
        GridLayout22_10             matlab.ui.container.GridLayout
        TilesDropDownLabel          matlab.ui.control.Label
        ClaheTiles                  matlab.ui.control.DropDown
        GridLayout22_9              matlab.ui.container.GridLayout
        GridLayout22_11             matlab.ui.container.GridLayout
        ClipLimitSliderLabel        matlab.ui.control.Label
        ClaheClipLim                matlab.ui.control.Slider
        ClaheDistr                  matlab.ui.control.DropDown
        AdjustContrastPanel         matlab.ui.container.Panel
        GridLayout16_3              matlab.ui.container.GridLayout
        GridLayout41                matlab.ui.container.GridLayout
        LowerLimitLabel             matlab.ui.control.Label
        ContrastLowLimEdit          matlab.ui.control.NumericEditField
        GridLayout42                matlab.ui.container.GridLayout
        UpperLimitLabel             matlab.ui.control.Label
        ContrastUpLimEdit           matlab.ui.control.NumericEditField
        GridLayout42_2              matlab.ui.container.GridLayout
        GammaLabel                  matlab.ui.control.Label
        ContrastGammaEdit           matlab.ui.control.NumericEditField
        AutoContrCheckBox           matlab.ui.control.CheckBox
        ImageGuidedFilterPanel      matlab.ui.container.Panel
        GridLayout16_2              matlab.ui.container.GridLayout
        GridLayout22_6              matlab.ui.container.GridLayout
        NbhSizeLabel                matlab.ui.control.Label
        ImGuideSizeEditField        matlab.ui.control.NumericEditField
        GridLayout22_7              matlab.ui.container.GridLayout
        SmoothSliderLabel           matlab.ui.control.Label
        ImGuideSmoothSlider         matlab.ui.control.Slider
        DepthMapSettingsPanel       matlab.ui.container.Panel
        GridLayout17                matlab.ui.container.GridLayout
        GridLayout30                matlab.ui.container.GridLayout
        GridLayout30_5              matlab.ui.container.GridLayout
        OffsetEditFieldLabel        matlab.ui.control.Label
        OffsetEditField             matlab.ui.control.NumericEditField
        RemoveTrendCheckBox         matlab.ui.control.CheckBox
        UsePolyFitBox               matlab.ui.control.CheckBox
        GridLayout30_2              matlab.ui.container.GridLayout
        GridLayout30_6              matlab.ui.container.GridLayout
        TranspEditField_2Label      matlab.ui.control.Label
        TranspEditField             matlab.ui.control.NumericEditField
        GridLayout30_7              matlab.ui.container.GridLayout
        MinAmpEditFieldLabel        matlab.ui.control.Label
        MinAmpEditField             matlab.ui.control.NumericEditField
        GridLayout30_3              matlab.ui.container.GridLayout
        GridLayout30_8              matlab.ui.container.GridLayout
        SmoothEditFieldLabel        matlab.ui.control.Label
        SmoothEditField             matlab.ui.control.NumericEditField
        GridLayout30_9              matlab.ui.container.GridLayout
        depthColor                  matlab.ui.control.DropDown
        DepthMapLabel               matlab.ui.control.Label
        GridLayout43                matlab.ui.container.GridLayout
        GridLayout43_2              matlab.ui.container.GridLayout
        topcutEditFieldLabel        matlab.ui.control.Label
        topcutEditField             matlab.ui.control.NumericEditField
        GridLayout43_3              matlab.ui.container.GridLayout
        depthcutEditFieldLabel      matlab.ui.control.Label
        depthcutEditField           matlab.ui.control.NumericEditField
        GridDepthRangeOffsett       matlab.ui.container.GridLayout
        depthoffsetSlider           matlab.ui.control.Slider
        surfaceoffsetSlider         matlab.ui.control.Slider
        GridDepthRangeOffsett_2     matlab.ui.container.GridLayout
        CropDropDownLabel           matlab.ui.control.Label
        CropDepthDropDown           matlab.ui.control.DropDown
        shiftEditFieldLabel         matlab.ui.control.Label
        shiftEditField              matlab.ui.control.NumericEditField
        histoAx                     matlab.ui.control.UIAxes
    end

    
    properties (Access = public)
        PPA = PostProBackEnd().empty;
    end
    
    methods (Access = public)
        
        function Close_Request(app)
            app.UIFigure.Visible = 'off';
            delete(app.PPA.MapFig.MainFig);
            app.PPA.MapFig = []; % delete old handles
        end
        
        % if auto update is enabled, apply image processing
        function Apply_Auto_Image_Processing(app)
            if app.AutoUpdateCheckBox.Value
                app.PPA.Apply_Image_Processing();
            else
                app.PPA.Handle_Map_Controls();
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function Open_App(app, PPA)
            movegui(app.UIFigure,'northwest');
            if ~isempty(PPA.MasterGUI)
                masterGuiPos = PPA.MasterGUI.UIFigure.Position;
                rightBorder = masterGuiPos(1)+masterGuiPos(3);
                app.UIFigure.Position(1) = rightBorder;
                % move down a bit so windows don't fully overlapp
                app.UIFigure.Position(2) = app.UIFigure.Position(2) - 50;
            end
            app.PPA = PPA;
            app.PPA.MapGUI = app; % transfer handle to app
            
            % setup UI axis for images
            app.PPA.Setup_Map_Figure();
            app.Apply_Auto_Image_Processing();
        end

        % Close request function: UIFigure
        function CloseApp(app, event)
            app.Close_Request();
        end

        % Value changed function: AdjustContrastCheckBox, 
        % AutoContrCheckBox, ClaheBins, ClaheClipLim, ClaheDistr, 
        % ClaheTiles, ContrastCheck, ContrastGammaEdit, 
        % ContrastLowLimEdit, ContrastUpLimEdit, FiltSize, 
        % FiltStrength, ImGuideSizeEditField, ImGuideSmoothSlider, 
        % ImageGuidedCheckBox, InterpolateCheckBox, 
        % SpotRemovalCheckBox, WienerCheckBox, WienerSize, 
        % imInterpFactor, imSpotRem
        function Settings_Changed(app, event)
            app.Apply_Auto_Image_Processing();
        end

        % Value changed function: MinAmpEditField, OffsetEditField, 
        % RemoveTrendCheckBox, SmoothEditField, TranspEditField, 
        % depthColor, depthcutEditField, depthoffsetSlider, 
        % shiftEditField, surfaceoffsetSlider, topcutEditField
        function Update_Depth_Map(app, event)
            if app.AutoUpdateCheckBox.Value
                app.PPA.Update_Depth_Map();
            end
        end

        % Value changed function: UseFrangiCheckBox
        function UseFrangiCheckBoxValueChanged(app, event)
            app.FrangiSettingsButton.Enable = app.UseFrangiCheckBox.Value;
            if app.UseFrangiCheckBox.Value
                app.PPA.Init_Frangi('map');
            end
        end

        % Button pushed function: FrangiSettingsButton
        function FrangiSettingsButtonPushed(app, event)
            app.PPA.MapFrangi.Open_GUI();
            % app.PPA.FraFilt.Apply_Frangi(); % update frangi plot...
        end

        % Value changed function: CropDepthDropDown
        function CropDepthDropDownValueChanged(app, event)
            relShift = strcmp(app.CropDepthDropDown.Value,'Relative (above)');
            app.topcutEditField.Enable = ~relShift;
            app.depthcutEditField.Enable = ~relShift;
            app.depthoffsetSlider.Enable = relShift;
            app.surfaceoffsetSlider.Enable = relShift;
            if app.AutoUpdateCheckBox.Value
                app.PPA.Update_Depth_Map();
            end
        end

        % Button pushed function: UpdateMapsButton
        function UpdateMapsButtonPushed(app, event)
            app.PPA.Apply_Image_Processing();
        end

        % Value changed function: FilterDropDown, 
        % SpecialFilterCheckBox
        function FilterDropDownValueChanged(app, event)
            app.FilterDropDown.Enable = app.SpecialFilterCheckBox.Value;
            switch app.FilterDropDown.Value
                case 'average'
                    app.FiltSize.Enable = true;
                    app.FiltStrength.Enable = false;
                case 'disk'
                    app.FiltSize.Enable = true;
                    app.FiltStrength.Enable = false;
                case 'laplacian'
                    app.FiltSize.Enable = false;
                    app.FiltStrength.Enable = true;
                    app.FiltStrength.Value = 0.5;
                case 'log'
                    app.FiltSize.Enable = true;
                    app.FiltStrength.Enable = true;
                case 'motion'
                    app.FiltSize.Enable = true;
                    app.FiltStrength.Enable = true;
                case 'prewitt'
                    app.FiltSize.Enable = false;
                    app.FiltStrength.Enable = false;
                case 'sobel'
                    app.FiltSize.Enable = false;
                    app.FiltStrength.Enable = false;
                case 'gaussian'
                    app.FiltSize.Enable = true;
                    app.FiltStrength.Enable = true;
                case 'median'
                    app.FiltSize.Enable = true;
                    app.FiltStrength.Enable = false;
            end
            if app.AutoUpdateCheckBox.Value
                app.PPA.Apply_Image_Processing();
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'GUI Map';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @CloseApp, true);
            app.MainGrid = uigridlayout(app.UIFigure);
            app.GridLayout31 = uigridlayout(app.MainGrid);
            app.GridLayout33 = uigridlayout(app.GridLayout31);
            app.UpdateMapsButton = uibutton(app.GridLayout33, 'push');
            app.UpdateMapsButton.ButtonPushedFcn = createCallbackFcn(app, @UpdateMapsButtonPushed, true);
            app.UpdateMapsButton.Text = 'Update Maps';
            app.AutoUpdateCheckBox = uicheckbox(app.GridLayout33);
            app.AutoUpdateCheckBox.Text = 'Auto Update?';
            app.AutoUpdateCheckBox.Value = true;
            app.ImageProcessingLabel = uilabel(app.GridLayout31);
            app.ImageProcessingLabel.Text = 'Image Processing';
            app.SelectProcessingStepsPanel = uipanel(app.GridLayout31);
            app.SelectProcessingStepsPanel.Title = 'Select Processing Steps';
            app.GridLayout11 = uigridlayout(app.SelectProcessingStepsPanel);
            app.ContrastCheck = uicheckbox(app.GridLayout11);
            app.ContrastCheck.ValueChangedFcn = createCallbackFcn(app, @Settings_Changed, true);
            app.ContrastCheck.Text = 'CLAHE';
            app.ContrastCheck.Value = true;
            app.GridLayout22 = uigridlayout(app.GridLayout11);
            app.imSpotRem = uieditfield(app.GridLayout22, 'numeric');
            app.imSpotRem.Limits = [1 20];
            app.imSpotRem.RoundFractionalValues = 'on';
            app.imSpotRem.ValueChangedFcn = createCallbackFcn(app, @Settings_Changed, true);
            app.imSpotRem.Value = 5;
            app.SpotRemovalCheckBox = uicheckbox(app.GridLayout22);
            app.SpotRemovalCheckBox.ValueChangedFcn = createCallbackFcn(app, @Settings_Changed, true);
            app.SpotRemovalCheckBox.Text = {'Spot'; 'Removal'};
            app.GridLayout22_2 = uigridlayout(app.GridLayout11);
            app.imInterpFactor = uieditfield(app.GridLayout22_2, 'numeric');
            app.imInterpFactor.Limits = [0.1 10];
            app.imInterpFactor.ValueChangedFcn = createCallbackFcn(app, @Settings_Changed, true);
            app.imInterpFactor.Value = 2;
            app.InterpolateCheckBox = uicheckbox(app.GridLayout22_2);
            app.InterpolateCheckBox.ValueChangedFcn = createCallbackFcn(app, @Settings_Changed, true);
            app.InterpolateCheckBox.Text = 'Interpolate';
            app.GridLayout22_3 = uigridlayout(app.GridLayout11);
            app.WienerCheckBox = uicheckbox(app.GridLayout22_3);
            app.WienerCheckBox.ValueChangedFcn = createCallbackFcn(app, @Settings_Changed, true);
            app.WienerCheckBox.Text = 'Wiener';
            app.WienerSize = uieditfield(app.GridLayout22_3, 'numeric');
            app.WienerSize.Limits = [3 50];
            app.WienerSize.RoundFractionalValues = 'on';
            app.WienerSize.ValueChangedFcn = createCallbackFcn(app, @Settings_Changed, true);
            app.WienerSize.Value = 3;
            app.ImageGuidedCheckBox = uicheckbox(app.GridLayout11);
            app.ImageGuidedCheckBox.ValueChangedFcn = createCallbackFcn(app, @Settings_Changed, true);
            app.ImageGuidedCheckBox.Text = 'Image Guided';
            app.AdjustContrastCheckBox = uicheckbox(app.GridLayout11);
            app.AdjustContrastCheckBox.ValueChangedFcn = createCallbackFcn(app, @Settings_Changed, true);
            app.AdjustContrastCheckBox.Text = 'Adjust Contrast';
            app.AdjustContrastCheckBox.Value = true;
            app.UseFrangiCheckBox = uicheckbox(app.GridLayout11);
            app.UseFrangiCheckBox.ValueChangedFcn = createCallbackFcn(app, @UseFrangiCheckBoxValueChanged, true);
            app.UseFrangiCheckBox.Text = 'Use Frangi';
            app.FrangiSettingsButton = uibutton(app.GridLayout11, 'push');
            app.FrangiSettingsButton.ButtonPushedFcn = createCallbackFcn(app, @FrangiSettingsButtonPushed, true);
            app.FrangiSettingsButton.Enable = 'off';
            app.FrangiSettingsButton.Text = 'Frangi Settings';
            app.SpecialFilterCheckBox = uicheckbox(app.GridLayout11);
            app.SpecialFilterCheckBox.ValueChangedFcn = createCallbackFcn(app, @FilterDropDownValueChanged, true);
            app.SpecialFilterCheckBox.Text = 'Special Filter';
            app.SpecialFilterPanel = uipanel(app.GridLayout31);
            app.SpecialFilterPanel.Title = 'Special Filter';
            app.GridLayout22_13 = uigridlayout(app.SpecialFilterPanel);
            app.GridLayout22_15 = uigridlayout(app.GridLayout22_13);
            app.StrenghEditFieldLabel = uilabel(app.GridLayout22_15);
            app.StrenghEditFieldLabel.Text = 'Strengh';
            app.FiltStrength = uieditfield(app.GridLayout22_15, 'numeric');
            app.FiltStrength.Limits = [1e-05 Inf];
            app.FiltStrength.ValueDisplayFormat = '%03.2f';
            app.FiltStrength.ValueChangedFcn = createCallbackFcn(app, @Settings_Changed, true);
            app.FiltStrength.Value = 5;
            app.FilterDropDown = uidropdown(app.GridLayout22_13);
            app.FilterDropDown.Items = {'gaussian', 'median', 'disk', 'average', 'log', 'laplacian', 'motion', 'prewitt', 'sobel'};
            app.FilterDropDown.ValueChangedFcn = createCallbackFcn(app, @FilterDropDownValueChanged, true);
            app.FilterDropDown.Value = 'gaussian';
            app.GridLayout22_17 = uigridlayout(app.GridLayout22_13);
            app.SizeEditFieldLabel = uilabel(app.GridLayout22_17);
            app.SizeEditFieldLabel.Text = 'Size';
            app.FiltSize = uieditfield(app.GridLayout22_17, 'numeric');
            app.FiltSize.Limits = [1 Inf];
            app.FiltSize.RoundFractionalValues = 'on';
            app.FiltSize.ValueDisplayFormat = '%i';
            app.FiltSize.ValueChangedFcn = createCallbackFcn(app, @Settings_Changed, true);
            app.FiltSize.Value = 5;
            app.CLAHEPanel = uipanel(app.GridLayout31);
            app.CLAHEPanel.Title = 'CLAHE';
            app.GridLayout16 = uigridlayout(app.CLAHEPanel);
            app.GridLayout22_4 = uigridlayout(app.GridLayout16);
            app.GridLayout22_8 = uigridlayout(app.GridLayout22_4);
            app.BinsLabel = uilabel(app.GridLayout22_8);
            app.BinsLabel.Text = 'Bins';
            app.ClaheBins = uidropdown(app.GridLayout22_8);
            app.ClaheBins.Items = {'8', '16', '32', '64', '128', '256', '512', '1024', '2048'};
            app.ClaheBins.ValueChangedFcn = createCallbackFcn(app, @Settings_Changed, true);
            app.ClaheBins.Value = '256';
            app.GridLayout22_10 = uigridlayout(app.GridLayout22_4);
            app.TilesDropDownLabel = uilabel(app.GridLayout22_10);
            app.TilesDropDownLabel.Text = 'Tiles';
            app.ClaheTiles = uidropdown(app.GridLayout22_10);
            app.ClaheTiles.Items = {'8', '16', '32', '64', '128', '256', '512'};
            app.ClaheTiles.ValueChangedFcn = createCallbackFcn(app, @Settings_Changed, true);
            app.ClaheTiles.Value = '32';
            app.GridLayout22_9 = uigridlayout(app.GridLayout16);
            app.GridLayout22_11 = uigridlayout(app.GridLayout22_9);
            app.ClipLimitSliderLabel = uilabel(app.GridLayout22_11);
            app.ClipLimitSliderLabel.Text = 'Clip Limit';
            app.ClaheClipLim = uislider(app.GridLayout22_11);
            app.ClaheClipLim.Limits = [0.0001 0.1];
            app.ClaheClipLim.MajorTicks = [];
            app.ClaheClipLim.MajorTickLabels = {};
            app.ClaheClipLim.ValueChangedFcn = createCallbackFcn(app, @Settings_Changed, true);
            app.ClaheClipLim.MinorTicks = [];
            app.ClaheClipLim.Value = 0.02;
            app.ClaheDistr = uidropdown(app.GridLayout22_9);
            app.ClaheDistr.Items = {'uniform', 'rayleigh', 'exponential'};
            app.ClaheDistr.ValueChangedFcn = createCallbackFcn(app, @Settings_Changed, true);
            app.ClaheDistr.Value = 'uniform';
            app.AdjustContrastPanel = uipanel(app.GridLayout31);
            app.AdjustContrastPanel.Title = 'Adjust Contrast';
            app.GridLayout16_3 = uigridlayout(app.AdjustContrastPanel);
            app.GridLayout41 = uigridlayout(app.GridLayout16_3);
            app.LowerLimitLabel = uilabel(app.GridLayout41);
            app.LowerLimitLabel.Text = {'Lower'; 'Limit'};
            app.ContrastLowLimEdit = uieditfield(app.GridLayout41, 'numeric');
            app.ContrastLowLimEdit.Limits = [0 1];
            app.ContrastLowLimEdit.ValueDisplayFormat = '%4.2f';
            app.ContrastLowLimEdit.ValueChangedFcn = createCallbackFcn(app, @Settings_Changed, true);
            app.ContrastLowLimEdit.Enable = 'off';
            app.GridLayout42 = uigridlayout(app.GridLayout16_3);
            app.UpperLimitLabel = uilabel(app.GridLayout42);
            app.UpperLimitLabel.Text = {'Upper'; 'Limit'};
            app.ContrastUpLimEdit = uieditfield(app.GridLayout42, 'numeric');
            app.ContrastUpLimEdit.Limits = [0 1];
            app.ContrastUpLimEdit.ValueDisplayFormat = '%4.2f';
            app.ContrastUpLimEdit.ValueChangedFcn = createCallbackFcn(app, @Settings_Changed, true);
            app.ContrastUpLimEdit.Enable = 'off';
            app.ContrastUpLimEdit.Value = 1;
            app.GridLayout42_2 = uigridlayout(app.GridLayout16_3);
            app.GammaLabel = uilabel(app.GridLayout42_2);
            app.GammaLabel.Text = 'Gamma';
            app.ContrastGammaEdit = uieditfield(app.GridLayout42_2, 'numeric');
            app.ContrastGammaEdit.Limits = [0 10];
            app.ContrastGammaEdit.ValueDisplayFormat = '%4.2f';
            app.ContrastGammaEdit.ValueChangedFcn = createCallbackFcn(app, @Settings_Changed, true);
            app.ContrastGammaEdit.Enable = 'off';
            app.ContrastGammaEdit.Value = 1.05;
            app.AutoContrCheckBox = uicheckbox(app.GridLayout16_3);
            app.AutoContrCheckBox.ValueChangedFcn = createCallbackFcn(app, @Settings_Changed, true);
            app.AutoContrCheckBox.Enable = 'off';
            app.AutoContrCheckBox.Text = 'Auto Calc Limits';
            app.AutoContrCheckBox.Value = true;
            app.ImageGuidedFilterPanel = uipanel(app.GridLayout31);
            app.ImageGuidedFilterPanel.Title = 'Image-Guided Filter';
            app.GridLayout16_2 = uigridlayout(app.ImageGuidedFilterPanel);
            app.GridLayout22_6 = uigridlayout(app.GridLayout16_2);
            app.NbhSizeLabel = uilabel(app.GridLayout22_6);
            app.NbhSizeLabel.Text = 'Nbh Size';
            app.ImGuideSizeEditField = uieditfield(app.GridLayout22_6, 'numeric');
            app.ImGuideSizeEditField.Limits = [1 100];
            app.ImGuideSizeEditField.RoundFractionalValues = 'on';
            app.ImGuideSizeEditField.ValueChangedFcn = createCallbackFcn(app, @Settings_Changed, true);
            app.ImGuideSizeEditField.Enable = 'off';
            app.ImGuideSizeEditField.Value = 5;
            app.GridLayout22_7 = uigridlayout(app.GridLayout16_2);
            app.SmoothSliderLabel = uilabel(app.GridLayout22_7);
            app.SmoothSliderLabel.Text = 'Smooth';
            app.ImGuideSmoothSlider = uislider(app.GridLayout22_7);
            app.ImGuideSmoothSlider.Limits = [1e-05 2];
            app.ImGuideSmoothSlider.MajorTicks = [];
            app.ImGuideSmoothSlider.MajorTickLabels = {''};
            app.ImGuideSmoothSlider.ValueChangedFcn = createCallbackFcn(app, @Settings_Changed, true);
            app.ImGuideSmoothSlider.MinorTicks = [];
            app.ImGuideSmoothSlider.Enable = 'off';
            app.ImGuideSmoothSlider.Value = 0.15;
            app.DepthMapSettingsPanel = uipanel(app.GridLayout31);
            app.DepthMapSettingsPanel.Title = 'Depth Map Settings';
            app.GridLayout17 = uigridlayout(app.DepthMapSettingsPanel);
            app.GridLayout30 = uigridlayout(app.GridLayout17);
            app.GridLayout30_5 = uigridlayout(app.GridLayout30);
            app.OffsetEditFieldLabel = uilabel(app.GridLayout30_5);
            app.OffsetEditFieldLabel.Text = 'Offset';
            app.OffsetEditField = uieditfield(app.GridLayout30_5, 'numeric');
            app.OffsetEditField.ValueChangedFcn = createCallbackFcn(app, @Update_Depth_Map, true);
            app.RemoveTrendCheckBox = uicheckbox(app.GridLayout30);
            app.RemoveTrendCheckBox.ValueChangedFcn = createCallbackFcn(app, @Update_Depth_Map, true);
            app.RemoveTrendCheckBox.Text = 'Remove Trend';
            app.UsePolyFitBox = uicheckbox(app.GridLayout30);
            app.UsePolyFitBox.Text = 'PF?';
            app.GridLayout30_2 = uigridlayout(app.GridLayout17);
            app.GridLayout30_6 = uigridlayout(app.GridLayout30_2);
            app.TranspEditField_2Label = uilabel(app.GridLayout30_6);
            app.TranspEditField_2Label.Text = 'Transp. (%)';
            app.TranspEditField = uieditfield(app.GridLayout30_6, 'numeric');
            app.TranspEditField.Limits = [0 500];
            app.TranspEditField.RoundFractionalValues = 'on';
            app.TranspEditField.ValueChangedFcn = createCallbackFcn(app, @Update_Depth_Map, true);
            app.TranspEditField.Value = 110;
            app.GridLayout30_7 = uigridlayout(app.GridLayout30_2);
            app.MinAmpEditFieldLabel = uilabel(app.GridLayout30_7);
            app.MinAmpEditFieldLabel.Text = {'Min.'; 'Amp (%)'};
            app.MinAmpEditField = uieditfield(app.GridLayout30_7, 'numeric');
            app.MinAmpEditField.Limits = [0 100];
            app.MinAmpEditField.ValueDisplayFormat = '%03.1f';
            app.MinAmpEditField.ValueChangedFcn = createCallbackFcn(app, @Update_Depth_Map, true);
            app.MinAmpEditField.Value = 50;
            app.GridLayout30_3 = uigridlayout(app.GridLayout17);
            app.GridLayout30_8 = uigridlayout(app.GridLayout30_3);
            app.SmoothEditFieldLabel = uilabel(app.GridLayout30_8);
            app.SmoothEditFieldLabel.Text = 'Smooth';
            app.SmoothEditField = uieditfield(app.GridLayout30_8, 'numeric');
            app.SmoothEditField.Limits = [0 Inf];
            app.SmoothEditField.ValueChangedFcn = createCallbackFcn(app, @Update_Depth_Map, true);
            app.SmoothEditField.Tooltip = {'Apply gaussian smoothing to depth map? '};
            app.SmoothEditField.Value = 1;
            app.GridLayout30_9 = uigridlayout(app.GridLayout30_3);
            app.depthColor = uidropdown(app.GridLayout30_9);
            app.depthColor.Items = {'hot', 'jet', 'parula', 'BOG', 'BGO', 'GOB', 'GBO', 'OGB', 'OBG', 'GR', 'GO', 'ROG', ''};
            app.depthColor.ValueChangedFcn = createCallbackFcn(app, @Update_Depth_Map, true);
            app.depthColor.Tooltip = {'select colomap for image display'; ''};
            app.depthColor.Value = 'BGO';
            app.DepthMapLabel = uilabel(app.GridLayout30_9);
            app.DepthMapLabel.Text = 'Depth Map';
            app.GridLayout43 = uigridlayout(app.GridLayout17);
            app.GridLayout43_2 = uigridlayout(app.GridLayout43);
            app.topcutEditFieldLabel = uilabel(app.GridLayout43_2);
            app.topcutEditFieldLabel.Text = 'top cut';
            app.topcutEditField = uieditfield(app.GridLayout43_2, 'numeric');
            app.topcutEditField.ValueDisplayFormat = '%04.2f';
            app.topcutEditField.ValueChangedFcn = createCallbackFcn(app, @Update_Depth_Map, true);
            app.topcutEditField.Enable = 'off';
            app.GridLayout43_3 = uigridlayout(app.GridLayout43);
            app.depthcutEditFieldLabel = uilabel(app.GridLayout43_3);
            app.depthcutEditFieldLabel.Text = 'depth cut';
            app.depthcutEditField = uieditfield(app.GridLayout43_3, 'numeric');
            app.depthcutEditField.ValueDisplayFormat = '%04.2f';
            app.depthcutEditField.ValueChangedFcn = createCallbackFcn(app, @Update_Depth_Map, true);
            app.depthcutEditField.Enable = 'off';
            app.GridDepthRangeOffsett = uigridlayout(app.GridLayout17);
            app.depthoffsetSlider = uislider(app.GridDepthRangeOffsett);
            app.depthoffsetSlider.Limits = [-0.5 0];
            app.depthoffsetSlider.MajorTicks = [];
            app.depthoffsetSlider.ValueChangedFcn = createCallbackFcn(app, @Update_Depth_Map, true);
            app.depthoffsetSlider.MinorTicks = [];
            app.surfaceoffsetSlider = uislider(app.GridDepthRangeOffsett);
            app.surfaceoffsetSlider.Limits = [0 0.5];
            app.surfaceoffsetSlider.MajorTicks = [];
            app.surfaceoffsetSlider.ValueChangedFcn = createCallbackFcn(app, @Update_Depth_Map, true);
            app.surfaceoffsetSlider.MinorTicks = [];
            app.GridDepthRangeOffsett_2 = uigridlayout(app.GridLayout17);
            app.CropDropDownLabel = uilabel(app.GridDepthRangeOffsett_2);
            app.CropDropDownLabel.Text = 'Crop';
            app.CropDepthDropDown = uidropdown(app.GridDepthRangeOffsett_2);
            app.CropDepthDropDown.Items = {'Relative (above)', 'Absolute (below)', ''};
            app.CropDepthDropDown.ValueChangedFcn = createCallbackFcn(app, @CropDepthDropDownValueChanged, true);
            app.CropDepthDropDown.Value = 'Relative (above)';
            app.shiftEditFieldLabel = uilabel(app.GridDepthRangeOffsett_2);
            app.shiftEditFieldLabel.Text = '+/- shift';
            app.shiftEditField = uieditfield(app.GridDepthRangeOffsett_2, 'numeric');
            app.shiftEditField.ValueDisplayFormat = '%04.2f';
            app.shiftEditField.ValueChangedFcn = createCallbackFcn(app, @Update_Depth_Map, true);
            app.histoAx = uiaxes(app.GridLayout17);
            title(app.histoAx, 'Depth Histogram ')
            app.histoAx.YTick = [];
            app.histoAx.YTickLabel = '';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = MapGui(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)Open_App(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end