classdef MasterGui < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        MainGrid                     matlab.ui.container.GridLayout
        MainButtonGrid               matlab.ui.container.GridLayout
        LoadRawDataButton            matlab.ui.control.Button
        VolumeProcessingButton       matlab.ui.control.Button
        ExportButton                 matlab.ui.control.Button
        MapProcessingButton          matlab.ui.control.Button
        VesselAnalysisButton         matlab.ui.control.Button
        RestoreSettingsButton        matlab.ui.control.Button
        TopLogoGrid                  matlab.ui.container.GridLayout
        WizardHat                    matlab.ui.control.Image
        Wand                         matlab.ui.control.Image
        PostProcessingWizardLabel    matlab.ui.control.Label
        InfoGrid                     matlab.ui.container.GridLayout
        HelpImage                    matlab.ui.control.Image
        LicenseImage                 matlab.ui.control.Image
        JRUZH2020Label               matlab.ui.control.Label
        DebugMode                    matlab.ui.control.CheckBox
        ProcessingLogPanel           matlab.ui.container.Panel
        GridLayout7                  matlab.ui.container.GridLayout
        Label                        matlab.ui.control.Label
        DebugText                    matlab.ui.control.ListBox
        GeneralInfoPanel             matlab.ui.container.Panel
        GridLayout9                  matlab.ui.container.GridLayout
        GridLayout10                 matlab.ui.container.GridLayout
        FileEditFieldLabel           matlab.ui.control.Label
        FileEditField                matlab.ui.control.EditField
        GridLayout10_2               matlab.ui.container.GridLayout
        cBars                        matlab.ui.control.DropDown
        ColormapLabel                matlab.ui.control.Label
        VolumeInfoPanel              matlab.ui.container.Panel
        GridLayout2                  matlab.ui.container.GridLayout
        GridLayout3                  matlab.ui.container.GridLayout
        nZVol                        matlab.ui.control.NumericEditField
        nYVol                        matlab.ui.control.NumericEditField
        nXVol                        matlab.ui.control.NumericEditField
        VolSizeLabel                 matlab.ui.control.Label
        GridLayout5                  matlab.ui.container.GridLayout
        TotalMemoryEditFieldLabel    matlab.ui.control.Label
        VolMemory                    matlab.ui.control.EditField
        GridLayout6                  matlab.ui.container.GridLayout
        VolResoLabel                 matlab.ui.control.Label
        dXVol                        matlab.ui.control.NumericEditField
        dYVol                        matlab.ui.control.NumericEditField
        dZVol                        matlab.ui.control.NumericEditField
        VolResoLabel_2               matlab.ui.control.Label
        MapInfoPanel                 matlab.ui.container.Panel
        MapInfoGrid                  matlab.ui.container.GridLayout
        GridLayout3_2                matlab.ui.container.GridLayout
        nYMap                        matlab.ui.control.NumericEditField
        nXMap                        matlab.ui.control.NumericEditField
        MapSizeLable                 matlab.ui.control.Label
        GridLayout5_2                matlab.ui.container.GridLayout
        TotalMemoryEditField_2Label  matlab.ui.control.Label
        MapMemory                    matlab.ui.control.EditField
        GridLayout6_2                matlab.ui.container.GridLayout
        MapResoLabel                 matlab.ui.control.Label
        dXIm                         matlab.ui.control.NumericEditField
        dYIm                         matlab.ui.control.NumericEditField
        MapResoLabelUnit             matlab.ui.control.Label
    end

    
    properties (Access = public)
        PPA = PostProBackEnd().empty;
    end
    
    methods (Access = public)
        
        function Open_Vol_Gui(app)
            if isa(app.PPA.VolGUI,'VolGui') && ishandle(app.PPA.VolGUI.UIFigure)
                app.PPA.VolGUI.UIFigure.Visible = 'on';
                figure(app.PPA.VolGUI.UIFigure); % bring to front
            else
                VolGui(app.PPA); % start Gui
            end
            app.PPA.Init_Vol_Gui();
        end
        
        function Open_Load_Gui(app)
            if isa(app.PPA.LoadGUI,'LoadGui') && ishandle(app.PPA.LoadGUI.UIFigure)
                app.PPA.LoadGUI.UIFigure.Visible = 'on';
                figure(app.PPA.LoadGUI.UIFigure); % bring to front
            else
                LoadGui(app.PPA); % start Gui
            end
        end
        
        function Open_Map_Gui(app)
            if isa(app.PPA.MapGUI,'MapGui') && ishandle(app.PPA.MapGUI.UIFigure)
                app.PPA.MapGUI.UIFigure.Visible = 'on';
                figure(app.PPA.MapGUI.UIFigure); % bring to front
            else
                MapGui(app.PPA); % start Gui
            end
            if isempty(app.PPA.procProj)
                app.PPA.Apply_Image_Processing();
            else
                app.PPA.Update_Map_Projections(app.PPA.procProj);
            end
        end
        
        function Open_Vessel_Gui(app)
            if isa(app.PPA.VesselGUI,'VesselGui') && ishandle(app.PPA.VesselGUI.UIFigure)
                figure(app.PPA.VesselGUI.UIFigure); % make visible and bring to front
            else
                VesselGui(app.PPA); % start Gui
            end
            app.PPA.Apply_Vessel_Processing();
        end
        
        
        function Open_Export_Gui(app)
            if isa(app.PPA.ExportGUI,'ExportGui') && ishandle(app.PPA.ExportGUI.UIFigure)
                figure(app.PPA.ExportGUI.UIFigure); % make visible and bring to front
            else
                ExportGui(app.PPA); % start Gui
            end
            app.PPA.Handle_Export_Controls();
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function StartGui(app, filePath, PPA)
            movegui(app.UIFigure,'northwest');
            
            if nargin == 3 % PPA was provided, use it
                app.PPA = PPA;
            else
                PPA = PostProBackEnd();
                app.PPA = PPA;
                assignin('base','PPA',PPA); % create PPA in workspace as well
            end
            app.PPA.MasterGUI = app;
            
            if nargin >= 2 && ~isempty(filePath) % filePath was provided, handle it
                app.PPA.filePath = filePath;
                LoadGui(app.PPA,filePath); % try loading file
            end
        end

        % Close request function: UIFigure
        function CloseGui(app, event)
            delete(app.PPA.LoadGUI);
            delete(app.PPA.VolGUI);
            delete(app.PPA.MapGUI);
            delete(app.PPA.VesselGUI);
            if ~isempty(app.PPA.VesselFigs) && ishandle(app.PPA.VesselFigs.MainFig)
                close(app.PPA.VesselFigs.MainFig);
            end
            if ~isempty(app.PPA.VesselFigs) && ishandle(app.PPA.VesselFigs.ResultsFig)
                close(app.PPA.VesselFigs.ResultsFig);
            end
            delete(app.PPA.ExportGUI);
            delete(app);
        end

        % Button pushed function: LoadRawDataButton
        function LoadRawDataButtonPushed(app, event)
            app.Open_Load_Gui();
        end

        % Button pushed function: VolumeProcessingButton
        function VolumeProcessingButtonPushed(app, event)
            app.Open_Vol_Gui();
        end

        % Image clicked function: HelpImage
        function HelpImageClicked(app, event)
            % open readme on github
            web('https://github.com/razanskylab/PostProGUI');
        end

        % Image clicked function: LicenseImage
        function LicenseImageClicked(app, event)
            web('https://github.com/razanskylab/PostProGUI/blob/master/LICENSE');
        end

        % Button pushed function: MapProcessingButton
        function MapProcessingButtonPushed(app, event)
            app.Open_Map_Gui();
        end

        % Value changed function: cBars
        function UpdateColorMaps(app, event)
            app.PPA.Change_Color_Maps();
        end

        % Button pushed function: ExportButton
        function ExportButtonPushed(app, event)
            app.Open_Export_Gui();
        end

        % Button pushed function: VesselAnalysisButton
        function VesselAnalysisButtonPushed(app, event)
            app.Open_Vessel_Gui();
        end

        % Button pushed function: RestoreSettingsButton
        function RestoreSettingsButtonPushed(app, event)
            [fileName,folder] = uigetfile({'*.mat', 'MATLAB Files (*.mat,)'},...
                'File Selector',app.PPA.folderPath);
            
            if fileName
                settingsPath = [folder fileName];
            else
                uialert(app.UIFigure,'No settings file selected!','No settings file selected!');
                return;
            end
            matFileVars = who('-file', settingsPath);
            if strcmp(matFileVars{1},'PostProSettings')
                load(settingsPath,'PostProSettings');
            else
                uialert(app.UIFigure,'Invalid settings file!','Invalid settings file!');
            end
            app.PPA.Restore_Gui_Settings(PostProSettings);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'Master';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @CloseGui, true);
            app.MainGrid = uigridlayout(app.UIFigure);
            app.MainButtonGrid = uigridlayout(app.MainGrid);
            app.LoadRawDataButton = uibutton(app.MainButtonGrid, 'push');
            app.LoadRawDataButton.ButtonPushedFcn = createCallbackFcn(app, @LoadRawDataButtonPushed, true);
            app.LoadRawDataButton.Text = 'Load Raw Data';
            app.VolumeProcessingButton = uibutton(app.MainButtonGrid, 'push');
            app.VolumeProcessingButton.ButtonPushedFcn = createCallbackFcn(app, @VolumeProcessingButtonPushed, true);
            app.VolumeProcessingButton.Enable = 'off';
            app.VolumeProcessingButton.Text = 'Volume Processing';
            app.ExportButton = uibutton(app.MainButtonGrid, 'push');
            app.ExportButton.ButtonPushedFcn = createCallbackFcn(app, @ExportButtonPushed, true);
            app.ExportButton.Text = 'Export & Batch Process';
            app.MapProcessingButton = uibutton(app.MainButtonGrid, 'push');
            app.MapProcessingButton.ButtonPushedFcn = createCallbackFcn(app, @MapProcessingButtonPushed, true);
            app.MapProcessingButton.Enable = 'off';
            app.MapProcessingButton.Text = 'Map Processing';
            app.VesselAnalysisButton = uibutton(app.MainButtonGrid, 'push');
            app.VesselAnalysisButton.ButtonPushedFcn = createCallbackFcn(app, @VesselAnalysisButtonPushed, true);
            app.VesselAnalysisButton.Enable = 'off';
            app.VesselAnalysisButton.Text = 'Vessel Analysis';
            app.RestoreSettingsButton = uibutton(app.MainButtonGrid, 'push');
            app.RestoreSettingsButton.ButtonPushedFcn = createCallbackFcn(app, @RestoreSettingsButtonPushed, true);
            app.RestoreSettingsButton.Text = 'Restore Settings';
            app.TopLogoGrid = uigridlayout(app.MainGrid);
            app.WizardHat = uiimage(app.TopLogoGrid);
            app.WizardHat.ImageSource = 'hat-wizard-solid.svg';
            app.Wand = uiimage(app.TopLogoGrid);
            app.Wand.ImageSource = 'magic-solid.svg';
            app.PostProcessingWizardLabel = uilabel(app.TopLogoGrid);
            app.PostProcessingWizardLabel.Text = {'Post'; 'Processing'; 'Wizard'};
            app.InfoGrid = uigridlayout(app.MainGrid);
            app.HelpImage = uiimage(app.InfoGrid);
            app.HelpImage.ImageClickedFcn = createCallbackFcn(app, @HelpImageClicked, true);
            app.HelpImage.ImageSource = 'ambulance-solid.svg';
            app.LicenseImage = uiimage(app.InfoGrid);
            app.LicenseImage.ImageClickedFcn = createCallbackFcn(app, @LicenseImageClicked, true);
            app.LicenseImage.ImageSource = 'registered-solid.svg';
            app.JRUZH2020Label = uilabel(app.InfoGrid);
            app.JRUZH2020Label.Text = 'JR | UZH | 2020';
            app.DebugMode = uicheckbox(app.InfoGrid);
            app.DebugMode.Text = '';
            app.ProcessingLogPanel = uipanel(app.MainGrid);
            app.ProcessingLogPanel.Title = 'Processing Log';
            app.GridLayout7 = uigridlayout(app.ProcessingLogPanel);
            app.Label = uilabel(app.GridLayout7);
            app.Label.Text = '';
            app.DebugText = uilistbox(app.GridLayout7);
            app.DebugText.Items = {' [Processing Log]', ''};
            app.DebugText.Value = {};
            app.GeneralInfoPanel = uipanel(app.MainGrid);
            app.GeneralInfoPanel.Title = 'General Info';
            app.GridLayout9 = uigridlayout(app.GeneralInfoPanel);
            app.GridLayout10 = uigridlayout(app.GridLayout9);
            app.FileEditFieldLabel = uilabel(app.GridLayout10);
            app.FileEditFieldLabel.Text = 'File';
            app.FileEditField = uieditfield(app.GridLayout10, 'text');
            app.FileEditField.Editable = 'off';
            app.FileEditField.Enable = 'off';
            app.FileEditField.Value = 'load raw data!';
            app.GridLayout10_2 = uigridlayout(app.GridLayout9);
            app.cBars = uidropdown(app.GridLayout10_2);
            app.cBars.Items = {'gray', 'hot', 'jet', 'parula'};
            app.cBars.ValueChangedFcn = createCallbackFcn(app, @UpdateColorMaps, true);
            app.cBars.Tooltip = {'select colomap for image display'; ''};
            app.cBars.Value = 'gray';
            app.ColormapLabel = uilabel(app.GridLayout10_2);
            app.ColormapLabel.Text = 'Colormap';
            app.VolumeInfoPanel = uipanel(app.MainGrid);
            app.VolumeInfoPanel.Title = 'Volume Info';
            app.GridLayout2 = uigridlayout(app.VolumeInfoPanel);
            app.GridLayout3 = uigridlayout(app.GridLayout2);
            app.nZVol = uieditfield(app.GridLayout3, 'numeric');
            app.nZVol.Editable = 'off';
            app.nZVol.Enable = 'off';
            app.nZVol.Value = -Inf;
            app.nYVol = uieditfield(app.GridLayout3, 'numeric');
            app.nYVol.Editable = 'off';
            app.nYVol.Enable = 'off';
            app.nYVol.Value = -Inf;
            app.nXVol = uieditfield(app.GridLayout3, 'numeric');
            app.nXVol.Editable = 'off';
            app.nXVol.Enable = 'off';
            app.nXVol.Value = -Inf;
            app.VolSizeLabel = uilabel(app.GridLayout3);
            app.VolSizeLabel.Text = 'Size';
            app.GridLayout5 = uigridlayout(app.GridLayout2);
            app.TotalMemoryEditFieldLabel = uilabel(app.GridLayout5);
            app.TotalMemoryEditFieldLabel.Text = 'Total Memory';
            app.VolMemory = uieditfield(app.GridLayout5, 'text');
            app.VolMemory.Editable = 'off';
            app.VolMemory.Enable = 'off';
            app.VolMemory.Tooltip = {'Inlcudes various variables used for processing, not just the final volume.'};
            app.VolMemory.Value = '0 B';
            app.GridLayout6 = uigridlayout(app.GridLayout2);
            app.VolResoLabel = uilabel(app.GridLayout6);
            app.VolResoLabel.Text = 'Reso';
            app.dXVol = uieditfield(app.GridLayout6, 'numeric');
            app.dXVol.ValueDisplayFormat = '%3.1f';
            app.dXVol.Editable = 'off';
            app.dXVol.Enable = 'off';
            app.dXVol.Value = -Inf;
            app.dYVol = uieditfield(app.GridLayout6, 'numeric');
            app.dYVol.ValueDisplayFormat = '%3.1f';
            app.dYVol.Editable = 'off';
            app.dYVol.Enable = 'off';
            app.dYVol.Value = -Inf;
            app.dZVol = uieditfield(app.GridLayout6, 'numeric');
            app.dZVol.ValueDisplayFormat = '%3.1f';
            app.dZVol.Editable = 'off';
            app.dZVol.Enable = 'off';
            app.dZVol.Value = -Inf;
            app.VolResoLabel_2 = uilabel(app.GridLayout6);
            app.VolResoLabel_2.Enable = 'off';
            app.VolResoLabel_2.Text = 'µm';
            app.MapInfoPanel = uipanel(app.MainGrid);
            app.MapInfoPanel.Title = 'Map Info';
            app.MapInfoGrid = uigridlayout(app.MapInfoPanel);
            app.GridLayout3_2 = uigridlayout(app.MapInfoGrid);
            app.nYMap = uieditfield(app.GridLayout3_2, 'numeric');
            app.nYMap.Editable = 'off';
            app.nYMap.Enable = 'off';
            app.nYMap.Value = -Inf;
            app.nXMap = uieditfield(app.GridLayout3_2, 'numeric');
            app.nXMap.Editable = 'off';
            app.nXMap.Enable = 'off';
            app.nXMap.Value = -Inf;
            app.MapSizeLable = uilabel(app.GridLayout3_2);
            app.MapSizeLable.Text = 'Size';
            app.GridLayout5_2 = uigridlayout(app.MapInfoGrid);
            app.TotalMemoryEditField_2Label = uilabel(app.GridLayout5_2);
            app.TotalMemoryEditField_2Label.Text = 'Total Memory';
            app.MapMemory = uieditfield(app.GridLayout5_2, 'text');
            app.MapMemory.Editable = 'off';
            app.MapMemory.Enable = 'off';
            app.MapMemory.Tooltip = {'Inlcudes various variables used for Map and depth processing, not just the final map.'};
            app.MapMemory.Value = '0 B';
            app.GridLayout6_2 = uigridlayout(app.MapInfoGrid);
            app.MapResoLabel = uilabel(app.GridLayout6_2);
            app.MapResoLabel.Text = 'Reso';
            app.dXIm = uieditfield(app.GridLayout6_2, 'numeric');
            app.dXIm.ValueDisplayFormat = '%3.1f';
            app.dXIm.Editable = 'off';
            app.dXIm.Enable = 'off';
            app.dXIm.Value = -Inf;
            app.dYIm = uieditfield(app.GridLayout6_2, 'numeric');
            app.dYIm.ValueDisplayFormat = '%3.1f';
            app.dYIm.Editable = 'off';
            app.dYIm.Enable = 'off';
            app.dYIm.Value = -Inf;
            app.MapResoLabelUnit = uilabel(app.GridLayout6_2);
            app.MapResoLabelUnit.Enable = 'off';
            app.MapResoLabelUnit.Text = 'µm';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = MasterGui(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)StartGui(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end