classdef VesselGui < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        MainGrid                    matlab.ui.container.GridLayout
        GridLayout31                matlab.ui.container.GridLayout
        BinarizeImagePanel          matlab.ui.container.Panel
        GridLayout16                matlab.ui.container.GridLayout
        BinarizationMethodDropDownLabel  matlab.ui.control.Label
        BinarizationMethodDropDown  matlab.ui.control.DropDown
        GridLayout32_23             matlab.ui.container.GridLayout
        sensitivityLabel            matlab.ui.control.Label
        BinSensEdit                 matlab.ui.control.NumericEditField
        GridLayout32_24             matlab.ui.container.GridLayout
        nLevelsLabel                matlab.ui.control.Label
        BinMultiLevels              matlab.ui.control.NumericEditField
        FrangiFiltInput             matlab.ui.control.CheckBox
        FrangiSettingsButton        matlab.ui.control.Button
        ResultsVisualizationPanel   matlab.ui.container.Panel
        GridLayout35                matlab.ui.container.GridLayout
        GridLayout34_2              matlab.ui.container.GridLayout
        GridLayout32_27             matlab.ui.container.GridLayout
        colorlevelsLabel            matlab.ui.control.Label
        nColors                     matlab.ui.control.NumericEditField
        GridLayout32_28             matlab.ui.container.GridLayout
        plotsizeLabel               matlab.ui.control.Label
        plotSize                    matlab.ui.control.NumericEditField
        GridLayout32_29             matlab.ui.container.GridLayout
        cMapLabel                   matlab.ui.control.Label
        DataColorMap                matlab.ui.control.DropDown
        GridLayout32_30             matlab.ui.container.GridLayout
        transparencyLabel           matlab.ui.control.Label
        scatterAlpha                matlab.ui.control.NumericEditField
        scaleSize                   matlab.ui.control.CheckBox
        removeOutliers              matlab.ui.control.CheckBox
        GridLayout32_31             matlab.ui.container.GridLayout
        maxstdLabel                 matlab.ui.control.Label
        maxStd                      matlab.ui.control.NumericEditField
        GridLayout32_32             matlab.ui.container.GridLayout
        OverlayLabel                matlab.ui.control.Label
        WhatDataOverlay             matlab.ui.control.DropDown
        UpdateVisualizationButton   matlab.ui.control.Button
        histoAx                     matlab.ui.control.UIAxes
        GenStatsButton              matlab.ui.control.Button
        ExportToWorkspaceButton     matlab.ui.control.Button
        VesselAnalysisLabel         matlab.ui.control.Label
        GridLayout33                matlab.ui.container.GridLayout
        AutoUpdateCheckBox          matlab.ui.control.CheckBox
        FittingWidthDetectionPanel  matlab.ui.container.Panel
        GridLayout34                matlab.ui.container.GridLayout
        GridLayout32_19             matlab.ui.container.GridLayout
        minsplinelengthLabel        matlab.ui.control.Label
        MinSplineLength             matlab.ui.control.NumericEditField
        GridLayout32_20             matlab.ui.container.GridLayout
        splinesmoothnessLabel       matlab.ui.control.Label
        SplineSmooth                matlab.ui.control.NumericEditField
        GridLayout32_21             matlab.ui.container.GridLayout
        smoothperpendLabel          matlab.ui.control.Label
        SmoothPer                   matlab.ui.control.NumericEditField
        GridLayout32_22             matlab.ui.container.GridLayout
        smoothparallelLabel         matlab.ui.control.Label
        SmoothPar                   matlab.ui.control.NumericEditField
        ForceConnect                matlab.ui.control.CheckBox
        RemoveExtreme               matlab.ui.control.CheckBox
        FitToFrangi                 matlab.ui.control.CheckBox
        CleanSegmentedPanel         matlab.ui.container.Panel
        GridLayout16_3              matlab.ui.container.GridLayout
        GridLayout32_8              matlab.ui.container.GridLayout
        fillholesizeLabel           matlab.ui.control.Label
        MinHoleSizeEdit             matlab.ui.control.NumericEditField
        GridLayout32_9              matlab.ui.container.GridLayout
        minobjsizeLabel             matlab.ui.control.Label
        MinObjSizeEdit              matlab.ui.control.NumericEditField
        GridLayout32_10             matlab.ui.container.GridLayout
        minspurlengthLabel          matlab.ui.control.Label
        MinSpurLength               matlab.ui.control.NumericEditField
        ClearNearBranchCheckBox     matlab.ui.control.CheckBox
        FindVesselsButton           matlab.ui.control.Button
    end

    
    properties (Access = public)
        PPA = PostProBackEnd().empty;
    end
    
    methods (Access = public)
        function Close_Request(app)
            app.UIFigure.Visible = 'off';
            delete(app.PPA.VesselFigs.MainFig);
            delete(app.PPA.VesselFigs.ResultsFig);
            app.PPA.VesselFigs = []; % delete old handles
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function Open_App(app, PPA)
            movegui(app.UIFigure,'northwest');
            if ~isempty(PPA.MasterGUI)
                masterGuiPos = PPA.MasterGUI.UIFigure.Position;
                rightBorder = masterGuiPos(1)+masterGuiPos(3);
                app.UIFigure.Position(1) = rightBorder;
                % move down a bit so windows don't fully overlapp
                app.UIFigure.Position(2) = app.UIFigure.Position(2) - 50;
            end
            app.PPA = PPA;
            app.PPA.VesselGUI = app; % transfer handle to app
            
            app.PPA.Setup_Vessel_Figures();
%             app.PPA.Apply_Vessel_Processing();
        end

        % Close request function: UIFigure
        function CloseApp(app, event)
            app.Close_Request();
        end

        % Value changed function: BinMultiLevels, BinSensEdit, 
        % BinarizationMethodDropDown, ClearNearBranchCheckBox, 
        % FitToFrangi, ForceConnect, MinHoleSizeEdit, 
        % MinObjSizeEdit, MinSplineLength, MinSpurLength, 
        % RemoveExtreme, SmoothPar, SmoothPer, SplineSmooth
        function Apply_Vessel_Processing(app, event)
            switch app.BinarizationMethodDropDown.Value
                case 'Otsu'
                    app.BinSensEdit.Enable = false;
                    app.BinMultiLevels.Enable = false;
                    
                case 'Multi'
                    app.BinSensEdit.Enable = false;
                    app.BinMultiLevels.Enable = true;
                    
                case 'Adaptive'
                    app.BinSensEdit.Enable = true;
                    app.BinMultiLevels.Enable = false;
                case 'Manual'
                    app.BinSensEdit.Enable = true;
                    app.BinMultiLevels.Enable = false;
            end
            if app.AutoUpdateCheckBox.Value
                app.PPA.Apply_Vessel_Processing();
            end
        end

        % Button pushed function: FrangiSettingsButton
        function FrangiSettingsButtonPushed(app, event)
            app.PPA.Init_Frangi('vessel'); 
            app.PPA.VesselFrangi.Apply_Frangi();
        end

        % Button pushed function: FindVesselsButton
        function FindVesselsButtonPushed(app, event)
            app.PPA.Apply_Vessel_Processing();
        end

        % Callback function: FrangiFiltInput, UIFigure
        function FrangiCheckBoxChanged(app, event)
            app.FrangiSettingsButton.Enable = app.FrangiFiltInput.Value;
            app.FitToFrangi.Enable = app.FrangiFiltInput.Value;
            if app.FrangiFiltInput.Value
                app.PPA.Init_Frangi('vessel');
            else
%                 app.PPA.VesselFrangi.GUI.UIFigure.Visible = 'off';
%                 app.PPA.VesselFrangi.GUI.UIFigure.Visible = 'off';
                % no frangi, so update fitted vessels, maybe...
                if app.AutoUpdateCheckBox.Value
                    app.PPA.Apply_Vessel_Processing();
                end
            end
            
        end

        % Button pushed function: UpdateVisualizationButton
        function UpdateVisualizationButtonPushed(app, event)
            app.PPA.Update_Vessel_Results_Plot();
        end

        % Value changed function: WhatDataOverlay
        function WhatDataOverlayValueChanged(app, event)
            % set default settings for each when changing what to plot
            switch app.WhatDataOverlay.Value
                case 'angle' % per vessel-segment
                    app.DataColorMap.Value = 'hsv';
                    app.plotSize.Value = 4;
                    app.scaleSize.Value = 0;
                case 'diameter' % per vessel-segment
                    app.DataColorMap.Value = 'GreenRed';
                    app.plotSize.Value = 4;
                    app.scaleSize.Value = 1;
                case 'turtuosity' % per vessel
                    app.DataColorMap.Value = 'parula';
                    app.plotSize.Value = 2;
                    app.scaleSize.Value = 0;
            end
            app.PPA.Update_Vessel_Results_Plot();
            
        end

        % Value changed function: removeOutliers
        function removeOutliersValueChanged(app, event)
            app.maxStd.Enable = app.removeOutliers.Value;
        end

        % Button pushed function: ExportToWorkspaceButton
        function ExportToWorkspaceButtonPushed(app, event)
            VesselData = app.PPA.AVA.Get_Full_Data(); % also get vessel data struct
            assignin('base','VesselData',VesselData);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'GUI: Vessel Analysis';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @CloseApp, true);
            app.UIFigure.ButtonDownFcn = createCallbackFcn(app, @FrangiCheckBoxChanged, true);
            app.MainGrid = uigridlayout(app.UIFigure);
            app.GridLayout31 = uigridlayout(app.MainGrid);
            app.BinarizeImagePanel = uipanel(app.GridLayout31);
            app.BinarizeImagePanel.Title = 'Binarize Image';
            app.GridLayout16 = uigridlayout(app.BinarizeImagePanel);
            app.BinarizationMethodDropDownLabel = uilabel(app.GridLayout16);
            app.BinarizationMethodDropDownLabel.Text = 'Binarization Method';
            app.BinarizationMethodDropDown = uidropdown(app.GridLayout16);
            app.BinarizationMethodDropDown.Items = {'Otsu', 'Manual', 'Multi', 'Adaptive'};
            app.BinarizationMethodDropDown.ValueChangedFcn = createCallbackFcn(app, @Apply_Vessel_Processing, true);
            app.BinarizationMethodDropDown.Value = 'Adaptive';
            app.GridLayout32_23 = uigridlayout(app.GridLayout16);
            app.sensitivityLabel = uilabel(app.GridLayout32_23);
            app.sensitivityLabel.Text = 'sensitivity';
            app.BinSensEdit = uieditfield(app.GridLayout32_23, 'numeric');
            app.BinSensEdit.Limits = [0 1];
            app.BinSensEdit.ValueDisplayFormat = '%3.2f';
            app.BinSensEdit.ValueChangedFcn = createCallbackFcn(app, @Apply_Vessel_Processing, true);
            app.BinSensEdit.Value = 0.25;
            app.GridLayout32_24 = uigridlayout(app.GridLayout16);
            app.nLevelsLabel = uilabel(app.GridLayout32_24);
            app.nLevelsLabel.Enable = 'off';
            app.nLevelsLabel.Text = 'n Levels';
            app.BinMultiLevels = uieditfield(app.GridLayout32_24, 'numeric');
            app.BinMultiLevels.Limits = [1 Inf];
            app.BinMultiLevels.RoundFractionalValues = 'on';
            app.BinMultiLevels.ValueDisplayFormat = '%i';
            app.BinMultiLevels.ValueChangedFcn = createCallbackFcn(app, @Apply_Vessel_Processing, true);
            app.BinMultiLevels.Enable = 'off';
            app.BinMultiLevels.Value = 1;
            app.FrangiFiltInput = uicheckbox(app.GridLayout16);
            app.FrangiFiltInput.ValueChangedFcn = createCallbackFcn(app, @FrangiCheckBoxChanged, true);
            app.FrangiFiltInput.Tooltip = {''};
            app.FrangiFiltInput.Text = 'pre-frangi input';
            app.FrangiSettingsButton = uibutton(app.GridLayout16, 'push');
            app.FrangiSettingsButton.ButtonPushedFcn = createCallbackFcn(app, @FrangiSettingsButtonPushed, true);
            app.FrangiSettingsButton.Enable = 'off';
            app.FrangiSettingsButton.Text = 'Frangi Settings';
            app.ResultsVisualizationPanel = uipanel(app.GridLayout31);
            app.ResultsVisualizationPanel.Title = 'Results Visualization';
            app.GridLayout35 = uigridlayout(app.ResultsVisualizationPanel);
            app.GridLayout34_2 = uigridlayout(app.GridLayout35);
            app.GridLayout32_27 = uigridlayout(app.GridLayout34_2);
            app.colorlevelsLabel = uilabel(app.GridLayout32_27);
            app.colorlevelsLabel.Text = 'color levels';
            app.nColors = uieditfield(app.GridLayout32_27, 'numeric');
            app.nColors.Limits = [2 512];
            app.nColors.RoundFractionalValues = 'on';
            app.nColors.ValueDisplayFormat = '%i';
            app.nColors.Tooltip = {''};
            app.nColors.Value = 45;
            app.GridLayout32_28 = uigridlayout(app.GridLayout34_2);
            app.plotsizeLabel = uilabel(app.GridLayout32_28);
            app.plotsizeLabel.Text = 'plot size';
            app.plotSize = uieditfield(app.GridLayout32_28, 'numeric');
            app.plotSize.Limits = [0 100];
            app.plotSize.ValueDisplayFormat = '%2.2f';
            app.plotSize.Tooltip = {''};
            app.plotSize.Value = 4;
            app.GridLayout32_29 = uigridlayout(app.GridLayout34_2);
            app.cMapLabel = uilabel(app.GridLayout32_29);
            app.cMapLabel.Text = 'cMap';
            app.DataColorMap = uidropdown(app.GridLayout32_29);
            app.DataColorMap.Items = {'jet', 'hot', 'gray', 'parula', 'hsv', 'GreenRed', 'RedGreen', 'GreenBlue', 'BlueGreen'};
            app.DataColorMap.Value = 'hsv';
            app.GridLayout32_30 = uigridlayout(app.GridLayout34_2);
            app.transparencyLabel = uilabel(app.GridLayout32_30);
            app.transparencyLabel.Text = 'transparency';
            app.scatterAlpha = uieditfield(app.GridLayout32_30, 'numeric');
            app.scatterAlpha.Limits = [0.1 1];
            app.scatterAlpha.ValueDisplayFormat = '%02.2f';
            app.scatterAlpha.Tooltip = {''};
            app.scatterAlpha.Value = 0.8;
            app.scaleSize = uicheckbox(app.GridLayout34_2);
            app.scaleSize.Tooltip = {''};
            app.scaleSize.Text = 'scale size';
            app.removeOutliers = uicheckbox(app.GridLayout34_2);
            app.removeOutliers.ValueChangedFcn = createCallbackFcn(app, @removeOutliersValueChanged, true);
            app.removeOutliers.Tooltip = {''};
            app.removeOutliers.Text = 'remove outliers';
            app.removeOutliers.Value = true;
            app.GridLayout32_31 = uigridlayout(app.GridLayout34_2);
            app.maxstdLabel = uilabel(app.GridLayout32_31);
            app.maxstdLabel.Text = 'max std';
            app.maxStd = uieditfield(app.GridLayout32_31, 'numeric');
            app.maxStd.Limits = [0 100];
            app.maxStd.ValueDisplayFormat = '%2.1f';
            app.maxStd.Tooltip = {''};
            app.maxStd.Value = 2;
            app.GridLayout32_32 = uigridlayout(app.GridLayout34_2);
            app.OverlayLabel = uilabel(app.GridLayout32_32);
            app.OverlayLabel.Text = 'Overlay';
            app.WhatDataOverlay = uidropdown(app.GridLayout32_32);
            app.WhatDataOverlay.Items = {'angle', 'diameter', 'turtuosity'};
            app.WhatDataOverlay.ValueChangedFcn = createCallbackFcn(app, @WhatDataOverlayValueChanged, true);
            app.WhatDataOverlay.Value = 'angle';
            app.UpdateVisualizationButton = uibutton(app.GridLayout35, 'push');
            app.UpdateVisualizationButton.ButtonPushedFcn = createCallbackFcn(app, @UpdateVisualizationButtonPushed, true);
            app.UpdateVisualizationButton.Text = 'Update Visualization';
            app.histoAx = uiaxes(app.GridLayout35);
            title(app.histoAx, 'Data Overlay')
            xlabel(app.histoAx, 'X')
            ylabel(app.histoAx, 'Y')
            app.GenStatsButton = uibutton(app.GridLayout35, 'push');
            app.GenStatsButton.Text = 'Generate Statistics Overview';
            app.ExportToWorkspaceButton = uibutton(app.GridLayout35, 'push');
            app.ExportToWorkspaceButton.ButtonPushedFcn = createCallbackFcn(app, @ExportToWorkspaceButtonPushed, true);
            app.ExportToWorkspaceButton.Text = 'Export To Workspace';
            app.VesselAnalysisLabel = uilabel(app.GridLayout31);
            app.VesselAnalysisLabel.Text = 'Vessel Analysis';
            app.GridLayout33 = uigridlayout(app.GridLayout31);
            app.AutoUpdateCheckBox = uicheckbox(app.GridLayout33);
            app.AutoUpdateCheckBox.Text = 'Auto Update?';
            app.FittingWidthDetectionPanel = uipanel(app.GridLayout31);
            app.FittingWidthDetectionPanel.Title = 'Fitting & Width Detection';
            app.GridLayout34 = uigridlayout(app.FittingWidthDetectionPanel);
            app.GridLayout32_19 = uigridlayout(app.GridLayout34);
            app.minsplinelengthLabel = uilabel(app.GridLayout32_19);
            app.minsplinelengthLabel.Text = {'min spline'; 'length'};
            app.MinSplineLength = uieditfield(app.GridLayout32_19, 'numeric');
            app.MinSplineLength.Limits = [3 Inf];
            app.MinSplineLength.RoundFractionalValues = 'on';
            app.MinSplineLength.ValueDisplayFormat = '%i';
            app.MinSplineLength.ValueChangedFcn = createCallbackFcn(app, @Apply_Vessel_Processing, true);
            app.MinSplineLength.Tooltip = {''};
            app.MinSplineLength.Value = 5;
            app.GridLayout32_20 = uigridlayout(app.GridLayout34);
            app.splinesmoothnessLabel = uilabel(app.GridLayout32_20);
            app.splinesmoothnessLabel.Text = {'spline'; 'smoothness'};
            app.SplineSmooth = uieditfield(app.GridLayout32_20, 'numeric');
            app.SplineSmooth.Limits = [3 Inf];
            app.SplineSmooth.RoundFractionalValues = 'on';
            app.SplineSmooth.ValueDisplayFormat = '%i';
            app.SplineSmooth.ValueChangedFcn = createCallbackFcn(app, @Apply_Vessel_Processing, true);
            app.SplineSmooth.Tooltip = {''};
            app.SplineSmooth.Value = 10;
            app.GridLayout32_21 = uigridlayout(app.GridLayout34);
            app.smoothperpendLabel = uilabel(app.GridLayout32_21);
            app.smoothperpendLabel.Text = {'smooth'; 'perpend.'};
            app.SmoothPer = uieditfield(app.GridLayout32_21, 'numeric');
            app.SmoothPer.Limits = [0 Inf];
            app.SmoothPer.ValueDisplayFormat = '%2.2f';
            app.SmoothPer.ValueChangedFcn = createCallbackFcn(app, @Apply_Vessel_Processing, true);
            app.SmoothPer.Tooltip = {''};
            app.SmoothPer.Value = 0.1;
            app.GridLayout32_22 = uigridlayout(app.GridLayout34);
            app.smoothparallelLabel = uilabel(app.GridLayout32_22);
            app.smoothparallelLabel.Text = {'smooth'; 'parallel'};
            app.SmoothPar = uieditfield(app.GridLayout32_22, 'numeric');
            app.SmoothPar.Limits = [0 Inf];
            app.SmoothPar.ValueDisplayFormat = '%2.2f';
            app.SmoothPar.ValueChangedFcn = createCallbackFcn(app, @Apply_Vessel_Processing, true);
            app.SmoothPar.Tooltip = {''};
            app.SmoothPar.Value = 1;
            app.ForceConnect = uicheckbox(app.GridLayout34);
            app.ForceConnect.ValueChangedFcn = createCallbackFcn(app, @Apply_Vessel_Processing, true);
            app.ForceConnect.Tooltip = {''; ''};
            app.ForceConnect.Text = 'force connectivity';
            app.ForceConnect.Value = true;
            app.RemoveExtreme = uicheckbox(app.GridLayout34);
            app.RemoveExtreme.ValueChangedFcn = createCallbackFcn(app, @Apply_Vessel_Processing, true);
            app.RemoveExtreme.Tooltip = {''; ''};
            app.RemoveExtreme.Text = 'remove fat vessels';
            app.RemoveExtreme.Value = true;
            app.FitToFrangi = uicheckbox(app.GridLayout34);
            app.FitToFrangi.ValueChangedFcn = createCallbackFcn(app, @Apply_Vessel_Processing, true);
            app.FitToFrangi.Tooltip = {''};
            app.FitToFrangi.Text = 'fit to frangi';
            app.CleanSegmentedPanel = uipanel(app.GridLayout31);
            app.CleanSegmentedPanel.Title = 'Clean Segmented';
            app.GridLayout16_3 = uigridlayout(app.CleanSegmentedPanel);
            app.GridLayout32_8 = uigridlayout(app.GridLayout16_3);
            app.fillholesizeLabel = uilabel(app.GridLayout32_8);
            app.fillholesizeLabel.Text = 'fill hole size';
            app.MinHoleSizeEdit = uieditfield(app.GridLayout32_8, 'numeric');
            app.MinHoleSizeEdit.Limits = [0 Inf];
            app.MinHoleSizeEdit.RoundFractionalValues = 'on';
            app.MinHoleSizeEdit.ValueDisplayFormat = '%i';
            app.MinHoleSizeEdit.ValueChangedFcn = createCallbackFcn(app, @Apply_Vessel_Processing, true);
            app.MinHoleSizeEdit.Tooltip = {''};
            app.MinHoleSizeEdit.Value = 5;
            app.GridLayout32_9 = uigridlayout(app.GridLayout16_3);
            app.minobjsizeLabel = uilabel(app.GridLayout32_9);
            app.minobjsizeLabel.Text = 'min obj size';
            app.MinObjSizeEdit = uieditfield(app.GridLayout32_9, 'numeric');
            app.MinObjSizeEdit.Limits = [0 Inf];
            app.MinObjSizeEdit.RoundFractionalValues = 'on';
            app.MinObjSizeEdit.ValueDisplayFormat = '%i';
            app.MinObjSizeEdit.ValueChangedFcn = createCallbackFcn(app, @Apply_Vessel_Processing, true);
            app.MinObjSizeEdit.Tooltip = {''};
            app.MinObjSizeEdit.Value = 5;
            app.GridLayout32_10 = uigridlayout(app.GridLayout16_3);
            app.minspurlengthLabel = uilabel(app.GridLayout32_10);
            app.minspurlengthLabel.Text = {'min spur'; 'length'};
            app.MinSpurLength = uieditfield(app.GridLayout32_10, 'numeric');
            app.MinSpurLength.Limits = [0 Inf];
            app.MinSpurLength.RoundFractionalValues = 'on';
            app.MinSpurLength.ValueDisplayFormat = '%i';
            app.MinSpurLength.ValueChangedFcn = createCallbackFcn(app, @Apply_Vessel_Processing, true);
            app.MinSpurLength.Tooltip = {''};
            app.MinSpurLength.Value = 5;
            app.ClearNearBranchCheckBox = uicheckbox(app.GridLayout16_3);
            app.ClearNearBranchCheckBox.ValueChangedFcn = createCallbackFcn(app, @Apply_Vessel_Processing, true);
            app.ClearNearBranchCheckBox.Tooltip = {''};
            app.ClearNearBranchCheckBox.Text = 'clear near branch';
            app.FindVesselsButton = uibutton(app.GridLayout31, 'push');
            app.FindVesselsButton.ButtonPushedFcn = createCallbackFcn(app, @FindVesselsButtonPushed, true);
            app.FindVesselsButton.Text = 'Find Vessels';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = VesselGui(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)Open_App(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end